classdef Estratificacionprimer_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        uipanel1     matlab.ui.container.Panel
        pushbutton3  matlab.ui.control.Button
        text21       matlab.ui.control.Label
        text20       matlab.ui.control.Label
        text19       matlab.ui.control.Label
        text17       matlab.ui.control.Label
        text16       matlab.ui.control.Label
        text15       matlab.ui.control.Label
        text14       matlab.ui.control.Label
        text13       matlab.ui.control.Label
        edit9        matlab.ui.control.EditField
        edit8        matlab.ui.control.EditField
        edit7        matlab.ui.control.EditField
        checkbox1    matlab.ui.control.CheckBox
        popupmenu1   matlab.ui.control.DropDown
        text12       matlab.ui.control.Label
        text9        matlab.ui.control.Label
        alpha        matlab.ui.control.EditField
        text7        matlab.ui.control.Label
        nf           matlab.ui.control.EditField
        text5        matlab.ui.control.Label
        Aceptar      matlab.ui.control.Button
        pushbutton1  matlab.ui.control.Button
        espesor      matlab.ui.control.EditField
        C            matlab.ui.control.EditField
        Pe           matlab.ui.control.EditField
        Phi          matlab.ui.control.EditField
        uitable1     matlab.ui.control.Table
        UIAxes       matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.uitable1.BackgroundColor = [1 1 1];
            app.uitable1.ColumnFormat = {[] [] []};
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Estratificacionprimer_OpeningFcn(app, varargin)
            % --- Executes just before Estratificacionprimer is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to Estratificacionprimer (see VARARGIN)
            global R2 R1
            R11=varargin{1};
            R22=varargin{2};
            datos=get(handles.uitable1,'Data');
            datos(:,:)=[];
           
            if R22==1
                set(handles.text13,'string','Espesor(ft)');
                set(handles.text14,'string','P.E (lb/ft3)');
                set(handles.text19,'string','(ft)');
                set(handles.text17,'string','Psi');
                set(handles.text20,'string','(lb/ft3)');
            end
            if R11==1
                set(handles.text13,'string','Espesor(m)');
                set(handles.text14,'string','P.E (kN/m3)');
                 set(handles.text17,'string','kPa');
                set(handles.text19,'string','(m)');
                set(handles.text20,'string','(kN/m3)');
            end
            set(handles.uitable1,'Data',datos);
            
            % Choose default command line output for Estratificacion1
            handles.output = hObject;
            % Choose default command line output for Estratificacionprimer
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            %aparezca posterior apagado


            % R11=R1;
            % R22=R2;
            % if R1==1
            %     R2=0;
            %     R11=1;
            % end
            % if R1==0
            %     R2=1;
            %     R22=1;
            %
            % end
            % if R2==1
            %     R1=0;
            %     R11=0;
            % end
            % if R2==0
            %     R1=1;
            % end
            
            
            assignin('base','R22',R22);
            assignin('base','R11',R11);
        end

        % Button pushed function: Aceptar
        function Aceptar_Callback(app, event)
            % --- Executes on button press in Aceptar.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Aceptar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            ObtenerDatos=get(handles.uitable1,'data');
            assignin('base','dataa',ObtenerDatos);
            
            
            nn=size(ObtenerDatos,1);%el numero de filas me dira la cantidad de estratos
%             archivo=fopen('datos.dat','W');
%             assignin('base','archivo',archivo);
%             
%             %llenamos la matriz usando un For
%             for i=1:nn
%                 fprintf(archivo,'%s %s %s \n',ObtenerDatos{i,:});
%             end
%             %cerramos el archivo creado
%             fclose(archivo);
%             %cargamos la matriz igualandola a una varible
%             B=load('datos.dat');%ya esta formada la matriz
            B=ObtenerDatos;
            assignin('base','B',B);
            %separamos el vector igualandolo a una variable aqui ya obtengo los valores
            %del suelo para usarlos se vuelven valores globales
            
            global espesor phi PesoE Cohesion NF n alpha k espe pe ph R
            
            n=nn;
            assignin('base','n',n);
            %%%%%SUELO
            if n>4 || n<0
                errordlg('Cantidad de estratos fuera de rango,valores de 1 a 4 estartos','ERROR')
                return
            end
            
            NF=str2double(get(handles.nf,'String'));
            assignin('base','nf',NF);
            alpha=str2double(get(handles.alpha,'String'));
            
            espesor=B(:,1);
            assignin('base','espesor',espesor);
            
            PesoE=B(:,2);
            phi=B(:,3);
            Cohesion=str2double(get(handles.C,'String'));
            assignin('base','PesoE',PesoE);
            assignin('base','PHI',phi);

            %NF
            if NF<0
                errordlg('Valor menor de N.F menor de 0, si no hay nivel freatico es 0','ERROR')
                return
            end
            if NF>n
                errordlg('Valor de N.F por encima del numero de estratos','ERROR')
                return
            end
            %alpha
            if alpha<0
                errordlg('Valor de Alpha fuera de Rango,Valor menor de 0','ERROR')
                return
            end
            if isnan(alpha)
                errordlg('Valor de alpha no numerico','ERROR')
                return
            end
            
            if R==1
                espe=str2double(get(handles.edit7,'String'));
                pe=str2double(get(handles.edit8,'String'));
                ph=str2double(get(handles.edit9,'String'));
            
                if espe<0
                    errordlg('Valor de espesor del Suelo Posterior fuera de Rango, Valor menor de 0','ERROR')
                    return
                end
                if isnan(espe)
                    errordlg('Valor de espesor del Suelo Posterior no numerico','ERROR')
                    return
                end
                %Peso especifico
                if pe<0
                    errordlg('Valor de Peso Especifico Del suelo Posterior fuera de Rango, Valor menor de 0','ERROR')
                    return
                end
                if isnan(pe)
                    errordlg('Valor de Peso Especifico Del suelo Posterior no numerico','ERROR')
                    return
                end
                %PHI INTERNA
                if isnan(ph)
                    errordlg('Valor de Phi Suelo posterior fuera de Rango, Valor menor de 0','ERROR')
                    return
                end
                 if isnan(ph)
                    errordlg('Valor de Phi Suelo posterior no numerico','ERROR')
                    return
                 end
            
            if R==0
                espe=0;
                pe=0;
                ph=0;
            end
            
            end
            assignin('base','k',k);
            assignin('base','R',R);
           Y1='k';
           Y2='k';
           
           if n==1
              
            
              fill(app.UIAxes,[0 0 10 10],[0 espesor espesor 0],[0.63 .45 0.3]);hold on;
               
              % Linea cota altura
               plot(app.UIAxes,[2 2],[0,espesor],Y1,'LineWidth',1.2),  
               % Flechas cota altura
               plot(app.UIAxes,[1.7 2],[0.85*espesor espesor],Y1,'LineWidth',1.2);	
               plot(app.UIAxes,[2 2.3],[espesor 0.85*espesor],Y1,'LineWidth',1.2);
               plot(app.UIAxes,[1.7 2],[0.15*espesor 0],Y1,'LineWidth',1.2);
               plot(app.UIAxes,[2 2.3],[0 0.15*espesor],Y1,'LineWidth',1.2);
               % Etiquetas
               text(2.3,0.5*espesor,['H = ',num2str(espesor)],'FontSize',20,'color',Y2);
               text(7,0.7*espesor,['γ = ',num2str(PesoE)],'FontSize',20,'color',Y2);
               text(7,0.5*espesor,['Φ = ',num2str(phi)],'FontSize',20,'color',Y2);
               text(0.1,1.05*espesor,'Superficie','FontSize',10,'color','K');
               if NF==1
                   plot(-0:0.1:10,(ones(101,1).*espesor),'--b','linewidth',2);
                   text(10,espesor,'N.F.','FontSize',10,'color','b');
               end
           end
           if n==2
               esp=espesor(1)+espesor(2);
               fill(app.UIAxes,[0 0 10 10],[esp esp-espesor(1) esp-espesor(1) esp],[0.1 .45 0.3]);hold on;
               fill(app.UIAxes,[0 0 10 10],[0 espesor(2) espesor(2) 0],[0.63 .45 0.3]);hold on;
               
              % Linea cota altura
               plot(app.UIAxes,[2 2],[0,espesor(1)+espesor(2)],Y1,'LineWidth',1.2),  
               % Flechas cota altura
               plot(app.UIAxes,[1.7 2],[0.85*esp esp],Y1,'LineWidth',1.2);	
               plot(app.UIAxes,[2 2.3],[esp 0.85*esp],Y1,'LineWidth',1.2);
               plot(app.UIAxes,[1.7 2],[0.15*esp 0],Y1,'LineWidth',1.2);
               plot(app.UIAxes,[2 2.3],[0 0.15*esp],Y1,'LineWidth',1.2);
               % Etiquetas
               text(2.3,0.5*esp,['H = ',num2str(espesor(1)+espesor(2))],'FontSize',20,'color',Y2);
               text(3,(0.59*espesor(1))+espesor(2),['γ1 = ',num2str(PesoE(1))],'FontSize',15,'color',Y2);
               text(7,(0.4*espesor(1))+espesor(2),['Φ1 = ',num2str(phi(1))],'FontSize',15,'color',Y2);
               text(5,(0.59*espesor(2)),['γ2 = ',num2str(PesoE(2))],'FontSize',15,'color',Y2);
               text(7,(0.4*espesor(2)),['Φ2 = ',num2str(phi(2))],'FontSize',15,'color',Y2);
               text(0.1,1.05*esp,'Superficie','FontSize',10,'color','K');


               if NF==1
                   plot(-0:0.1:10,(ones(101,1).*esp),'--b','linewidth',2);
                   text(10,esp,'N.F.','FontSize',10,'color','b');
               end
               if NF==2
                   plot(-0:0.1:10,(ones(101,1).*espesor(2)),'--b','linewidth',2);
                   text(10,espesor(2),'N.F.','FontSize',10,'color','b');
               end
           end
           if n==3
               esp=espesor(1)+espesor(2)+espesor(3);
               fill(app.UIAxes,[0 0 10 10],[esp esp-espesor(1) esp-espesor(1) esp],[0.1 .45 0.3]);hold on;
               fill(app.UIAxes,[0 0 10 10],[0 espesor(3)+espesor(2) espesor(3)+espesor(2) 0],[0.63 .45 0.3]);hold on;
               fill(app.UIAxes,[0 0 10 10],[0 espesor(3) espesor(3) 0],[0.8 .45 0.3]);hold on;
              % Linea cota altura
               plot(app.UIAxes,[2 2],[0,espesor(1)+espesor(2)+espesor(3)],Y1,'LineWidth',1.2),  
               % Flechas cota altura
               plot(app.UIAxes,[1.7 2],[0.85*esp esp],Y1,'LineWidth',1.2);	
               plot(app.UIAxes,[2 2.3],[esp 0.85*esp],Y1,'LineWidth',1.2);
               plot(app.UIAxes,[1.7 2],[0.15*esp 0],Y1,'LineWidth',1.2);
               plot(app.UIAxes,[2 2.3],[0 0.15*esp],Y1,'LineWidth',1.2);
               % Etiquetas
               text(2.3,0.5*esp,['H = ',num2str(espesor(1)+espesor(2)+espesor(3))],'FontSize',20,'color',Y2);
               text(5,(0.59*espesor(1))+espesor(2)+espesor(3),['γ1 = ',num2str(PesoE(1))],'FontSize',12,'color',Y2);
               text(7,(0.4*espesor(1))+espesor(2)+espesor(3),['Φ1 = ',num2str(phi(1))],'FontSize',12,'color',Y2);
               text(5,(0.59*espesor(2))+espesor(3),['γ2 = ',num2str(PesoE(2))],'FontSize',12,'color',Y2);
               text(7,(0.4*espesor(2))+espesor(3),['Φ2 = ',num2str(phi(2))],'FontSize',12,'color',Y2);
               text(5,(0.59*espesor(3)),['γ3 = ',num2str(PesoE(3))],'FontSize',12,'color',Y2);
               text(7,(0.35*espesor(3)),['Φ3 = ',num2str(phi(3))],'FontSize',12,'color',Y2);
               text(0.1,1.05*esp,'Superficie','FontSize',10,'color','K');

               if NF==1
                   plot(-0:0.1:10,(ones(101,1).*esp),'--b','linewidth',2);
                   text(10,esp,'N.F.','FontSize',10,'color','b');
               end
               if NF==2
                   plot(-0:0.1:10,(ones(101,1).*espesor(2)+espesor(3)),'--b','linewidth',2);
                   text(10,espesor(2)+espesor(3),'N.F.','FontSize',10,'color','b');
               end
               if NF==3
                   plot(-0:0.1:10,(ones(101,1).*espesor(3)),'--b','linewidth',2);
                   text(10,espesor(3),'N.F.','FontSize',10,'color','b');
               end
           end

           if n==4
               esp=espesor(1)+espesor(2)+espesor(3)+espesor(4);
               fill(app.UIAxes,[0 0 10 10],[esp esp-espesor(1) esp-espesor(1) esp],[0.1 .45 0.3]);hold on;
               fill(app.UIAxes,[0 0 10 10],[0 espesor(3)+espesor(2)+espesor(4) espesor(3)+espesor(2)+espesor(4) 0],[0.63 .45 0.3]);hold on;
               fill(app.UIAxes,[0 0 10 10],[0 espesor(3)+espesor(4) espesor(3)+espesor(4) 0],[0.8 .45 0.3]);hold on;
               fill(app.UIAxes,[0 0 10 10],[0 espesor(4) espesor(4) 0],[0.8 0.8 0.8]);hold on;
              % Linea cota altura
               plot(app.UIAxes,[2 2],[0,espesor(1)+espesor(2)+espesor(3)+espesor(4)],Y1,'LineWidth',1.2),  
               % Flechas cota altura
               plot(app.UIAxes,[1.7 2],[0.85*esp esp],Y1,'LineWidth',1.2);	
               plot(app.UIAxes,[2 2.3],[esp 0.85*esp],Y1,'LineWidth',1.2);
               plot(app.UIAxes,[1.7 2],[0.15*esp 0],Y1,'LineWidth',1.2);
               plot(app.UIAxes,[2 2.3],[0 0.15*esp],Y1,'LineWidth',1.2);
               % Etiquetas
               text(2.3,0.5*esp,['H = ',num2str(espesor(1)+espesor(2)+espesor(3)+espesor(4))],'FontSize',20,'color',Y2);
               text(5,(0.59*espesor(1))+espesor(2)+espesor(3)+espesor(4),['γ1 = ',num2str(PesoE(1))],'FontSize',12,'color',Y2);
               text(7,(0.4*espesor(1))+espesor(2)+espesor(3)+espesor(4),['Φ1 = ',num2str(phi(1))],'FontSize',12,'color',Y2);
               text(5,(0.59*espesor(2))+espesor(3)+espesor(4),['γ2 = ',num2str(PesoE(2))],'FontSize',12,'color',Y2);
               text(7,(0.4*espesor(2))+espesor(3)+espesor(4),['Φ2 = ',num2str(phi(2))],'FontSize',12,'color',Y2);
               text(5,(0.59*espesor(3))+espesor(4),['γ3 = ',num2str(PesoE(3))],'FontSize',12,'color',Y2);
               text(7,(0.35*espesor(3)+espesor(4)),['Φ3 = ',num2str(phi(3))],'FontSize',12,'color',Y2);
               text(5,(0.59*espesor(4)),['γ4 = ',num2str(PesoE(4))],'FontSize',12,'color',Y2);
               text(7,(0.35*espesor(4)),['Φ4 = ',num2str(phi(4))],'FontSize',12,'color',Y2);
               text(0.1,1.05*esp,'Superficie','FontSize',10,'color','K');
               if NF==1
                   plot(-0:0.1:10,(ones(101,1).*esp),'--b','linewidth',2);
                   text(10,esp,'N.F.','FontSize',10,'color','b');
               end
               if NF==2
                   plot(-0:0.1:10,(ones(101,1).*espesor(2)+espesor(3)+espesor(4)),'--b','linewidth',2);
                   text(10,espesor(2)+espesor(3)+espesor(4),'N.F.','FontSize',10,'color','b');
               end
               if NF==3
                   plot(-0:0.1:10,(ones(101,1).*espesor(3)+espesor(4)),'--b','linewidth',2);
                   text(10,espesor(3)+espesor(4),'N.F.','FontSize',10,'color','b');
               end
               if NF==4
                   plot(-0:0.1:10,(ones(101,1).*espesor(4)),'--b','linewidth',2);
                   text(10,espesor(4),'N.F.','FontSize',10,'color','b');
               end
           end
            waitfor(msgbox('Datos Asignados','Ok'));
            MuroT_App
        end

        % Value changed function: checkbox1
        function checkbox1_Callback(app, event)
            % --- Executes on button press in checkbox1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox1
            global R
            
            R=get(hObject,'Value');
            
                if R==1
                set(handles.edit7,'Visible','on');
                set(handles.edit8,'Visible','on');
                set(handles.edit9,'Visible','on');
                set(handles.text13,'Visible','on');
                set(handles.text14,'Visible','on');
                set(handles.text15,'Visible','on');
                end
                if R==0
                set(handles.edit7,'Visible','off');
                set(handles.edit8,'Visible','off');
                set(handles.edit9,'Visible','off');
                set(handles.text13,'Visible','off');
                set(handles.text14,'Visible','off');
                set(handles.text15,'Visible','off');
                end
        end

        % Value changed function: popupmenu1
        function popupmenu1_Callback(app, event)
            % --- Executes on selection change in popupmenu1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu1 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu1
            %Popmenu1 para que se escoga si es ka o ko
            global k
            
            k=get(hObject,'Value');
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % --- Executes on button press in pushbutton1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            espesor=str2double(get(handles.espesor,'String'));
            PesoEspecifico=str2double(get(handles.Pe,'String'));
            Phia=str2double(get(handles.Phi,'String'));
            assignin('base','espesortabla',espesor);
            assignin('base','PesoEspecifico',PesoEspecifico);
            assignin('base','Phia',Phia);
            %ESPESOR
            if isnan(espesor)
                    errordlg('Valor de Espesor no numerico','ERROR')
                    return
            else
                espesor=espesor;
            end
            %PESO ESPECIFICO
            if isnan(PesoEspecifico)
                    errordlg('Valor de Peso especifico no numerico','ERROR')
                    return
            else
                PesoEspecifico=PesoEspecifico;
            end
            %PHI
            if isnan(Phia)
                    errordlg('Valor de PHI no numerico','ERROR')
                    return
            else
                Phia=Phia;
            end
            datos=get(handles.uitable1,'Data');
            
            if isempty(datos)
                datos(1,1)=espesor;
                datos(1,2)=PesoEspecifico;
                datos(1,3)=Phia;
            
            else
                datos(end+1,1)=espesor;
                datos(end,2)=PesoEspecifico;
                datos(end,3)=Phia;
            
            end
            set(handles.uitable1,'Data',datos);
            
        end

        % Button pushed function: pushbutton3
        function pushbutton3_Callback(app, event)
            % --- Executes on button press in pushbutton3.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            cla(app.UIAxes);
            datos=get(handles.uitable1,'Data');
            datos(:,:)=[];
            set(handles.uitable1,'Data',datos);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Suelo';
            app.figure1.Icon = fullfile(pathToMLAPP, 'ima', 'm.png');
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Datos del suelo';
            app.uipanel1.Tag = 'uipanel1';
            app.UIAxes = uiaxes(app.uipanel1);
            title(app.UIAxes, 'Estratos')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [0 1 2 3 4];
            app.UIAxes.YTickLabel = {'0'; '1'; '0'; '1'; '4'};
            app.uitable1 = uitable(app.uipanel1);
            app.uitable1.ColumnName = {'Espesor'; 'Peso e.'; 'Phi Φ'};
            app.uitable1.RowName = {};
            app.uitable1.ColumnEditable = [true true true];
            app.uitable1.Tag = 'uitable1';
            app.Phi = uieditfield(app.uipanel1, 'text');
            app.Phi.Tag = 'Phi';
            app.Phi.Tooltip = {'''Valores de angulo de friccion interna'''; 'Ingreasar de 1 en 1.'};
            app.Pe = uieditfield(app.uipanel1, 'text');
            app.Pe.Tag = 'Pe';
            app.Pe.Tooltip = {'''Valores de Peso especifico del suelo de relleno'''; 'Ingreasar de 1 en 1.'};
            app.C = uieditfield(app.uipanel1, 'text');
            app.C.Tag = 'C';
            app.C.Tooltip = {'''C'''; 'Cohesión o Adhesión Suelo-Muro'};
            app.espesor = uieditfield(app.uipanel1, 'text');
            app.espesor.Tag = 'espesor';
            app.espesor.Tooltip = {'''Valores de espesor'''; 'Ingreasar de 1 en 1.'};
            app.pushbutton1 = uibutton(app.uipanel1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Tooltip = {'''Añadir'''; 'Ingresa los valores a la tabla '};
            app.pushbutton1.Text = 'Añadir';
            app.Aceptar = uibutton(app.uipanel1, 'push');
            app.Aceptar.ButtonPushedFcn = createCallbackFcn(app, @Aceptar_Callback, true);
            app.Aceptar.Tag = 'Aceptar';
            app.Aceptar.Text = 'Aceptar';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'N.F';
            app.nf = uieditfield(app.uipanel1, 'text');
            app.nf.Tag = 'nf';
            app.nf.Tooltip = {'''N.F'''; 'Estrato donde se encuentra el nivel Freatico. '; 'donde 1 corresponde al primer estrato ingresado.'};
            app.text7 = uilabel(app.uipanel1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'α';
            app.alpha = uieditfield(app.uipanel1, 'text');
            app.alpha.Tag = 'alpha';
            app.alpha.Tooltip = {'''Alpha'''; 'Angulo de inlclinación del relleno.'; 'Si no hay inclinación digitar valor 0.'};
            app.text9 = uilabel(app.uipanel1);
            app.text9.Tag = 'text9';
            app.text9.Text = 'grados°';
            app.text12 = uilabel(app.uipanel1);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Condición del Suelo';
            app.popupmenu1 = uidropdown(app.uipanel1);
            app.popupmenu1.Items = {'', 'Ka', 'Ko', 'Kp'};
            app.popupmenu1.ValueChangedFcn = createCallbackFcn(app, @popupmenu1_Callback, true);
            app.popupmenu1.Tag = 'popupmenu1';
            app.popupmenu1.Tooltip = {'''En que estado se encuentra el suelo'''; 'Ka: estado activo'; 'Kp:estado pasivo'};
            app.popupmenu1.Value = '';
            app.checkbox1 = uicheckbox(app.uipanel1);
            app.checkbox1.ValueChangedFcn = createCallbackFcn(app, @checkbox1_Callback, true);
            app.checkbox1.Tag = 'checkbox1';
            app.checkbox1.Tooltip = {'''Suelo Posterior del muro'''; 'Seleccionar si hay suelo Posterorior en el muro.'};
            app.checkbox1.Text = 'Suelo Posterior';
            app.checkbox1.Value = true;
            app.edit7 = uieditfield(app.uipanel1, 'text');
            app.edit7.Tag = 'edit7';
            app.edit8 = uieditfield(app.uipanel1, 'text');
            app.edit8.Tag = 'edit8';
            app.edit9 = uieditfield(app.uipanel1, 'text');
            app.edit9.Tag = 'edit9';
            app.edit9.Tooltip = {'''Phi'''; 'Angulo friccion interna del suelo posterior.'};
            app.text13 = uilabel(app.uipanel1);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Espesor (m)';
            app.text14 = uilabel(app.uipanel1);
            app.text14.Tag = 'text14';
            app.text14.Text = 'P.E (kN/m^3)';
            app.text15 = uilabel(app.uipanel1);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Phi Φ (grados°)';
            app.text16 = uilabel(app.uipanel1);
            app.text16.Tag = 'text16';
            app.text16.Text = 'C';
            app.text17 = uilabel(app.uipanel1);
            app.text17.Tag = 'text17';
            app.text17.Text = 'kPa';
            app.text19 = uilabel(app.uipanel1);
            app.text19.Tag = 'text19';
            app.text19.Text = '(m)';
            app.text20 = uilabel(app.uipanel1);
            app.text20.Tag = 'text20';
            app.text20.Text = '(kN/m3)';
            app.text21 = uilabel(app.uipanel1);
            app.text21.Tag = 'text21';
            app.text21.Text = '(grados°)';
            app.pushbutton3 = uibutton(app.uipanel1, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3_Callback, true);
            app.pushbutton3.Tag = 'pushbutton3';
            app.pushbutton3.Tooltip = {'''Eliminar'''; 'Limpiar valores de la tabla'};
            app.pushbutton3.Text = 'Eliminar';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Estratificacionprimer_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)Estratificacionprimer_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end