classdef Estratigravedadpe_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        uipanel1     matlab.ui.container.Panel
        pushbutton3  matlab.ui.control.Button
        text21       matlab.ui.control.Label
        text20       matlab.ui.control.Label
        text19       matlab.ui.control.Label
        popupmenu2   matlab.ui.control.DropDown
        text17       matlab.ui.control.Label
        text16       matlab.ui.control.Label
        text15       matlab.ui.control.Label
        text14       matlab.ui.control.Label
        text13       matlab.ui.control.Label
        edit9        matlab.ui.control.EditField
        edit8        matlab.ui.control.EditField
        edit7        matlab.ui.control.EditField
        checkbox1    matlab.ui.control.CheckBox
        popupmenu1   matlab.ui.control.DropDown
        text12       matlab.ui.control.Label
        text5        matlab.ui.control.Label
        Aceptar      matlab.ui.control.Button
        pushbutton1  matlab.ui.control.Button
        espesor      matlab.ui.control.EditField
        C            matlab.ui.control.EditField
        Pe           matlab.ui.control.EditField
        Phi          matlab.ui.control.EditField
        uitable1     matlab.ui.control.Table
        UIAxes       matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function nf_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to nf (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.uitable1.BackgroundColor = [1 1 1];
            app.uitable1.ColumnFormat = {[] [] []};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Estratigravedadpe_OpeningFcn(app, varargin)
            % --- Executes just before Estratigravedadpe is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to Estratigravedadpe (see VARARGIN)
            global R2 R1
            R11=varargin{1};
            R22=varargin{2};
            assignin('base','R11',R11);
            
            if R22==1
                set(handles.text13,'string','Espesor(ft)');
                set(handles.text14,'string','P.e. (lb/ft3)');
                set(handles.text19,'string','(ft)');
                set(handles.text17,'string','Psi');
                set(handles.text20,'string','(lb/ft^3)');
            end
            if R11==1
                set(handles.text13,'string','Espesor(m)');
                set(handles.text14,'string','P.e. (kN/m3)');
                set(handles.text17,'string','kPa');
                set(handles.text19,'string','(m)');
                set(handles.text20,'string','(kN/m3)');
            end
            
            datos=get(handles.uitable1,'Data');
            
            datos(:,:)=[];
            
            set(handles.uitable1,'Data',datos);
            
            % Choose default command line output for Estratificacion1
            handles.output = hObject;
            % Choose default command line output for Estratigravedadpe
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: Aceptar
        function Aceptar_Callback(app, event)
            % --- Executes on button press in Aceptar.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Aceptar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            ObtenerDatos=get(handles.uitable1,'data');
            assignin('base','dataa',ObtenerDatos);
            %ESPESOR DE ESTRATO
            
            
            nn=size(ObtenerDatos,1);%el numero de filas me dira la cantidad de estratos
%             archivo=fopen('datos.dat','W');
%             %llenamos la matriz usando un For
%             for i=1:nn
%                 fprintf(archivo,'%s %s %s \n',ObtenerDatos{i,:});
%             end
%             %cerramos el archivo creado
%             fclose(archivo);
            %cargamos la matriz igualandola a una varible
            Btabla=ObtenerDatos;%ya esta formada la matriz
            assignin('base','B',Btabla);
            %separamos el vector igualandolo a una variable aqui ya obtengo los valores
            %del suelo para usarlos se vuelven valores globales
            
            global espesor phi PesoE Cohesion  n k espe pe ph R  R1 R2 TS


            n=nn;
            assignin('base','n',n);
            
            %%%%%SUELO
            if n>1 || n<0
                errordlg('Valor fuera de Rango,Para Coulomb Solo un estrato','ERROR')
                return
            end
            
            
            espesor=Btabla(:,1);
            
            assignin('base','espesor',espesor);


            PesoE=Btabla(:,2);
            phi=Btabla(:,3);
            Cohesion=str2double(get(handles.C,'String'));
            
            
            %COhesion
            if Cohesion<0
                errordlg('Valor fuera de Rango, Valores de Cohesión menor de 0','ERROR')
                return
            end
            if  isnan(Cohesion)
                errordlg('Valor de Cohesión no numerico','ERROR')
                return
            end


            if R==1
                espe=str2double(get(handles.edit7,'String'));
                pe=str2double(get(handles.edit8,'String'));
                ph=str2double(get(handles.edit9,'String'));
                %Espesor posterior
                if espe<0
                    errordlg('Valor de espesor del Suelo Posterior fuera de Rango, Valor menor de 0','ERROR')
                    return
                end
                if isnan(espe)
                    errordlg('Valor de espesor del Suelo Posterior no numerico','ERROR')
                    return
                end
                %Peso especifico
                if pe<0
                    errordlg('Valor de Peso Especifico Del suelo Posterior fuera de Rango, Valor menor de 0','ERROR')
                    return
                end
                if isnan(pe)
                    errordlg('Valor de Peso Especifico Del suelo Posterior no numerico','ERROR')
                    return
                end
                %PHI INTERNA
                if isnan(ph)
                    errordlg('Valor de Phi Suelo posterior fuera de Rango, Valor menor de 0','ERROR')
                    return
                end
                 if isnan(ph)
                    errordlg('Valor de Phi Suelo posterior no numerico','ERROR')
                    return
                end
            if R==0
                espe=0;
                pe=0;
                ph=0;
            end
            
            end
            assignin('base','k',k);
            assignin('base','R',R);


            Y1='k';
            Y2='k';
            

            if n==1
              
            
              fill(app.UIAxes,[0 0 10 10],[0 espesor espesor 0],[0.63 .45 0.3]);hold on;
               
              % Linea cota altura
               plot(app.UIAxes,[2 2],[0,espesor],Y1,'LineWidth',1.2),  
               % Flechas cota altura
               plot(app.UIAxes,[1.7 2],[0.85*espesor espesor],Y1,'LineWidth',1.2);	
               plot(app.UIAxes,[2 2.3],[espesor 0.85*espesor],Y1,'LineWidth',1.2);
               plot(app.UIAxes,[1.7 2],[0.15*espesor 0],Y1,'LineWidth',1.2);
               plot(app.UIAxes,[2 2.3],[0 0.15*espesor],Y1,'LineWidth',1.2);
               % Etiquetas
               text(2.3,0.5*espesor,['H = ',num2str(espesor)],'FontSize',20,'color',Y2);
               text(5,0.7*espesor,['γ = ',num2str(PesoE)],'FontSize',20,'color',Y2);
               text(7,0.5*espesor,['Φ = ',num2str(phi)],'FontSize',20,'color',Y2);
               text(0.1,1.05*espesor,'Superficie','FontSize',10,'color','K');

               if TS==3
                   plot(-0:0.1:10,(ones(101,1).*espesor),'--b','linewidth',2);
                   text(10,espesor,'N.F.','FontSize',10,'color','b');
               end
            end

            waitfor(msgbox('Datos Asignados','Ok'));
           
            MGrape_App
        end

        % Value changed function: checkbox1
        function checkbox1_Callback(app, event)
            % --- Executes on button press in checkbox1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox1
            global R
            
            R=get(hObject,'Value');
            
                if R==1
                set(handles.edit7,'Visible','on');
                set(handles.edit8,'Visible','on');
                set(handles.edit9,'Visible','on');
                set(handles.text13,'Visible','on');
                set(handles.text14,'Visible','on');
                set(handles.text15,'Visible','on');
                end
                if R==0
                set(handles.edit7,'Visible','off');
                set(handles.edit8,'Visible','off');
                set(handles.edit9,'Visible','off');
                set(handles.text13,'Visible','off');
                set(handles.text14,'Visible','off');
                set(handles.text15,'Visible','off');
                end
        end

        % Value changed function: popupmenu1
        function popupmenu1_Callback(app, event)
            % --- Executes on selection change in popupmenu1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu1 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu1
            %Popmenu1 para que se escoga si es ka o ko
            global k
            
            k=get(hObject,'Value');
            if k==3
                errordlg('Para las teorias Monombe-Okabe,Metodo Simplificado y Richar y Elms, no se puede evaluar el empuje pasivo, seleccione ka','ERROR')
                return

            end
        end

        % Value changed function: popupmenu2
        function popupmenu2_Callback(app, event)
            % --- Executes on selection change in popupmenu2.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu2 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu2
            global TS
            
            TS=get(hObject,'Value');
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % --- Executes on button press in pushbutton1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            espesor=str2double(get(handles.espesor,'String'));
            PesoEspecifico=str2double(get(handles.Pe,'String'));
            Phia=str2double(get(handles.Phi,'String'));
            %ESPESOR
            if isnan(espesor)
                    errordlg('Valor de Espesor no numerico','ERROR')
                    return
            else
                espesor=espesor;
            end
            %PESO ESPECIFICO
            if isnan(PesoEspecifico)
                    errordlg('Valor de Peso especifico no numerico','ERROR')
                    return
            else
                PesoEspecifico=PesoEspecifico;
            end
            %PHI
            if isnan(Phia)
                    errordlg('Valor de PHI no numerico','ERROR')
                    return
            else
                Phia=Phia;
            end
            datos=get(handles.uitable1,'Data');
            
            
            if isempty(datos)
                datos(1,1)=espesor;
                datos(1,2)=PesoEspecifico;
                datos(1,3)=Phia;
            
            else
                datos(end+1,1)=espesor;
                datos(end,2)=PesoEspecifico;
                datos(end,3)=Phia;
            
            end
            
            set(handles.uitable1,'Data',datos);
        end

        % Button pushed function: pushbutton3
        function pushbutton3ButtonPushed(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            cla(app.UIAxes);
            datos=get(handles.uitable1,'Data');
            datos(:,:)=[];
            set(handles.uitable1,'Data',datos);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Suelo';
            app.figure1.Icon = fullfile(pathToMLAPP, 'ima', 'm.png');
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Datos del suelo';
            app.uipanel1.Tag = 'uipanel1';
            app.UIAxes = uiaxes(app.uipanel1);
            title(app.UIAxes, 'Estratos')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [0 1 2 3 4];
            app.UIAxes.YTickLabel = {'0'; '1'; '0'; '1'; '4'};
            app.uitable1 = uitable(app.uipanel1);
            app.uitable1.ColumnName = {'Espesor'; 'Peso.e.'; 'Phi'};
            app.uitable1.RowName = {};
            app.uitable1.ColumnEditable = [false false false];
            app.uitable1.Tag = 'uitable1';
            app.Phi = uieditfield(app.uipanel1, 'text');
            app.Phi.Tag = 'Phi';
            app.Phi.Tooltip = {'''Valores de angulo de friccion interna'''; 'Ingreasar de 1 en 1.'};
            app.Pe = uieditfield(app.uipanel1, 'text');
            app.Pe.Tag = 'Pe';
            app.Pe.Tooltip = {'''Valores de Peso especifico del suelo de relleno'''; 'Ingreasar de 1 en 1.'};
            app.C = uieditfield(app.uipanel1, 'text');
            app.C.Tag = 'C';
            app.C.Tooltip = {'''C'''; 'Cohesión o Adhesión Suelo-Muro en la Base'};
            app.espesor = uieditfield(app.uipanel1, 'text');
            app.espesor.Tag = 'espesor';
            app.espesor.Tooltip = {'''Valores de espesor'''; 'Ingreasar de 1 en 1'};
            app.pushbutton1 = uibutton(app.uipanel1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Tooltip = {'''Añadir'''; 'Ingresa los valores a la tabla'};
            app.pushbutton1.Text = 'Añadir';
            app.Aceptar = uibutton(app.uipanel1, 'push');
            app.Aceptar.ButtonPushedFcn = createCallbackFcn(app, @Aceptar_Callback, true);
            app.Aceptar.Tag = 'Aceptar';
            app.Aceptar.Text = 'Aceptar';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'N.F  ▼   ';
            app.text12 = uilabel(app.uipanel1);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Condición del Suelo';
            app.popupmenu1 = uidropdown(app.uipanel1);
            app.popupmenu1.Items = {'', 'Ka', 'Kp'};
            app.popupmenu1.ValueChangedFcn = createCallbackFcn(app, @popupmenu1_Callback, true);
            app.popupmenu1.Tag = 'popupmenu1';
            app.popupmenu1.Tooltip = {'''En que estado se encuentra el suelo'''; 'Ka: estado activo'; 'Kp:estado pasivo'};
            app.popupmenu1.Value = '';
            app.checkbox1 = uicheckbox(app.uipanel1);
            app.checkbox1.ValueChangedFcn = createCallbackFcn(app, @checkbox1_Callback, true);
            app.checkbox1.Tag = 'checkbox1';
            app.checkbox1.Tooltip = {'''Suelo Posterior del muro'''; 'Seleccionar si hay suelo Posterorior en el muro.'};
            app.checkbox1.Text = 'Suelo Posterior';
            app.checkbox1.Value = true;
            app.edit7 = uieditfield(app.uipanel1, 'text');
            app.edit7.Tag = 'edit7';
            app.edit8 = uieditfield(app.uipanel1, 'text');
            app.edit8.Tag = 'edit8';
            app.edit9 = uieditfield(app.uipanel1, 'text');
            app.edit9.Tag = 'edit9';
            app.text13 = uilabel(app.uipanel1);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Espesor (m)';
            app.text14 = uilabel(app.uipanel1);
            app.text14.Tag = 'text14';
            app.text14.Text = 'P.e. (kN/m3)';
            app.text15 = uilabel(app.uipanel1);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Phi (°)';
            app.text16 = uilabel(app.uipanel1);
            app.text16.Tag = 'text16';
            app.text16.Text = 'C';
            app.text17 = uilabel(app.uipanel1);
            app.text17.Tag = 'text17';
            app.text17.Text = 'kPa';
            app.popupmenu2 = uidropdown(app.uipanel1);
            app.popupmenu2.Items = {'', 'Suelo Seco', 'Suelo Saturado'};
            app.popupmenu2.ValueChangedFcn = createCallbackFcn(app, @popupmenu2_Callback, true);
            app.popupmenu2.Tag = 'popupmenu2';
            app.popupmenu2.Tooltip = {'''N.F'''; 'Nivel Freatico presenta 2 casos.'; '1. Suelo Seco'; '2. Suelo Saturado'};
            app.popupmenu2.Value = '';
            app.text19 = uilabel(app.uipanel1);
            app.text19.Tag = 'text19';
            app.text19.Text = '(m)';
            app.text20 = uilabel(app.uipanel1);
            app.text20.Tag = 'text20';
            app.text20.Text = '(kN/m3)';
            app.text21 = uilabel(app.uipanel1);
            app.text21.Tag = 'text20';
            app.text21.Text = '(grados°)';
            app.pushbutton3 = uibutton(app.uipanel1, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3ButtonPushed, true);
            app.pushbutton3.Tag = 'pushbutton3';
            app.pushbutton3.Tooltip = {'''Eliminar'''; 'Limpiar valores de la tabla'};
            app.pushbutton3.Text = 'Eliminar';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Estratigravedadpe_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)Estratigravedadpe_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end