classdef MGrape_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1              matlab.ui.Figure
        MenMenu              matlab.ui.container.Menu
        MenPrincipalMenu     matlab.ui.container.Menu
        AnlisisEstaticoMenu  matlab.ui.container.Menu
        salir                matlab.ui.control.Button
        pushbutton4          matlab.ui.control.Button
        uipanel5             matlab.ui.container.Panel
        textww3              matlab.ui.control.Label
        textww2              matlab.ui.control.Label
        Ww3                  matlab.ui.control.Label
        text37_3             matlab.ui.control.Label
        Ww2                  matlab.ui.control.Label
        text37_2             matlab.ui.control.Label
        uipanel1             matlab.ui.container.Panel
        sobrecarga           matlab.ui.control.Label
        text23               matlab.ui.control.Label
        alpha                matlab.ui.control.EditField
        text22               matlab.ui.control.Label
        text25               matlab.ui.control.Label
        phib                 matlab.ui.control.EditField
        text24               matlab.ui.control.Label
        carga                matlab.ui.control.EditField
        text116              matlab.ui.control.Label
        text49               matlab.ui.control.Label
        text48               matlab.ui.control.Label
        tal                  matlab.ui.control.EditField
        text47               matlab.ui.control.Label
        text46               matlab.ui.control.Label
        ht                   matlab.ui.control.EditField
        text45               matlab.ui.control.Label
        Base3                matlab.ui.control.EditField
        text44               matlab.ui.control.Label
        text34               matlab.ui.control.Label
        text32               matlab.ui.control.Label
        pconcreto            matlab.ui.control.EditField
        text31               matlab.ui.control.Label
        estratificacion      matlab.ui.control.Button
        uipanel4             matlab.ui.container.Panel
        checkbox1            matlab.ui.control.CheckBox
        text20               matlab.ui.control.Label
        deltas               matlab.ui.control.EditField
        text19               matlab.ui.control.Label
        text18               matlab.ui.control.Label
        text17               matlab.ui.control.Label
        Beta                 matlab.ui.control.EditField
        text16               matlab.ui.control.Label
        Base2                matlab.ui.control.EditField
        text15               matlab.ui.control.Label
        text12               matlab.ui.control.Label
        text11               matlab.ui.control.Label
        text9                matlab.ui.control.Label
        H                    matlab.ui.control.EditField
        uipanel3             matlab.ui.container.Panel
        popupmenu1           matlab.ui.control.DropDown
        uibuttongroup1       matlab.ui.container.ButtonGroup
        radiobutton1         matlab.ui.control.RadioButton
        radiobutton2         matlab.ui.control.RadioButton
        Base1                matlab.ui.control.EditField
        basec                matlab.ui.control.EditField
        text14               matlab.ui.control.Label
        text8                matlab.ui.control.Label
        text35               matlab.ui.control.Label
        kv                   matlab.ui.control.EditField
        text36               matlab.ui.control.Label
        kh                   matlab.ui.control.EditField
        text10               matlab.ui.control.Label
        Co                   matlab.ui.control.EditField
        text13               matlab.ui.control.Label
        uipanel8             matlab.ui.container.Panel
        Av_2                 matlab.ui.control.EditField
        Dr_3                 matlab.ui.control.EditField
        text115              matlab.ui.control.Label
        text114              matlab.ui.control.Label
        text113              matlab.ui.control.Label
        text112              matlab.ui.control.Label
        uibuttongroup5       matlab.ui.container.ButtonGroup
        text110              matlab.ui.control.Label
        text111              matlab.ui.control.Label
        text109              matlab.ui.control.Label
        text108              matlab.ui.control.Label
        radiobutton11        matlab.ui.control.RadioButton
        radiobutton12        matlab.ui.control.RadioButton
        radiobutton13        matlab.ui.control.RadioButton
        radiobutton14        matlab.ui.control.RadioButton
        text106              matlab.ui.control.Label
        text107              matlab.ui.control.Label
        uipanel2             matlab.ui.container.Panel
        textIs               matlab.ui.control.Label
        Is                   matlab.ui.control.Label
        FSVAs                matlab.ui.control.Label
        FSVs                 matlab.ui.control.Label
        FSDs                 matlab.ui.control.Label
        text39               matlab.ui.control.Label
        text37               matlab.ui.control.Label
        text30               matlab.ui.control.Label
        FSVA                 matlab.ui.control.Label
        FSVR                 matlab.ui.control.Label
        FSD                  matlab.ui.control.Label
        text6                matlab.ui.control.Label
        text5                matlab.ui.control.Label
        text4                matlab.ui.control.Label
        Calcular             matlab.ui.control.Button
        uibuttongroup2       matlab.ui.container.ButtonGroup
        radiobutton5         matlab.ui.control.RadioButton
        radiobutton4         matlab.ui.control.RadioButton
        radiobutton3         matlab.ui.control.RadioButton
        ayuda                matlab.ui.control.Button
        text3                matlab.ui.control.Label
        uipanel9             matlab.ui.container.Panel
        text115_3            matlab.ui.control.Label
        Av                   matlab.ui.control.EditField
        text113_3            matlab.ui.control.Label
        text115_2            matlab.ui.control.Label
        textdr               matlab.ui.control.Label
        Aa                   matlab.ui.control.EditField
        text113_2            matlab.ui.control.Label
        text112_2            matlab.ui.control.Label
        Dr_2                 matlab.ui.control.EditField
        axes1                matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function edit34_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit34 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit35_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit35 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit36_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit36 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit37_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit37 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit38_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit38 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit39_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit39 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit40_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit40 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit41_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit41 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit42_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit42 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit43_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit43 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit44_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit44 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit45_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit45 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit46_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit46 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit47_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit47 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit48_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit48 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function popupmenu5_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to popupmenu5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function MGrape_OpeningFcn(app, varargin)
            % --- Executes just before MGrape is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to MGrape (see VARARGIN)
            
            % Choose default command line output for MGrape
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);


            % UIWAIT makes MGrape wait for user response (see UIRESUME)
            % uiwait(handles.figure1;
            
            app.Calcular.Enable="off";
            app.uipanel8.Visible="off";
            app.uipanel9.Visible="off";
            app.uipanel5.Visible="off";
            set(handles.carga,'Visible','on');
            set(handles.text116,'Visible','on');
            
            global R1 Rad3 Rad14
           
            Rad3=1;
            Rad14=1;
            assignin('base','Rad3',Rad3);
            assignin('base','Rad14',Rad14);
            R1=1;
                set(handles.text14,'Visible','off');
                set(handles.text15,'Visible','off');
                set(handles.Base1,'Visible','off');
                set(handles.text11,'Visible','off')
                set(handles.Base2,'Visible','off');
                set(handles.text16,'Visible','off');
            
                set(handles.basec,'Visible','off');
                set(handles.text8,'Visible','off');
                set(handles.text10,'Visible','off');
                set(handles.Co,'Visible','off');
                set(handles.text13,'Visible','off');
            
                set(handles.text17,'Visible','off');
                set(handles.Beta,'Visible','off');
                set(handles.text18,'Visible','off');
                set(handles.text19,'Visible','off');
                set(handles.deltas,'Visible','off');
                set(handles.text20,'Visible','off');
            
                set(handles.text9,'Visible','off');
                set(handles.H,'Visible','off');
                set(handles.text12,'Visible','off')
            
                set(handles.alpha,'Visible','off');
                set(handles.text23,'Visible','off');
                set(handles.text22,'Visible','off');
                set(handles.phib,'Visible','off');
                set(handles.text24,'Visible','off');
                set(handles.text25,'Visible','off');
            
                set(handles.Base3,'Visible','off');
                set(handles.text44,'Visible','off');
                set(handles.text45,'Visible','off');
            
                set(handles.ht,'Visible','off');
                set(handles.text46,'Visible','off');
                set(handles.text47,'Visible','off');
                set(handles.tal,'Visible','off');
                set(handles.text48,'Visible','off');
                set(handles.text49,'Visible','off');
        end

        % Button pushed function: Calcular
        function Calcular_Callback(app, event)
            % --- Executes on button press in Calcular.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            % hObject    handle to Calcular (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global a espesor phi PesoE Cohesion  n k espe pe ph R R3 TS R1 R2 Rad3 Rad4 Rad14 Rad13 Rad12 Rad11 Rad5
            assignin('base','a',a);


            %SI hay inclinacion
            if R3==1
                alpha=str2double(get(handles.alpha, 'string'));
                So=str2double(get(handles.carga, 'string'));
            
            else
                alpha=0;
                 So=str2double(get(handles.carga, 'string'));
            
                if So<0
                    errordlg('Valor de la sobrecarga fuera de Rango,Valor menor de 0','ERROR')
                    return
                end
                if isnan(So)
                    errordlg('Valor de la sobrecarga no numerico','ERROR')
                    return
                end
            
            end
            assignin('base','So',So);
            %%%%%%%%%%%%%%%%%%%%%%%%
            if Rad3==1
                kh=str2double(get(handles.kh, 'string'));
                kv=str2double(get(handles.kv, 'string'));
                assignin('base','kh',kh);
                assignin('base','kv',kv);


                if kh<0
                    errordlg('Valor de kh fuera de Rango,Valor menor de 0','ERROR')
                    return
                end
                if isnan(kh)
                    errordlg('Valor de kh no numerico','ERROR')
                    return
                end
                %%kv
                 if kv<0
                     errordlg('Valor de kv fuera de Rango,Valor menor de 0 ','ERROR')
                    return
                 end
                 if isnan(kv)
                     errordlg('Valor de kv no numerico','ERROR')
                     return
                 end
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%
                if a==2
                    Base1=str2double(get(handles.Base1, 'string')); % Base del muro de contencion
                    Base2=str2double(get(handles.Base2, 'string')); % Base del muro de contencion
                    Ha=str2double(get(handles.H, 'string')); % altura total
                    D=str2double(get(handles.deltas, 'string'));
                    B=str2double(get(handles.Beta, 'string'));
                    phib=str2double(get(handles.phib, 'string'));
                    concreto=str2double(get(handles.pconcreto, 'string'));
                    %%%%%%%%%%%%%%%%%%%%%%CHEQUEO

                    if R1==1
                        if concreto<14
                            errordlg('Valor del peso específico del concreto fuera de Rango,Valor menor de 14 kN/m3, elegir un concreto de mayor resistencia','ERROR')
                            return
                        end
                        if isnan(concreto)
                            errordlg('Valor del peso específico del concreto no numerico','ERROR')
                            return
                        end


                    end

                    if R2==1
                        if concreto<90
                            errordlg('Valor del peso específico del concreto fuera de Rango,Valor menor de 90 lb/ft3, elegir un concreto de mayor resistencia','ERROR')
                            return
                        end
                        if isnan(concreto)
                            errordlg('Valor del peso específico del concreto no numerico','ERROR')
                            return
                        end                

                   end
                     %Altura
                     if Ha<0
                         errordlg('Valor de altura fuera de Rango,Valor menor de 0 ','ERROR')
                        return
                     end
                     if isnan(Ha)
                         errordlg('Valor de la altura no numerico','ERROR')
                         return
                     end
                    %Base1
                    if Base1<0
                        errordlg('Valor de Base fuera de Rango,Valor menor de 0 ','ERROR')
                        return
                    end
                    if isnan(Base1)
                        errordlg('Valor de la base no numerico','ERROR')
                        return
                    end
                     %Base2
                    if Base2<0
                        errordlg('Valor de Base fuera de Rango,Valor menor de 0 ','ERROR')
                        return
                    end
                    if isnan(Base2)
                        errordlg('Valor de la base no numerico','ERROR')
                        return
                    end
                   %delta
                    if D<0
                        errordlg('Valor de Delta fuera de Rango,Valor menor de 0','ERROR')
                        return
                    end
                    if isnan(D)
                        errordlg('Valor de delta no numerico','ERROR')
                        return
                    end
            
                    %beta
                    if B<90 || B>90
                        warndlg('Recuerde que para este tipo de muro Beta es 90','Warning')
                        return
                    end
                    if isnan(B)
                        errordlg('Valor de beta no numerico','ERROR')
                        return
                    end
            
                    %alpha
                    if alpha<0
                        errordlg('Valor de Alpha fuera de Rango,Valor menor de 0','ERROR')
                        return
                    end
                    if isnan(alpha)
                        errordlg('Valor de alpha no numerico','ERROR')
                        return
                    end


                    %PHI BASE MURO
                    if phib<0
                        errordlg('Valor fuera de Rango, Valores de ΦBase entre 0.5Φ a 0.67Φ ','ERROR')
                        return
                    end
                    if isnan(phib)
                        errordlg('Valor de Phi de la base no numerico','ERROR')
                        return
                    end


                    [FSD,FSVR,FSVA,FSDs,FSVRs,FSVAs,dEae]= Mtripseudo(Base1,Base2,Ha,D,B,alpha,phib,concreto,espesor,phi,PesoE,Cohesion,n,k,espe,pe,ph,R,TS,R1,R2,kh,kv,So);
            
                    set(handles.FSD,'String',round(FSD,2));
                    set(handles.FSVR,'String',round(FSVR,2));
                    set(handles.FSVA,'String',round(FSVA,2));
                    %%%Seudo
                    set(handles.FSDs,'String',round(FSDs,2));
                    set(handles.FSVs,'String',round(FSVRs,2));
                    set(handles.FSVAs,'String',round(FSVAs,2));
                    set(handles.Is,'String',round(dEae,2));


                end
                %%%%%%%%%%%%%%%%%%55
                if a==3
                     Base=str2double(get(handles.basec, 'string')); % Base del muro de contencion
                     Ha=str2double(get(handles.H, 'string'));
                     Co=str2double(get(handles.Co, 'string'));
                     B=str2double(get(handles.Beta, 'string'));
                     assignin('base','BB',B);
                     D=str2double(get(handles.deltas, 'string'));
                     phib=str2double(get(handles.phib, 'string'));
                     concreto=str2double(get(handles.pconcreto, 'string'));
                     %%%%%%%%%%%%%%%%%%%%%%CHEQUEO
                     %Altura
                     if Ha<0
                         errordlg('Valor de altura fuera de Rango,Valor menor de 0 ','ERROR')
                        return
                     end
                     if isnan(Ha)
                         errordlg('Valor de la altura no numerico','ERROR')
                         return
                     end
                    %Base
                    if Base<0
                        errordlg('Valor de Base fuera de Rango,Valor menor de 0 ','ERROR')
                        return
                    end
                    if isnan(Base)
                        errordlg('Valor de la base no numerico','ERROR')
                        return
                    end
                    %Corona
                    if Co<0
                        errordlg('Valor de Corona fuera de Rango,Valor menor de 0','ERROR')
                        return
                    end
                    if isnan(Co)
                        errordlg('Valor de la corona no numerico','ERROR')
                        return
                    end
                    %delta
                    if D<0
                        errordlg('Valor de Delta fuera de Rango,Valor menor de 0','ERROR')
                        return
                    end
                    if isnan(D)
                        errordlg('Valor de delta no numerico','ERROR')
                        return
                    end
            
                    %beta
                    if B<0
                        errordlg('Valor de Beta fuera de Rango,Valor menor de 0','ERROR')
                        return
                    end
                    if isnan(B)
                        errordlg('Valor de beta no numerico','ERROR')
                        return
                    end
                    %alpha
                    if alpha<0
                        errordlg('Valor de Alpha fuera de Rango,Valor menor de 0','ERROR')
                        return
                    end
                    if isnan(alpha)
                        errordlg('Valor de alpha no numerico','ERROR')
                        return
                    end


                    %PHI BASE MURO
                    if phib<0
                        errordlg('Valor fuera de Rango, Valores de ΦBase entre 0.5Φ a 0.67Φ ','ERROR')
                        return
                    end
                    if isnan(phib)
                        errordlg('Valor de Phi de la base no numerico','ERROR')
                        return
                    end
            
            
                     [FSD,FSVR,FSVA,FSDs,FSVRs,FSVAs,dEae]= Mtrapseudo(Base,Co,Ha,D,B,alpha,phib,concreto,espesor,phi,PesoE,Cohesion,n,k,espe,pe,ph,R,TS,R1,R2,kh,kv,So);
                     set(handles.FSD,'String',round(FSD,2));
                     set(handles.FSVR,'String',round(FSVR,2));
                     set(handles.FSVA,'String',round(FSVA,2));
                     %%%Seudo
                    set(handles.FSDs,'String',round(FSDs,2));
                    set(handles.FSVs,'String',round(FSVRs,2));
                    set(handles.FSVAs,'String',round(FSVAs,2));
                    set(handles.Is,'String',round(dEae,2));
            
                end
            
                %%%%%%%%%%%%%%%%%%%%%%%%%
                if a==4
                    Base1=str2double(get(handles.Base1, 'string')); % Base del muro de contencion
                    Base2=str2double(get(handles.Base2, 'string')); % Base del muro de contencion
                    Base3=str2double(get(handles.Base3, 'string')); % Base del muro de contencion
                    ht=str2double(get(handles.ht, 'string')); % altura total
                    tal=str2double(get(handles.tal, 'string')); % altura total
                    Ha=str2double(get(handles.H, 'string')); % altura total
                    D=str2double(get(handles.deltas, 'string'));
                    B=str2double(get(handles.Beta, 'string'));
                    phib=str2double(get(handles.phib, 'string'));
                    concreto=str2double(get(handles.pconcreto, 'string'));
                    %%%%%%%%%%%%%%%%%%%%%%CHEQUEO
                     %Altura
                     if Ha<0
                         errordlg('Valor de altura fuera de Rango,Valor menor de 0 ','ERROR')
                        return
                     end
                     if isnan(Ha)
                         errordlg('Valor de la altura no numerico','ERROR')
                         return
                     end
                    %Base1
                    if Base1<0
                        errordlg('Valor de Base fuera de Rango,Valor menor de 0 ','ERROR')
                        return
                    end
                    if isnan(Base1)
                        errordlg('Valor de la base no numerico','ERROR')
                        return
                    end
                     %Base2
                    if Base2<0
                        errordlg('Valor de Base fuera de Rango,Valor menor de 0 ','ERROR')
                        return
                    end
                    if isnan(Base2)
                        errordlg('Valor de la base no numerico','ERROR')
                        return
                    end
                    if Base3<0
                        errordlg('Valor de Base fuera de Rango,Valor menor de 0 ','ERROR')
                        return
                    end
                    if isnan(Base3)
                        errordlg('Valor de la base no numerico','ERROR')
                        return
                    end
                   %delta
                    if D<0
                        errordlg('Valor de Delta fuera de Rango,Valor menor de 0','ERROR')
                        return
                    end
                    if isnan(D)
                        errordlg('Valor de delta no numerico','ERROR')
                        return
                    end
            
                    %beta
                    if B<90 || B>90
                        warndlg('Recuerde que para este tipo de muro Beta es 90','Warning')
                        return
                    end
                    if isnan(B)
                        errordlg('Valor de beta no numerico','ERROR')
                        return
                    end
            
                    %alpha
                    if alpha<0
                        errordlg('Valor de Alpha fuera de Rango,Valor menor de 0','ERROR')
                        return
                    end
                    if isnan(alpha)
                        errordlg('Valor de alpha no numerico','ERROR')
                        return
                    end


                    %PHI BASE MURO
                    if phib<0
                        errordlg('Valor fuera de Rango, Valores de ΦBase entre 0.5Φ a 0.67Φ ','ERROR')
                        return
                    end
                    if isnan(phib)
                        errordlg('Valor de Phi de la base no numerico','ERROR')
                        return
                    end


                    [FSD,FSVR,FSVA,FSDs,FSVRs,FSVAs,dEae]= Mtrizapseudo(Base1,Base2,Base3,Ha,ht,tal,D,B,alpha,phib,concreto,espesor,phi,PesoE,Cohesion,n,k,espe,pe,ph,R,TS,R1,R2,kh,kv,So);
            
                    set(handles.FSD,'String',round(FSD,2));
                    set(handles.FSVR,'String',round(FSVR,2));
                    set(handles.FSVA,'String',round(FSVA,2));
                    %%%Seudo
                    set(handles.FSDs,'String',round(FSDs,2));
                    set(handles.FSVs,'String',round(FSVRs,2));
                    set(handles.FSVAs,'String',round(FSVAs,2));
                    set(handles.Is,'String',round(dEae,2));
                end
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%PARTE SIMPLIFICADA
            %AV_2 ==as DR_3==Dr
            if Rad4==1
                Dr=str2double(app.Dr_3.Value);% Base del muro de contencion
                as=str2double(app.Av_2.Value);% Base del muro de contencion
                assignin('base','DR',Dr);


                kv=str2double(get(handles.kv, 'string'));
                
                assignin('base','as',as);
                assignin('base','kv',kv);
                %TOCA METERLOS PORQUE ES CUANDO APARECEN RAD11 RAD12...
                if Rad11==1
                    kh=(0.622-(Dr*100)/47)*as;
                end
                if Rad12==1
                    kh=(0.586-(Dr*100)/47)*as;
                end
                if Rad13==1
                    kh=(0.551-(Dr*100)/47)*as;
                end
                if Rad14==1
                    kh=(0.489-(Dr*100)/47)*as;
                end
                assignin('base','kh',kh);
                if a==3
                    errordlg('Para Este tipo de muro(Trapezoide) no se tiene encuenta el metodo simplificado ya que es especifico para muros con un "BETA"de 90° ','ERROR')
                    return
                end
            
                if a==2
                    Base1=str2double(get(handles.Base1, 'string')); % Base del muro de contencion
                    Base2=str2double(get(handles.Base2, 'string')); % Base del muro de contencion
                    Ha=str2double(get(handles.H, 'string')); % altura total
                    D=str2double(get(handles.deltas, 'string'));
                    B=str2double(get(handles.Beta, 'string'));
                    phib=str2double(get(handles.phib, 'string'));
                    concreto=str2double(get(handles.pconcreto, 'string'));
                    %%%%%%%%%%%%%%%%%%%%%%CHEQUEO
                     %DR
                     if Dr<0
                         errordlg('Valor de Desplazamiento restrigido fuera de Rango,Valor menor de 0 ','ERROR')
                        return
                     end
                     if isnan(Dr)
                         errordlg('Valor deL Desplazamiento restigido es no numerico','ERROR')
                         return
                     end
                     %as
                     if as<0
                         errordlg('Valor de aceleración maxima del terreo fuera de Rango,Valor menor de 0 ','ERROR')
                        return
                     end
                     if isnan(as)
                         errordlg('Valor de aceleración maxima del terreo no numerico','ERROR')
                         return
                     end
                     %kv
                     if kv<0
                         errordlg('Valor de kv fuera de Rango,Valor menor de 0 ','ERROR')
                        return
                     end
                     if isnan(kv)
                         errordlg('Valor de kv no numerico','ERROR')
                         return
                     end
            
            
                     %Altura
                     if Ha<0
                         errordlg('Valor de altura fuera de Rango,Valor menor de 0 ','ERROR')
                        return
                     end
                     if isnan(Ha)
                         errordlg('Valor de la altura no numerico','ERROR')
                         return
                     end
                    %Base1
                    if Base1<0
                        errordlg('Valor de Base fuera de Rango,Valor menor de 0 ','ERROR')
                        return
                    end
                    if isnan(Base1)
                        errordlg('Valor de la base no numerico','ERROR')
                        return
                    end
                     %Base2
                    if Base2<0
                        errordlg('Valor de Base fuera de Rango,Valor menor de 0 ','ERROR')
                        return
                    end
                    if isnan(Base2)
                        errordlg('Valor de la base no numerico','ERROR')
                        return
                    end
                   %delta
                    if D<0
                        errordlg('Valor de Delta fuera de Rango,Valor menor de 0','ERROR')
                        return
                    end
                    if isnan(D)
                        errordlg('Valor de delta no numerico','ERROR')
                        return
                    end
            
                    %beta
                    if B<90 || B>90
                        warndlg('Recuerde que para este tipo de muro Beta es 90','Warning')
                        return
                    end
                    if isnan(B)
                        errordlg('Valor de beta no numerico','ERROR')
                        return
                    end
            
                    %alpha
                    if alpha<0
                        errordlg('Valor de Alpha fuera de Rango,Valor menor de 0','ERROR')
                        return
                    end
                    if isnan(alpha)
                        errordlg('Valor de alpha no numerico','ERROR')
                        return
                    end


                    %PHI BASE MURO
                    if phib<0
                        errordlg('Valor fuera de Rango, Valores de ΦBase entre 0.5Φ a 0.67Φ ','ERROR')
                        return
                    end
                    if isnan(phib)
                        errordlg('Valor de Phi de la base no numerico','ERROR')
                        return
                    end


                    [FSD,FSVR,FSVA,FSDs,FSVRs,FSVAs,dEae]= MtripseudoSIM(Base1,Base2,Ha,D,B,alpha,phib,concreto,espesor,phi,PesoE,Cohesion,n,k,espe,pe,ph,R,TS,R1,R2,kh,kv,as,So);
            
                    set(handles.FSD,'String',round(FSD,2));
                    set(handles.FSVR,'String',round(FSVR,2));
                    set(handles.FSVA,'String',round(FSVA,2));
                    %%%Seudo
                    set(handles.FSDs,'String',round(FSDs,2));
                    set(handles.FSVs,'String',round(FSVRs,2));
                    set(handles.FSVAs,'String',round(FSVAs,2));
                    set(handles.Is,'String',round(dEae,2));
                end
                
                %%%%%%%MURO Triza
                if a==4
                    Base1=str2double(get(handles.Base1, 'string')); % Base del muro de contencion
                    Base2=str2double(get(handles.Base2, 'string')); % Base del muro de contencion
                    Base3=str2double(get(handles.Base3, 'string')); % Base del muro de contencion
                    ht=str2double(get(handles.ht, 'string')); % altura total
                    tal=str2double(get(handles.tal, 'string')); % altura total
                    Ha=str2double(get(handles.H, 'string')); % altura total
                    D=str2double(get(handles.deltas, 'string'));
                    B=str2double(get(handles.Beta, 'string'));
                    phib=str2double(get(handles.phib, 'string'));
                    concreto=str2double(get(handles.pconcreto, 'string'));
                    %%%%%%%%%%%%%%%%%%%%%%CHEQUEO
                     %Altura
                     if Ha<0
                         errordlg('Valor de altura fuera de Rango,Valor menor de 0 ','ERROR')
                        return
                     end
                     if isnan(Ha)
                         errordlg('Valor de la altura no numerico','ERROR')
                         return
                     end
                    %Base1
                    if Base1<0
                        errordlg('Valor de Base fuera de Rango,Valor menor de 0 ','ERROR')
                        return
                    end
                    if isnan(Base1)
                        errordlg('Valor de la base no numerico','ERROR')
                        return
                    end
                     %Base2
                    if Base2<0
                        errordlg('Valor de Base fuera de Rango,Valor menor de 0 ','ERROR')
                        return
                    end
                    if isnan(Base2)
                        errordlg('Valor de la base no numerico','ERROR')
                        return
                    end
                    if Base3<0
                        errordlg('Valor de Base fuera de Rango,Valor menor de 0 ','ERROR')
                        return
                    end
                    if isnan(Base3)
                        errordlg('Valor de la base no numerico','ERROR')
                        return
                    end
                   %delta
                    if D<0
                        errordlg('Valor de Delta fuera de Rango,Valor menor de 0','ERROR')
                        return
                    end
                    if isnan(D)
                        errordlg('Valor de delta no numerico','ERROR')
                        return
                    end
            
                    %beta
                    if B<90 || B>90
                        warndlg('Recuerde que para este tipo de muro Beta es 90','Warning')
                        return
                    end
                    if isnan(B)
                        errordlg('Valor de beta no numerico','ERROR')
                        return
                    end
            
                    %alpha
                    if alpha<0
                        errordlg('Valor de Alpha fuera de Rango,Valor menor de 0','ERROR')
                        return
                    end
                    if isnan(alpha)
                        errordlg('Valor de alpha no numerico','ERROR')
                        return
                    end


                    %PHI BASE MURO
                    if phib<0
                        errordlg('Valor fuera de Rango, Valores de ΦBase entre 0.5Φ a 0.67Φ ','ERROR')
                        return
                    end
                    if isnan(phib)
                        errordlg('Valor de Phi de la base no numerico','ERROR')
                        return
                    end
            
            
                    [FSD,FSVR,FSVA,FSDs,FSVRs,FSVAs,dEae]= MtrizapseudoSIM(Base1,Base2,Base3,Ha,ht,tal,D,B,alpha,phib,concreto,espesor,phi,PesoE,Cohesion,n,k,espe,pe,ph,R,TS,R1,R2,kh,kv,as,So);
            
                    set(handles.FSD,'String',round(FSD,2));
                    set(handles.FSVR,'String',round(FSVR,2));
                    set(handles.FSVA,'String',round(FSVA,2));
                    %%%Seudo
                    set(handles.FSDs,'String',round(FSDs,2));
                    set(handles.FSVs,'String',round(FSVRs,2));
                    set(handles.FSVAs,'String',round(FSVAs,2));
                    set(handles.Is,'String',round(dEae,2));
                end
            
            
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%RICHARYELMS
            if Rad5==1
                DR=1000*str2double(app.Dr_2.Value); % Base del muro de contencion
                Aa=str2double(app.Aa.Value); % Base del muro de contencion
                Av=str2double(app.Av.Value); % altura total
                kv=str2double(get(handles.kv, 'string'));
                assignin('base','DR',DR);
                assignin('base','Aa',Aa);
                assignin('base','Av',Av);
            
                kh=Aa*(0.2*(Av)^2/(Aa*DR))^0.25;
                
                assignin('base','kh',kh);
                assignin('base','kv',kv);
            

                if DR<0
                    errordlg('Valor de DR fuera de Rango,Valor menor de 0','ERROR')
                    return
                end
                if isnan(DR)
                    errordlg('Valor de DR no numerico','ERROR')
                    return
                end
                if Aa<0
                    errordlg('Valor de Aa fuera de Rango,Valor menor de 0','ERROR')
                    return
                end
                if isnan(Aa)
                    errordlg('Valor de Aa no numerico','ERROR')
                    return
                end
                if Av<0
                    errordlg('Valor de Av fuera de Rango,Valor menor de 0','ERROR')
                    return
                end
                if isnan(Av)
                    errordlg('Valor de Av no numerico','ERROR')
                    return
                end
            
            
                if kh<0
                    errordlg('Valor de kh fuera de Rango,Valor menor de 0','ERROR')
                    return
                end
                if isnan(kh)
                    errordlg('Valor de kh no numerico','ERROR')
                    return
                end
                %%kv
                 if kv<0
                     errordlg('Valor de kv fuera de Rango,Valor menor de 0 ','ERROR')
                    return
                 end
                 if isnan(kv)
                     errordlg('Valor de kv no numerico','ERROR')
                     return
                 end
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%
                if a==2
                    Base1=str2double(get(handles.Base1, 'string')); % Base del muro de contencion
                    Base2=str2double(get(handles.Base2, 'string')); % Base del muro de contencion
                    Ha=str2double(get(handles.H, 'string')); % altura total
                    D=str2double(get(handles.deltas, 'string'));
                    B=str2double(get(handles.Beta, 'string'));
                    phib=str2double(get(handles.phib, 'string'));
                    concreto=str2double(get(handles.pconcreto, 'string'));
                    %%%%%%%%%%%%%%%%%%%%%%CHEQUEO
                     %Altura
                     if Ha<0
                         errordlg('Valor de altura fuera de Rango,Valor menor de 0 ','ERROR')
                        return
                     end
                     if isnan(Ha)
                         errordlg('Valor de la altura no numerico','ERROR')
                         return
                     end
                    %Base1
                    if Base1<0
                        errordlg('Valor de Base fuera de Rango,Valor menor de 0 ','ERROR')
                        return
                    end
                    if isnan(Base1)
                        errordlg('Valor de la base no numerico','ERROR')
                        return
                    end
                     %Base2
                    if Base2<0
                        errordlg('Valor de Base fuera de Rango,Valor menor de 0 ','ERROR')
                        return
                    end
                    if isnan(Base2)
                        errordlg('Valor de la base no numerico','ERROR')
                        return
                    end
                   %delta
                    if D<0
                        errordlg('Valor de Delta fuera de Rango,Valor menor de 0','ERROR')
                        return
                    end
                    if isnan(D)
                        errordlg('Valor de delta no numerico','ERROR')
                        return
                    end
            
                    %beta
                    if B<90 || B>90
                        warndlg('Recuerde que para este tipo de muro Beta es 90','Warning')
                        return
                    end
                    if isnan(B)
                        errordlg('Valor de beta no numerico','ERROR')
                        return
                    end
            
                    %alpha
                    if alpha<0
                        errordlg('Valor de Alpha fuera de Rango,Valor menor de 0','ERROR')
                        return
                    end
                    if isnan(alpha)
                        errordlg('Valor de alpha no numerico','ERROR')
                        return
                    end


                    %PHI BASE MURO
                    if phib<0
                        errordlg('Valor fuera de Rango, Valores de ΦBase entre 0.5Φ a 0.67Φ ','ERROR')
                        return
                    end
                    if isnan(phib)
                        errordlg('Valor de Phi de la base no numerico','ERROR')
                        return
                    end


                    [FSD,FSVR,FSVA,FSDs,FSVRs,FSVAs,dEae,Ww2,Ww3]= MtripseudoRyE(Base1,Base2,Ha,D,B,alpha,phib,concreto,espesor,phi,PesoE,Cohesion,n,k,espe,pe,ph,R,TS,R1,R2,kh,kv,So);
            
                    set(handles.FSD,'String',round(FSD,2));
                    set(handles.FSVR,'String',round(FSVR,2));
                    set(handles.FSVA,'String',round(FSVA,2));
                    %%%Seudo
                    set(handles.FSDs,'String',round(FSDs,2));
                    set(handles.FSVs,'String',round(FSVRs,2));
                    set(handles.FSVAs,'String',round(FSVAs,2));
                    set(handles.Is,'String',round(dEae,2));
                    app.Ww2.Text=num2str(round(Ww2,2));
                    app.Ww3.Text=num2str(round(Ww3,2));
                    
                end
                %%%%%%%%%%%%%%%%%%55
                if a==3
                     Base=str2double(get(handles.basec, 'string')); % Base del muro de contencion
                     Ha=str2double(get(handles.H, 'string'));
                     Co=str2double(get(handles.Co, 'string'));
                     B=str2double(get(handles.Beta, 'string'));
                     assignin('base','BB',B);
                     D=str2double(get(handles.deltas, 'string'));
                     phib=str2double(get(handles.phib, 'string'));
                     concreto=str2double(get(handles.pconcreto, 'string'));
                     %%%%%%%%%%%%%%%%%%%%%%CHEQUEO
                     %Altura
                     if Ha<0
                         errordlg('Valor de altura fuera de Rango,Valor menor de 0 ','ERROR')
                        return
                     end
                     if isnan(Ha)
                         errordlg('Valor de la altura no numerico','ERROR')
                         return
                     end
                    %Base
                    if Base<0
                        errordlg('Valor de Base fuera de Rango,Valor menor de 0 ','ERROR')
                        return
                    end
                    if isnan(Base)
                        errordlg('Valor de la base no numerico','ERROR')
                        return
                    end
                    %Corona
                    if Co<0
                        errordlg('Valor de Corona fuera de Rango,Valor menor de 0','ERROR')
                        return
                    end
                    if isnan(Co)
                        errordlg('Valor de la corona no numerico','ERROR')
                        return
                    end
                    %delta
                    if D<0
                        errordlg('Valor de Delta fuera de Rango,Valor menor de 0','ERROR')
                        return
                    end
                    if isnan(D)
                        errordlg('Valor de delta no numerico','ERROR')
                        return
                    end
            
                    %beta
                    if B<0
                        errordlg('Valor de Beta fuera de Rango,Valor menor de 0','ERROR')
                        return
                    end
                    if isnan(B)
                        errordlg('Valor de beta no numerico','ERROR')
                        return
                    end
                    %alpha
                    if alpha<0
                        errordlg('Valor de Alpha fuera de Rango,Valor menor de 0','ERROR')
                        return
                    end
                    if isnan(alpha)
                        errordlg('Valor de alpha no numerico','ERROR')
                        return
                    end


                    %PHI BASE MURO
                    if phib<0
                        errordlg('Valor fuera de Rango, Valores de ΦBase entre 0.5Φ a 0.67Φ ','ERROR')
                        return
                    end
                    if isnan(phib)
                        errordlg('Valor de Phi de la base no numerico','ERROR')
                        return
                    end
            
            
                     [FSD,FSVR,FSVA,FSDs,FSVRs,FSVAs,dEae,Ww2,Ww3]= MtrapseudoRyE(Base,Co,Ha,D,B,alpha,phib,concreto,espesor,phi,PesoE,Cohesion,n,k,espe,pe,ph,R,TS,R1,R2,kh,kv,So);
                     set(handles.FSD,'String',round(FSD,2));
                     set(handles.FSVR,'String',round(FSVR,2));
                     set(handles.FSVA,'String',round(FSVA,2));
                     %%%Seudo
                    set(handles.FSDs,'String',round(FSDs,2));
                    set(handles.FSVs,'String',round(FSVRs,2));
                    set(handles.FSVAs,'String',round(FSVAs,2));
                    set(handles.Is,'String',round(dEae,2));
                    app.Ww2.Text=num2str(round(Ww2,2));
                    app.Ww3.Text=num2str(round(Ww3,2));
            
                end
            
                %%%%%%%%%%%%%%%%%%%%%%%%%
                if a==4
                    Base1=str2double(get(handles.Base1, 'string')); % Base del muro de contencion
                    Base2=str2double(get(handles.Base2, 'string')); % Base del muro de contencion
                    Base3=str2double(get(handles.Base3, 'string')); % Base del muro de contencion
                    ht=str2double(get(handles.ht, 'string')); % altura total
                    tal=str2double(get(handles.tal, 'string')); % altura total
                    Ha=str2double(get(handles.H, 'string')); % altura total
                    D=str2double(get(handles.deltas, 'string'));
                    B=str2double(get(handles.Beta, 'string'));
                    phib=str2double(get(handles.phib, 'string'));
                    concreto=str2double(get(handles.pconcreto, 'string'));
                    %%%%%%%%%%%%%%%%%%%%%%CHEQUEO
                     %Altura
                     if Ha<0
                         errordlg('Valor de altura fuera de Rango,Valor menor de 0 ','ERROR')
                        return
                     end
                     if isnan(Ha)
                         errordlg('Valor de la altura no numerico','ERROR')
                         return
                     end
                    %Base1
                    if Base1<0
                        errordlg('Valor de Base fuera de Rango,Valor menor de 0 ','ERROR')
                        return
                    end
                    if isnan(Base1)
                        errordlg('Valor de la base no numerico','ERROR')
                        return
                    end
                     %Base2
                    if Base2<0
                        errordlg('Valor de Base fuera de Rango,Valor menor de 0 ','ERROR')
                        return
                    end
                    if isnan(Base2)
                        errordlg('Valor de la base no numerico','ERROR')
                        return
                    end
                    if Base3<0
                        errordlg('Valor de Base fuera de Rango,Valor menor de 0 ','ERROR')
                        return
                    end
                    if isnan(Base3)
                        errordlg('Valor de la base no numerico','ERROR')
                        return
                    end
                   %delta
                    if D<0
                        errordlg('Valor de Delta fuera de Rango,Valor menor de 0','ERROR')
                        return
                    end
                    if isnan(D)
                        errordlg('Valor de delta no numerico','ERROR')
                        return
                    end
            
                    %beta
                    if B<90 || B>90
                        warndlg('Recuerde que para este tipo de muro Beta es 90','Warning')
                        return
                    end
                    if isnan(B)
                        errordlg('Valor de beta no numerico','ERROR')
                        return
                    end
            
                    %alpha
                    if alpha<0
                        errordlg('Valor de Alpha fuera de Rango,Valor menor de 0','ERROR')
                        return
                    end
                    if isnan(alpha)
                        errordlg('Valor de alpha no numerico','ERROR')
                        return
                    end


                    %PHI BASE MURO
                    if phib<0
                        errordlg('Valor fuera de Rango, Valores de ΦBase entre 0.5Φ a 0.67Φ ','ERROR')
                        return
                    end
                    if isnan(phib)
                        errordlg('Valor de Phi de la base no numerico','ERROR')
                        return
                    end


                    [FSD,FSVR,FSVA,FSDs,FSVRs,FSVAs,dEae,Ww2,Ww3]= MtrizapseudoRyE(Base1,Base2,Base3,Ha,ht,tal,D,B,alpha,phib,concreto,espesor,phi,PesoE,Cohesion,n,k,espe,pe,ph,R,TS,R1,R2,kh,kv,So);
            
                    set(handles.FSD,'String',round(FSD,2));
                    set(handles.FSVR,'String',round(FSVR,2));
                    set(handles.FSVA,'String',round(FSVA,2));
                    %%%Seudo
                    set(handles.FSDs,'String',round(FSDs,2));
                    set(handles.FSVs,'String',round(FSVRs,2));
                    set(handles.FSVAs,'String',round(FSVAs,2));
                    set(handles.Is,'String',round(dEae,2));
                    app.Ww2.Text=num2str(round(Ww2,2));
                    app.Ww3.Text=num2str(round(Ww3,2));
                end
            end


        end

        % Button pushed function: ayuda
        function ayuda_Callback(app, event)
            % --- Executes on button press in ayuda.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ayuda (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            winopen('Fundamentos.pdf')
        end

        % Value changed function: checkbox1
        function checkbox1_Callback(app, event)
            % --- Executes on button press in checkbox1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox1
            global R3
            R3=get(hObject,'Value');
            if R3==1
                set(handles.alpha,'Visible','on');
                set(handles.text22,'Visible','on');
                set(handles.text23,'Visible','on');
            
            
            else
                set(handles.alpha,'Visible','off');
                set(handles.text22,'Visible','off');
                set(handles.text23,'Visible','off');


            end
        end

        % Button pushed function: estratificacion
        function estratificacion_Callback(app, event)
            % --- Executes on button press in estratificacion.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to estratificacion (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global R2 R1
            
            if R1==1
                R2=0;
                R11=1;
                R22=0;
            end
            if R2==1
                R1=0;
                R22=1;
                R11=0;
            end
            Estratigravedadpe_App(R11,R22)
            app.Calcular.Enable="on";
        end

        % Value changed function: popupmenu1
        function popupmenu1_Callback(app, event)
            % --- Executes on selection change in popupmenu1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu1 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu1
            global a
            a=get(hObject,'Value');
            
            if a==4% Muro con zapatas
            
                axes(handles.axes1);
                bkgrnd=imread('tz.jpg');
                imshow(bkgrnd);
            
                set(handles.text11,'Visible','on');
                set(handles.text14,'Visible','on');
                set(handles.text15,'Visible','on');
                set(handles.Base1,'Visible','on');
                set(handles.Base2,'Visible','on');
                set(handles.text16,'Visible','on');
            
                set(handles.text9,'Visible','on');
                set(handles.H,'Visible','on');
                set(handles.text12,'Visible','on');
                set(handles.text17,'Visible','on');
                set(handles.Beta,'Visible','on');
                set(handles.text18,'Visible','on');
                set(handles.phib,'Visible','on');
                set(handles.text24,'Visible','on');
                set(handles.text25,'Visible','on');
            
            
                set(handles.text19,'Visible','on');
                set(handles.deltas,'Visible','on');
                set(handles.text20,'Visible','on');
            
                set(handles.basec,'Visible','off');
                set(handles.text8,'Visible','off');
                set(handles.text10,'Visible','off');
                set(handles.Co,'Visible','off');
                set(handles.text13,'Visible','off');
            
            
                set(handles.Base3,'Visible','on');
                set(handles.text44,'Visible','on');
                set(handles.text45,'Visible','on');
                set(handles.ht,'Visible','on');
                set(handles.text46,'Visible','on');
                set(handles.text47,'Visible','on');
                set(handles.tal,'Visible','on');
                set(handles.text48,'Visible','on');
                set(handles.text49,'Visible','on');
            
            end
            
            if a==2% Muro Triangular
                axes(handles.axes1);
                bkgrnd=imread('Triangular.jpg');
                imshow(bkgrnd);
            
            
                set(handles.text14,'Visible','on');
                set(handles.text15,'Visible','on');
                set(handles.Base1,'Visible','on');
                set(handles.Base2,'Visible','on');
                set(handles.text16,'Visible','on');
                set(handles.text11,'Visible','on');
                set(handles.text9,'Visible','on');
                set(handles.H,'Visible','on');
                set(handles.text12,'Visible','on');
                set(handles.text17,'Visible','on');
                set(handles.Beta,'Visible','on');
                set(handles.text18,'Visible','on');
                set(handles.phib,'Visible','on');
                set(handles.text24,'Visible','on');
                set(handles.text25,'Visible','on');
            
            
                set(handles.text19,'Visible','on');
                set(handles.deltas,'Visible','on');
                set(handles.text20,'Visible','on');
            
                set(handles.basec,'Visible','off');
                set(handles.text8,'Visible','off');
                set(handles.text10,'Visible','off');
                set(handles.Co,'Visible','off');
                set(handles.text13,'Visible','off');
            
                set(handles.Base3,'Visible','off');
                set(handles.text44,'Visible','off');
                set(handles.text45,'Visible','off');
                set(handles.ht,'Visible','off');
                set(handles.text46,'Visible','off');
                set(handles.text47,'Visible','off');
                set(handles.tal,'Visible','off');
                set(handles.text48,'Visible','off');
                set(handles.text49,'Visible','off');
            end
            
            if a==3% Muro Gravedad treapezoidal
                axes(handles.axes1);
                bkgrnd=imread('cou.png');
                imshow(bkgrnd);
            
            
                set(handles.basec,'Visible','on');
                set(handles.text8,'Visible','on');
                set(handles.text9,'Visible','on');
                set(handles.H,'Visible','on');
                set(handles.text12,'Visible','on');
                set(handles.text10,'Visible','on');
                set(handles.text11,'Visible','on');
                set(handles.Co,'Visible','on');
                set(handles.text13,'Visible','on');
                set(handles.text19,'Visible','on');
                set(handles.deltas,'Visible','on');
                set(handles.text20,'Visible','on');
                set(handles.text17,'Visible','on');
                set(handles.Beta,'Visible','on');
                set(handles.text18,'Visible','on');
                set(handles.phib,'Visible','on');
                set(handles.text24,'Visible','on');
                set(handles.text25,'Visible','on');
            
                set(handles.Base3,'Visible','off');
                set(handles.text44,'Visible','off');
                set(handles.text45,'Visible','off');
            
            
                set(handles.text14,'Visible','off');
                set(handles.text15,'Visible','off');
                set(handles.Base1,'Visible','off');
                set(handles.Base2,'Visible','off');
                set(handles.text16,'Visible','off');
                set(handles.ht,'Visible','off');
                set(handles.text46,'Visible','off');
                set(handles.text47,'Visible','off');
                set(handles.tal,'Visible','off');
                set(handles.text48,'Visible','off');
                set(handles.text49,'Visible','off');
            
            
            end
        end

        % Callback function
        function salir_Callback(app, event)
            % --- Executes on button press in salir.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to salir (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.delete
        end

        % Callback function
        function pushbutton4ButtonPushed(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            set(handles.kh,'string','');
            set(handles.kv,'string','');
            set(handles.H,'string','');
            set(handles.Base1,'string','');
            set(handles.Base2,'string','');
            set(handles.Base3,'string','');
            set(handles.basec,'string','');
            set(handles.ht,'string','');
            set(handles.tal,'string','');
            set(handles.Co,'string','');
            set(handles.Beta,'string','');
            set(handles.deltas,'string','');
            set(handles.alpha,'string','');
            set(handles.carga,'string','');
            set(handles.phib,'string','');
            set(handles.pconcreto,'string','');
            app.Dr.Value='';
            app.as.Value='';
            app.Dr_2.Value='';
            app.Aa.Value='';
            app.Av.Value='';
            
            
        end

        % Selection changed function: uibuttongroup1
        function uibuttongroup1SelectionChanged(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            selectedButton = app.uibuttongroup1.SelectedObject;
            global R1 R2 R3 Rad4
            R1=app.radiobutton1.Value;
            R2=app.radiobutton2.Value;
            H=str2double(get(handles.H, 'string'));
            Base1=str2double(get(handles.Base1, 'string')); % Base del muro de contencion
            Base2=str2double(get(handles.Base2, 'string'));
            Base3=str2double(get(handles.Base3, 'string'));
            basec=str2double(get(handles.basec, 'string'));
            ht=str2double(get(handles.ht, 'string')); % altura total
            tal=str2double(get(handles.tal, 'string'));  %altura del talon
            Co=str2double(get(handles.Co, 'string')); %ancho del talon
            %beta=str2double(get(handles.Beta, 'string'));
            %delta=str2double(get(handles.deltas, 'string'));
            %alpha=str2double(get(handles.alpha, 'string')); %ancho de la corona
            So=str2double(get(handles.carga, 'string'));
            %phi=str2double(get(handles.phib, 'string'));
            concreto=str2double(get(handles.pconcreto, 'string'));
            if Rad4==1
                if R1==1
                     set(handles.text114,'string','m');
                     Dr=str2double(get(handles.Dr, 'string'));
                     set(handles.Dr,'String',round(Dr*0.3048 ,2));
                end
                if R2==1
                     set(handles.text114,'string','ft');
                     Dr=str2double(get(handles.Dr, 'string'));
                     set(handles.Dr,'String',round(Dr/0.3048 ,2));
                end
            end

            if R1==1
   
                set(handles.text11,'string','m');
                set(handles.text12,'string','m');
                set(handles.text13,'string','m');
                set(handles.text16,'string','m');
                set(handles.text45,'string','m');
                set(handles.text47,'string','m');
                set(handles.text49,'string','m');
                set(handles.sobrecarga,'string','kN/m');   
                set(handles.text32,'string','kN/m^{3}');
                set(handles.carga,'String',round(So/0.068521 ,2));
                if R3==0
                   set(handles.sobrecarga,'string','kN/m');   
                   set(handles.carga,'String',round(So/0.068521 ,2));
                end
                set(handles.H,'String',round(H*0.3048 ,2));
                set(handles.Base1,'String',round(Base1*0.3048 ,2));
                set(handles.Base2,'String',round(Base2*0.3048 ,2));
                set(handles.Base3,'String',round(Base3*0.3048 ,2));
                set(handles.basec,'String',round(basec*0.3048 ,2));
                
                set(handles.ht,'String',round(ht*0.3048 ,2));
                set(handles.tal,'String',round(tal*0.3048 ,2));
                set(handles.Co,'String',round(Co*0.3048 ,2));
                set(handles.pconcreto,'String',round(concreto/6.365880 ,2));

                %richardyelms
                app.sobrecarga.Text='kN/m';
                app.text114.Text='m';
                app.textdr.Text='m';
                app.textww2.Text='kN/m';
                app.textww3.Text='kN/m';
                app.textIs.Text='kN/m';

                
            end
            if R2==1
    
                set(handles.text11,'string','ft');
                set(handles.text12,'string','ft');
                set(handles.text13,'string','ft');
                set(handles.text16,'string','ft');
                set(handles.text45,'string','ft');
                set(handles.text47,'string','ft');
                set(handles.text49,'string','ft');
                set(handles.text32,'string','lb/ft^{3}');
                set(handles.sobrecarga,'string','kips/ft');
                set(handles.carga,'String',round(So*0.068521 ,2));
                if R3==0
                   set(handles.sobrecarga,'string','kips/ft');
                   set(handles.carga,'String',round(So* 0.068521 ,2));
                end
                set(handles.H,'String',round(H/0.3048 ,2));
                set(handles.Base1,'String',round(Base1/0.3048 ,2));
                set(handles.Base2,'String',round(Base2/0.3048 ,2));
                set(handles.Base3,'String',round(Base3/0.3048 ,2));
                set(handles.basec,'String',round(basec/0.3048 ,2));
                set(handles.ht,'String',round(ht*0.3048 ,2));
                set(handles.tal,'String',round(tal*0.3048 ,2));
                set(handles.Co,'String',round(Co*0.3048 ,2));
                set(handles.pconcreto,'String',round(concreto*6.365880 ,2));

                app.sobrecarga.Text='kips/ft';
                app.text114.Text='ft';
                app.textdr.Text='ft';
                app.textww2.Text='kips/ft';
                app.textww3.Text='kips/ft';
                app.textIs.Text='kips/ft';

                
            end


        end

        % Selection changed function: uibuttongroup2
        function uibuttongroup2SelectionChanged(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            selectedButton = app.uibuttongroup2.SelectedObject;
            global Rad3 Rad4 Rad5

            Rad3=app.radiobutton3.Value;
            Rad4=app.radiobutton4.Value;
            Rad5=app.radiobutton5.Value;
            assignin('base','Rad3',Rad3);
            assignin('base','Rad4',Rad4);
            assignin('base','Rad5',Rad5);


            if Rad3==1
                set(handles.uipanel1,'Visible','on');
                app.uipanel8.Visible="off";
                app.uipanel9.Visible="off";
                app.uipanel5.Visible="off";
                set(handles.kv,'Visible','on');
                set(handles.text36,'Visible','on');
                set(handles.kh,'Visible','on');
                set(handles.text35,'Visible','on');


            end

             
            if Rad4==1
                app.uipanel8.Visible="on";
                set(handles.uipanel1,'Visible','on');
                app.uipanel9.Visible="off";
                app.uipanel5.Visible="off";
                set(handles.kv,'Visible','on');
                set(handles.text36,'Visible','on');
                
                set(handles.kh,'Visible','off');
                set(handles.text35,'Visible','off');


            end
             if Rad5==1
                set(handles.uipanel1,'Visible','on');
                app.uipanel8.Visible="off";
                app.uipanel9.Visible="on";
                app.uipanel5.Visible="on";
                app.kh.Visible="off";
                set(handles.kv,'Visible','on');
                set(handles.text36,'Visible','on');
                set(handles.text35,'Visible','off');


            end


        end

        % Selection changed function: uibuttongroup5
        function uibuttongroup5SelectionChanged(app, event)
            selectedButton = app.uibuttongroup5.SelectedObject;
            global Rad11 Rad12 Rad13 Rad14
            Rad11=app.radiobutton11.Value;
            Rad12=app.radiobutton12.Value;
            Rad13=app.radiobutton13.Value;
            Rad14=app.radiobutton14.Value;
            assignin('base','Rad11',Rad11);
            assignin('base','Rad12',Rad12);
            assignin('base','Rad13',Rad13);
            assignin('base','Rad14',Rad14);
            
            
        end

        % Callback function
        function reporteButtonPushed(app, event)
         %Base1,Base2,Ha,D,B,alpha,phib,concreto,espesor,phi,PesoE,Cohesion,n,k,espe,pe,ph,R,TS,R1,R2,kh,kv,So 
         
         
        end

        % Button pushed function: pushbutton4
        function pushbutton4ButtonPushed2(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            set(handles.kh,'string','');
            set(handles.kv,'string','');
            set(handles.H,'string','');
            set(handles.Base1,'string','');
            set(handles.Base2,'string','');
            set(handles.Base3,'string','');
            set(handles.basec,'string','');
            set(handles.ht,'string','');
            set(handles.tal,'string','');
            set(handles.Co,'string','');
            set(handles.Beta,'string','');
            set(handles.deltas,'string','');
            set(handles.alpha,'string','');
            set(handles.carga,'string','');
            set(handles.phib,'string','');
            set(handles.pconcreto,'string','');
        end

        % Button pushed function: salir
        function salirButtonPushed(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to salir (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.delete
        end

        % Menu selected function: MenPrincipalMenu
        function MenPrincipalMenuSelected(app, event)
            app.delete
            press11
        end

        % Menu selected function: AnlisisEstaticoMenu
        function AnlisisEstaticoMenuSelected(app, event)
            app.delete
            press2
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Análisis pseudo-estático';
            app.figure1.Icon = fullfile(pathToMLAPP, 'ima', 'm.png');
            app.figure1.Tag = 'figure1';
            app.MenMenu = uimenu(app.figure1);
            app.MenMenu.Text = 'Menú';
            app.MenPrincipalMenu = uimenu(app.MenMenu);
            app.MenPrincipalMenu.MenuSelectedFcn = createCallbackFcn(app, @MenPrincipalMenuSelected, true);
            app.MenPrincipalMenu.Text = 'Menú Principal';
            app.AnlisisEstaticoMenu = uimenu(app.MenMenu);
            app.AnlisisEstaticoMenu.MenuSelectedFcn = createCallbackFcn(app, @AnlisisEstaticoMenuSelected, true);
            app.AnlisisEstaticoMenu.Text = 'Análisis Estatico';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.uipanel9 = uipanel(app.figure1);
            app.uipanel9.Title = 'Metodo Richards y Elms';
            app.uipanel9.Tag = 'uipanel8';
            app.Dr_2 = uieditfield(app.uipanel9, 'text');
            app.Dr_2.Tag = 'Dr';
            app.Dr_2.Tooltip = {'Valor de desplazamiento que se controla'};
            app.text112_2 = uilabel(app.uipanel9);
            app.text112_2.Tag = 'text112';
            app.text112_2.Text = {'Desp.'; 'Restringido '; '(Dr)'};
            app.text113_2 = uilabel(app.uipanel9);
            app.text113_2.Tag = 'text113';
            app.text113_2.Text = {'Coeficiente de acelereación '; 'efectiva (Aa)'; ''};
            app.Aa = uieditfield(app.uipanel9, 'text');
            app.Aa.Tag = 'as';
            app.Aa.Tooltip = {'Valor de aceleracíon maxima de la zona.'};
            app.textdr = uilabel(app.uipanel9);
            app.textdr.Interpreter = 'tex';
            app.textdr.Tag = 'text114';
            app.textdr.Text = 'm';
            app.text115_2 = uilabel(app.uipanel9);
            app.text115_2.Interpreter = 'tex';
            app.text115_2.Tag = 'text115';
            app.text115_2.Text = 'g';
            app.text113_3 = uilabel(app.uipanel9);
            app.text113_3.Tag = 'text113';
            app.text113_3.Text = {'Coeficiente de velocidad pico'; 'efectiva (Av)'; ''};
            app.Av = uieditfield(app.uipanel9, 'text');
            app.Av.Tag = 'as';
            app.Av.Tooltip = {'Valor de aceleracíon maxima de la zona.'};
            app.text115_3 = uilabel(app.uipanel9);
            app.text115_3.Interpreter = 'tex';
            app.text115_3.Tag = 'text115';
            app.text115_3.Text = 'g';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'PSEUDO-ESTÁTICO';
            app.ayuda = uibutton(app.figure1, 'push');
            app.ayuda.ButtonPushedFcn = createCallbackFcn(app, @ayuda_Callback, true);
            app.ayuda.Tag = 'ayuda';
            app.ayuda.Icon = fullfile(pathToMLAPP, 'ayuda.png');
            app.ayuda.Tooltip = {'''BOTON AYUDA PARA INFORMACIÓN DEL PROGRAMA'''};
            app.ayuda.Text = '';
            app.uibuttongroup2 = uibuttongroup(app.figure1);
            app.uibuttongroup2.SelectionChangedFcn = createCallbackFcn(app, @uibuttongroup2SelectionChanged, true);
            app.uibuttongroup2.Tooltip = {'Teorias de análisis'};
            app.uibuttongroup2.Title = 'TEORÍAS';
            app.uibuttongroup2.Tag = 'uibuttongroup2';
            app.radiobutton3 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton3.Tag = 'radiobutton3';
            app.radiobutton3.Text = 'Mononobe-Okabe';
            app.radiobutton3.Value = true;
            app.radiobutton4 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton4.Tag = 'radiobutton4';
            app.radiobutton4.Text = 'Método Simplificado';
            app.radiobutton5 = uiradiobutton(app.uibuttongroup2);
            app.radiobutton5.Tag = 'radiobutton4';
            app.radiobutton5.Text = 'Richard y Elms';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Analisis de Estabilidad';
            app.uipanel2.Tag = 'uipanel2';
            app.Calcular = uibutton(app.uipanel2, 'push');
            app.Calcular.ButtonPushedFcn = createCallbackFcn(app, @Calcular_Callback, true);
            app.Calcular.Tag = 'Calcular';
            app.Calcular.Tooltip = {'''Boton Calcular'''; 'Calcular los Factores de Seguridad '};
            app.Calcular.Text = 'Calcular';
            app.text4 = uilabel(app.uipanel2);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Revisión por Volcamiento (+Resistente)';
            app.text5 = uilabel(app.uipanel2);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Revisión por Deslizamiento';
            app.text6 = uilabel(app.uipanel2);
            app.text6.Tag = 'text6';
            app.text6.Text = 'F.S';
            app.FSD = uilabel(app.uipanel2);
            app.FSD.Tag = 'FSD';
            app.FSD.Text = '';
            app.FSVR = uilabel(app.uipanel2);
            app.FSVR.Tag = 'FSVR';
            app.FSVR.Text = '';
            app.FSVA = uilabel(app.uipanel2);
            app.FSVA.Tag = 'FSVA';
            app.FSVA.Text = '';
            app.text30 = uilabel(app.uipanel2);
            app.text30.Tag = 'text30';
            app.text30.Text = 'Revisión por Volcamiento                (-Actuante)';
            app.text37 = uilabel(app.uipanel2);
            app.text37.Tag = 'text37';
            app.text37.Text = {'Incremento'; 'Sísmico'};
            app.text39 = uilabel(app.uipanel2);
            app.text39.Tag = 'text39';
            app.text39.Text = {'F.S'; '(Sismo)'; ''};
            app.FSDs = uilabel(app.uipanel2);
            app.FSDs.Tag = 'FSDs';
            app.FSDs.Text = '';
            app.FSVs = uilabel(app.uipanel2);
            app.FSVs.Tag = 'FSVs';
            app.FSVs.Text = '';
            app.FSVAs = uilabel(app.uipanel2);
            app.FSVAs.Tag = 'FSVAs';
            app.FSVAs.Text = '';
            app.Is = uilabel(app.uipanel2);
            app.Is.Tag = 'Is';
            app.Is.Text = '';
            app.textIs = uilabel(app.uipanel2);
            app.textIs.Interpreter = 'tex';
            app.textIs.Tag = 'sobrecarga';
            app.textIs.Text = 'kN/m';
            app.uipanel8 = uipanel(app.figure1);
            app.uipanel8.Title = 'Metódo Simplifiado';
            app.uipanel8.Tag = 'uipanel8';
            app.uibuttongroup5 = uibuttongroup(app.uipanel8);
            app.uibuttongroup5.SelectionChangedFcn = createCallbackFcn(app, @uibuttongroup5SelectionChanged, true);
            app.uibuttongroup5.Tag = 'uibuttongroup5';
            app.text107 = uilabel(app.uibuttongroup5);
            app.text107.Tag = 'text107';
            app.text107.Text = 'Kh';
            app.text106 = uilabel(app.uibuttongroup5);
            app.text106.Tag = 'text106';
            app.text106.Text = 'ZONA SÍSMICA';
            app.radiobutton14 = uiradiobutton(app.uibuttongroup5);
            app.radiobutton14.Tag = 'radiobutton14';
            app.radiobutton14.Text = '1';
            app.radiobutton14.Value = true;
            app.radiobutton13 = uiradiobutton(app.uibuttongroup5);
            app.radiobutton13.Tag = 'radiobutton13';
            app.radiobutton13.Text = '2';
            app.radiobutton12 = uiradiobutton(app.uibuttongroup5);
            app.radiobutton12.Tag = 'radiobutton12';
            app.radiobutton12.Text = '3';
            app.radiobutton11 = uiradiobutton(app.uibuttongroup5);
            app.radiobutton11.Tag = 'radiobutton11';
            app.radiobutton11.Text = '4';
            app.text108 = uilabel(app.uibuttongroup5);
            app.text108.Tag = 'text108';
            app.text108.Text = '0.35';
            app.text109 = uilabel(app.uibuttongroup5);
            app.text109.Tag = 'text109';
            app.text109.Text = '0.25';
            app.text111 = uilabel(app.uibuttongroup5);
            app.text111.Tag = 'text111';
            app.text111.Text = '0.10';
            app.text110 = uilabel(app.uibuttongroup5);
            app.text110.Tag = 'text110';
            app.text110.Text = '0.18';
            app.text112 = uilabel(app.uipanel8);
            app.text112.Tag = 'text112';
            app.text112.Text = {'Desp.'; 'Restringido '; '(Dr)'};
            app.text113 = uilabel(app.uipanel8);
            app.text113.Tag = 'text113';
            app.text113.Text = {'Acelereación'; 'Máxima'; ''};
            app.text114 = uilabel(app.uipanel8);
            app.text114.Interpreter = 'tex';
            app.text114.Tag = 'text114';
            app.text114.Text = 'm';
            app.text115 = uilabel(app.uipanel8);
            app.text115.Interpreter = 'tex';
            app.text115.Tag = 'text115';
            app.text115.Text = 'g';
            app.Dr_3 = uieditfield(app.uipanel8, 'text');
            app.Dr_3.Tag = 'Dr';
            app.Dr_3.Tooltip = {'Valor de desplazamiento que se controla'};
            app.Av_2 = uieditfield(app.uipanel8, 'text');
            app.Av_2.Tag = 'as';
            app.Av_2.Tooltip = {'Valor de aceleracíon maxima de la zona.'};
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Tag = 'uipanel1';
            app.text13 = uilabel(app.uipanel1);
            app.text13.Interpreter = 'tex';
            app.text13.Tag = 'text13';
            app.text13.Text = 'm';
            app.Co = uieditfield(app.uipanel1, 'text');
            app.Co.Tag = 'Co';
            app.text10 = uilabel(app.uipanel1);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Corona';
            app.kh = uieditfield(app.uipanel1, 'text');
            app.kh.Tag = 'kh';
            app.kh.Tooltip = {'Coeficiente de aceleración horizontal'};
            app.text36 = uilabel(app.uipanel1);
            app.text36.Tag = 'text36';
            app.text36.Text = 'Kv';
            app.kv = uieditfield(app.uipanel1, 'text');
            app.kv.Tag = 'kv';
            app.kv.Tooltip = {'Coeficiente de aceleración vertical'};
            app.text35 = uilabel(app.uipanel1);
            app.text35.Tag = 'text35';
            app.text35.Text = 'Kh';
            app.text8 = uilabel(app.uipanel1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Base Muro';
            app.text14 = uilabel(app.uipanel1);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Base 1 (B1)';
            app.basec = uieditfield(app.uipanel1, 'text');
            app.basec.Tag = 'basec';
            app.Base1 = uieditfield(app.uipanel1, 'text');
            app.Base1.Tag = 'Base1';
            app.uibuttongroup1 = uibuttongroup(app.uipanel1);
            app.uibuttongroup1.SelectionChangedFcn = createCallbackFcn(app, @uibuttongroup1SelectionChanged, true);
            app.uibuttongroup1.Tooltip = {'Sistema de unidades'};
            app.uibuttongroup1.Title = 'SISTEMA DE UNIDADES';
            app.uibuttongroup1.Tag = 'uibuttongroup1';
            app.radiobutton2 = uiradiobutton(app.uibuttongroup1);
            app.radiobutton2.Tag = 'radiobutton2';
            app.radiobutton2.Text = 'S.Inglés';
            app.radiobutton1 = uiradiobutton(app.uibuttongroup1);
            app.radiobutton1.Tag = 'radiobutton1';
            app.radiobutton1.Text = 'S.Internacional';
            app.radiobutton1.Value = true;
            app.uipanel3 = uipanel(app.uipanel1);
            app.uipanel3.Tooltip = {'3 tipos de geometrias.'; '1. Trapezoidal Rectangular'; '2. Trapezoidal Isósceles'; '3. Canteliver o en Voladizo'};
            app.uipanel3.Title = 'Tipo de Muro';
            app.uipanel3.Tag = 'uipanel3';
            app.popupmenu1 = uidropdown(app.uipanel3);
            app.popupmenu1.Items = {'', 'Trapezoidal Rectangular', 'Trapezoidal Isósceles', 'En Voladizo'};
            app.popupmenu1.ValueChangedFcn = createCallbackFcn(app, @popupmenu1_Callback, true);
            app.popupmenu1.Tag = 'popupmenu1';
            app.popupmenu1.Value = '';
            app.H = uieditfield(app.uipanel1, 'text');
            app.H.Tag = 'H';
            app.H.Tooltip = {'''Altura total del muro de retención'''};
            app.text9 = uilabel(app.uipanel1);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Altura muro (H)';
            app.text11 = uilabel(app.uipanel1);
            app.text11.Interpreter = 'tex';
            app.text11.Tag = 'text11';
            app.text11.Text = 'm';
            app.text12 = uilabel(app.uipanel1);
            app.text12.Interpreter = 'tex';
            app.text12.Tag = 'text12';
            app.text12.Text = 'm';
            app.text15 = uilabel(app.uipanel1);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Base 2 (B2)';
            app.Base2 = uieditfield(app.uipanel1, 'text');
            app.Base2.Tag = 'Base2';
            app.text16 = uilabel(app.uipanel1);
            app.text16.Interpreter = 'tex';
            app.text16.Tag = 'text16';
            app.text16.Text = 'm';
            app.Beta = uieditfield(app.uipanel1, 'text');
            app.Beta.Tag = 'Beta';
            app.Beta.Tooltip = {'''Ángulo Beta'''; 'Inclinación del muro de gravedad.'; 'Para los muros ''Trapezoidal rectangular'' y ''Muro en Voladizo'' Beta es 90°'};
            app.text17 = uilabel(app.uipanel1);
            app.text17.Tag = 'text17';
            app.text17.Text = {'Ángulo de'; 'Inclinación'; 'del muro (β) '};
            app.text18 = uilabel(app.uipanel1);
            app.text18.Interpreter = 'tex';
            app.text18.Tag = 'text18';
            app.text18.Text = 'grados°';
            app.text19 = uilabel(app.uipanel1);
            app.text19.Tag = 'text19';
            app.text19.Text = {'Ángulo de'; 'fricción'; 'suelo-muro (δ)'};
            app.deltas = uieditfield(app.uipanel1, 'text');
            app.deltas.Tag = 'deltas';
            app.deltas.Tooltip = {'''Angulo delta'''; 'Angulo de friccion  en la pantalla del muro.'};
            app.text20 = uilabel(app.uipanel1);
            app.text20.Interpreter = 'tex';
            app.text20.Tag = 'text20';
            app.text20.Text = 'grados°';
            app.uipanel4 = uipanel(app.uipanel1);
            app.uipanel4.Tooltip = {'Activar si el suelo de relleno presenta inclinación.'};
            app.uipanel4.Title = 'Inclinación?';
            app.uipanel4.Tag = 'uipanel4';
            app.checkbox1 = uicheckbox(app.uipanel4);
            app.checkbox1.ValueChangedFcn = createCallbackFcn(app, @checkbox1_Callback, true);
            app.checkbox1.Tag = 'checkbox1';
            app.checkbox1.Text = 'SI';
            app.estratificacion = uibutton(app.uipanel1, 'push');
            app.estratificacion.ButtonPushedFcn = createCallbackFcn(app, @estratificacion_Callback, true);
            app.estratificacion.Tag = 'estratificacion';
            app.estratificacion.Tooltip = {'''SUELO'''; 'Aqui se ingresan los datos del terreno.'};
            app.estratificacion.Text = 'SUELO';
            app.text31 = uilabel(app.uipanel1);
            app.text31.Tag = 'text31';
            app.text31.Text = {'    P.e.'; 'Concreto'};
            app.pconcreto = uieditfield(app.uipanel1, 'text');
            app.pconcreto.Tag = 'pconcreto';
            app.pconcreto.Tooltip = {'''Peso específico del concreto'''};
            app.text32 = uilabel(app.uipanel1);
            app.text32.Interpreter = 'tex';
            app.text32.Tag = 'text32';
            app.text32.Text = 'kN/m^{3}';
            app.text34 = uilabel(app.uipanel1);
            app.text34.Interpreter = 'tex';
            app.text34.Tag = 'text34';
            app.text34.Text = 'g';
            app.text44 = uilabel(app.uipanel1);
            app.text44.Tag = 'text44';
            app.text44.Text = 'Base 3 (B3)';
            app.Base3 = uieditfield(app.uipanel1, 'text');
            app.Base3.Tag = 'Base3';
            app.text45 = uilabel(app.uipanel1);
            app.text45.Interpreter = 'tex';
            app.text45.Tag = 'text45';
            app.text45.Text = 'm';
            app.ht = uieditfield(app.uipanel1, 'text');
            app.ht.Tag = 'ht';
            app.text46 = uilabel(app.uipanel1);
            app.text46.Tag = 'text46';
            app.text46.Text = 'Altura Talón (H.T)';
            app.text47 = uilabel(app.uipanel1);
            app.text47.Interpreter = 'tex';
            app.text47.Tag = 'text47';
            app.text47.Text = 'm';
            app.tal = uieditfield(app.uipanel1, 'text');
            app.tal.Tag = 'tal';
            app.text48 = uilabel(app.uipanel1);
            app.text48.Tag = 'text48';
            app.text48.Text = 'Talón';
            app.text49 = uilabel(app.uipanel1);
            app.text49.Interpreter = 'tex';
            app.text49.Tag = 'text49';
            app.text49.Text = 'm';
            app.text116 = uilabel(app.uipanel1);
            app.text116.Tag = 'text116';
            app.text116.Text = 'Sobrecarga';
            app.carga = uieditfield(app.uipanel1, 'text');
            app.carga.Tag = 'carga';
            app.carga.Tooltip = {'''Sobre carga en el terreno'''};
            app.text24 = uilabel(app.uipanel1);
            app.text24.Tag = 'text24';
            app.text24.Text = {'Ángulo de'; 'fricción'; 'base-muro (Φb)'};
            app.phib = uieditfield(app.uipanel1, 'text');
            app.phib.Tag = 'phib';
            app.phib.Tooltip = {'''Ángulo Phi'''; 'Ángulo de fricción en la base del muro.'};
            app.text25 = uilabel(app.uipanel1);
            app.text25.Interpreter = 'tex';
            app.text25.Tag = 'text25';
            app.text25.Text = 'grados°';
            app.text22 = uilabel(app.uipanel1);
            app.text22.Tag = 'text22';
            app.text22.Text = {'Ángulo de'; 'inclinación (α)'};
            app.alpha = uieditfield(app.uipanel1, 'text');
            app.alpha.Tag = 'alpha';
            app.alpha.Tooltip = {'''Ángulo alpha'''; 'Inclinación del terreno.'};
            app.text23 = uilabel(app.uipanel1);
            app.text23.Interpreter = 'tex';
            app.text23.Tag = 'text23';
            app.text23.Text = 'grados°';
            app.sobrecarga = uilabel(app.uipanel1);
            app.sobrecarga.Interpreter = 'tex';
            app.sobrecarga.Tag = 'sobrecarga';
            app.sobrecarga.Text = 'kN/m';
            app.uipanel5 = uipanel(app.figure1);
            app.uipanel5.Title = 'Peso del muro para Des.restringido';
            app.text37_2 = uilabel(app.uipanel5);
            app.text37_2.Tag = 'text37';
            app.text37_2.Text = 'Para un F.s recomendado de 1.5';
            app.Ww2 = uilabel(app.uipanel5);
            app.Ww2.Tag = 'FSD';
            app.Ww2.Text = '';
            app.text37_3 = uilabel(app.uipanel5);
            app.text37_3.Tag = 'text37';
            app.text37_3.Text = 'Para el valor obtenido';
            app.Ww3 = uilabel(app.uipanel5);
            app.Ww3.Tag = 'FSD';
            app.Ww3.Text = '';
            app.textww2 = uilabel(app.uipanel5);
            app.textww2.Interpreter = 'tex';
            app.textww2.Tag = 'sobrecarga';
            app.textww2.Text = 'kN/m';
            app.textww3 = uilabel(app.uipanel5);
            app.textww3.Interpreter = 'tex';
            app.textww3.Tag = 'sobrecarga';
            app.textww3.Text = 'kN/m';
            app.pushbutton4 = uibutton(app.figure1, 'push');
            app.pushbutton4.ButtonPushedFcn = createCallbackFcn(app, @pushbutton4ButtonPushed2, true);
            app.pushbutton4.Tag = 'pushbutton4';
            app.pushbutton4.Icon = fullfile(pathToMLAPP, 'ima', 'reset.png');
            app.pushbutton4.Tooltip = {'''RESTABLECER VALORES A 0'''};
            app.pushbutton4.Text = '';
            app.salir = uibutton(app.figure1, 'push');
            app.salir.ButtonPushedFcn = createCallbackFcn(app, @salirButtonPushed, true);
            app.salir.Tag = 'salir';
            app.salir.Icon = fullfile(pathToMLAPP, 'ima', 'salir.png');
            app.salir.Tooltip = {'''BOTON CERRAR APLICACIÓN'''};
            app.salir.Text = '';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MGrape_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)MGrape_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end