classdef MGravedad_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                    matlab.ui.Figure
        MenMenu                    matlab.ui.container.Menu
        MenPrincipalMenu           matlab.ui.container.Menu
        AnlisisEstaticoMenu        matlab.ui.container.Menu
        AnlisispseudoestaticoMenu  matlab.ui.container.Menu
        ayuda                      matlab.ui.control.Button
        uipanel2                   matlab.ui.container.Panel
        text30                     matlab.ui.control.Label
        FSVA                       matlab.ui.control.Label
        FSVR                       matlab.ui.control.Label
        FSD                        matlab.ui.control.Label
        text6                      matlab.ui.control.Label
        text5                      matlab.ui.control.Label
        text4                      matlab.ui.control.Label
        Calcular                   matlab.ui.control.Button
        pushbutton4                matlab.ui.control.Button
        Salir                      matlab.ui.control.Button
        text3                      matlab.ui.control.Label
        uipanel1                   matlab.ui.container.Panel
        Carga                      matlab.ui.control.EditField
        text24_2                   matlab.ui.control.Label
        text23_2                   matlab.ui.control.Label
        text32                     matlab.ui.control.Label
        pconcreto                  matlab.ui.control.EditField
        text31                     matlab.ui.control.Label
        text25                     matlab.ui.control.Label
        phib                       matlab.ui.control.EditField
        text24                     matlab.ui.control.Label
        estratificacion            matlab.ui.control.Button
        uipanel4                   matlab.ui.container.Panel
        checkbox1                  matlab.ui.control.CheckBox
        text23                     matlab.ui.control.Label
        alpha                      matlab.ui.control.EditField
        text22                     matlab.ui.control.Label
        text20                     matlab.ui.control.Label
        deltas                     matlab.ui.control.EditField
        text19                     matlab.ui.control.Label
        text18                     matlab.ui.control.Label
        text17                     matlab.ui.control.Label
        Beta                       matlab.ui.control.EditField
        text16                     matlab.ui.control.Label
        Base2                      matlab.ui.control.EditField
        text15                     matlab.ui.control.Label
        text13                     matlab.ui.control.Label
        text12                     matlab.ui.control.Label
        text11                     matlab.ui.control.Label
        text10                     matlab.ui.control.Label
        text9                      matlab.ui.control.Label
        Co                         matlab.ui.control.EditField
        H                          matlab.ui.control.EditField
        uipanel3                   matlab.ui.container.Panel
        popupmenu1                 matlab.ui.control.DropDown
        uibuttongroup1             matlab.ui.container.ButtonGroup
        radiobutton1               matlab.ui.control.RadioButton
        radiobutton2               matlab.ui.control.RadioButton
        Base1                      matlab.ui.control.EditField
        basec                      matlab.ui.control.EditField
        text14                     matlab.ui.control.Label
        text8                      matlab.ui.control.Label
        axes1                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function MGravedad_OpeningFcn(app, varargin)
            % --- Executes just before MGravedad is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            app.Calcular.Enable="off";
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to MGravedad (see VARARGIN)
            
            % Choose default command line output for MGravedad
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            % UIWAIT makes MGravedad wait for user response (see UIRESUME)
            % uiwait(handles.figure1
            global R1
            R1=1;
                set(handles.text14,'Visible','off');
                set(handles.text15,'Visible','off');
                set(handles.Base1,'Visible','off');
                set(handles.text11,'Visible','off')
                set(handles.Base2,'Visible','off');
                set(handles.text16,'Visible','off');
            
                set(handles.basec,'Visible','off');
                set(handles.text8,'Visible','off');
                set(handles.text10,'Visible','off');
                set(handles.Co,'Visible','off');
                set(handles.text13,'Visible','off');
            
                set(handles.text17,'Visible','off');
                set(handles.Beta,'Visible','off');
                set(handles.text18,'Visible','off');
                set(handles.text19,'Visible','off');
                set(handles.deltas,'Visible','off');
                set(handles.text20,'Visible','off');
            
                set(handles.text9,'Visible','off');
                set(handles.H,'Visible','off');
                set(handles.text12,'Visible','off')
            
                set(handles.alpha,'Visible','off');
                set(handles.text23,'Visible','off');
                set(handles.text22,'Visible','off');
                set(handles.phib,'Visible','off');
                set(handles.text24,'Visible','off');
                set(handles.text25,'Visible','off');
        end

        % Button pushed function: Calcular
        function Calcular_Callback(app, event)
            % --- Executes on button press in Calcular.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global a
            % hObject    handle to Calcular (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global espesor phi PesoE Cohesion  n k espe pe ph R R3 TS R1 R2
            
            Carga=str2double(get(handles.Carga, 'string')); % Base del muro de contencion
            if Carga<0
                     errordlg('Valor de carga fuera de Rango,Valor menor de 0 ','ERROR')
                    return
            end
             if isnan(Carga)
                 errordlg('Valor de la carga no numerico','ERROR')
                 return
             end
            %SI hay inclinacion
            if R3==1
                alpha=str2double(get(handles.alpha, 'string'));
            
            else
                alpha=0;
            end


            %%%%%%%%%%%%%%%%%%%%%%%%%
            if a==2
                Base1=str2double(get(handles.Base1, 'string')); % Base del muro de contencion
                Base2=str2double(get(handles.Base2, 'string')); % Base del muro de contencion
                Ha=str2double(get(handles.H, 'string')); % altura total
                D=str2double(get(handles.deltas, 'string'));
                B=str2double(get(handles.Beta, 'string'));
                phib=str2double(get(handles.phib, 'string'));
                concreto=str2double(get(handles.pconcreto, 'string'));
                %%%%%%%%%%%%%%%%%%%%%%CHEQUEO

                if R1==1
                    if concreto<14
                        errordlg('Valor del peso específico del concreto fuera de Rango,Valor menor de 14 kN/m3, elegir un concreto de mayor resistencia','ERROR')
                        return
                    end
                    if isnan(concreto)
                        errordlg('Valor del peso específico del concreto no numerico','ERROR')
                        return
                    end


                end

                if R2==1
                    if concreto<90
                        errordlg('Valor del peso específico del concreto fuera de Rango,Valor menor de 90 lb/ft3, elegir un concreto de mayor resistencia','ERROR')
                        return
                    end
                    if isnan(concreto)
                        errordlg('Valor del peso específico del concreto no numerico','ERROR')
                        return
                    end


                end
                 %Altura
                 
                
                 if Ha<0
                     errordlg('Valor de altura fuera de Rango,Valor menor de 0 ','ERROR')
                    return
                 end
                 if isnan(Ha)
                     errordlg('Valor de la altura no numerico','ERROR')
                     return
                 end
                %Base1
                if Base1<0
                    errordlg('Valor de Base fuera de Rango,Valor menor de 0 ','ERROR')
                    return
                end
                if isnan(Base1)
                    errordlg('Valor de la base no numerico','ERROR')
                    return
                end
                 %Base2
                if Base2<0
                    errordlg('Valor de Base fuera de Rango,Valor menor de 0 ','ERROR')
                    return
                end
                if isnan(Base2)
                    errordlg('Valor de la base no numerico','ERROR')
                    return
                end
               %delta
                if D<0
                    errordlg('Valor de Delta fuera de Rango,Valor menor de 0','ERROR')
                    return
                end
                if isnan(D)
                    errordlg('Valor de delta no numerico','ERROR')
                    return
                end
            
                %beta
                if B<90 || B>90
                    warndlg('Recuerde que para este tipo de muro Beta es 90','Warning')
                    return
                end
                if isnan(B)
                    errordlg('Valor de beta no numerico','ERROR')
                    return
                end
            
                %alpha
                if alpha<0
                    errordlg('Valor de Alpha fuera de Rango,Valor menor de 0','ERROR')
                    return
                end
                if isnan(alpha)
                    errordlg('Valor de alpha no numerico','ERROR')
                    return
                end


                %PHI BASE MURO
                if phib<0
                    errordlg('Valor fuera de Rango, Valores de ΦBase entre 0.5Φ a 0.67Φ ','ERROR')
                    return
                end
                if isnan(phib)
                    errordlg('Valor de Phi de la base no numerico','ERROR')
                    return
                end


                [FSD,FSVR,FSVA]= Mtriangular(Base1,Base2,Ha,D,B,alpha,phib,concreto,espesor,phi,PesoE,Cohesion,n,k,espe,pe,ph,R,TS,R1,R2,Carga);
            
                set(handles.FSD,'String',round(FSD,2));
                set(handles.FSVR,'String',round(FSVR,2));
                set(handles.FSVA,'String',round(FSVA,2));
            end
            if a==3
                 Base=str2double(get(handles.basec, 'string')); % Base del muro de contencion
                 Ha=str2double(get(handles.H, 'string'));
                 Co=str2double(get(handles.Co, 'string'));
                 B=str2double(get(handles.Beta, 'string'));
                 assignin('base','BB',B);
                 D=str2double(get(handles.deltas, 'string'));
                 phib=str2double(get(handles.phib, 'string'));
                 concreto=str2double(get(handles.pconcreto, 'string'));
                 %%%%%%%%%%%%%%%%%%%%%%CHEQUEO
                 %Altura
                 if R1==1
                    if concreto<14
                        errordlg('Valor del peso específico del concreto fuera de Rango,Valor menor de 14 kN/m3, elegir un concreto de mayor resistencia','ERROR')
                        return
                    end
                    if isnan(concreto)
                        errordlg('Valor del peso específico del concreto no numerico','ERROR')
                        return
                    end


                end

                if R2==1
                    if concreto<90
                        errordlg('Valor del peso específico del concreto fuera de Rango,Valor menor de 90 lb/ft3, elegir un concreto de mayor resistencia','ERROR')
                        return
                    end
                    if isnan(concreto)
                        errordlg('Valor del peso específico del concreto no numerico','ERROR')
                        return
                    end


                end


                 if Ha<0
                     errordlg('Valor de altura fuera de Rango,Valor menor de 0 ','ERROR')
                    return
                 end
                 if isnan(Ha)
                     errordlg('Valor de la altura no numerico','ERROR')
                     return
                 end
                %Base
                if Base<0
                    errordlg('Valor de Base fuera de Rango,Valor menor de 0 ','ERROR')
                    return
                end
                if isnan(Base)
                    errordlg('Valor de la base no numerico','ERROR')
                    return
                end
                %Corona
                if Co<0
                    errordlg('Valor de Corona fuera de Rango,Valor menor de 0','ERROR')
                    return
                end
                if isnan(Co)
                    errordlg('Valor de la corona no numerico','ERROR')
                    return
                end
                %delta
                if D<0
                    errordlg('Valor de Delta fuera de Rango,Valor menor de 0','ERROR')
                    return
                end
                if isnan(D)
                    errordlg('Valor de delta no numerico','ERROR')
                    return
                end
            
                %beta
                if B<0
                    errordlg('Valor de Beta fuera de Rango,Valor menor de 0','ERROR')
                    return
                end
                if isnan(B)
                    errordlg('Valor de beta no numerico','ERROR')
                    return
                end
                %alpha
                if alpha<0
                    errordlg('Valor de Alpha fuera de Rango,Valor menor de 0','ERROR')
                    return
                end
                if isnan(alpha)
                    errordlg('Valor de alpha no numerico','ERROR')
                    return
                end


                %PHI BASE MURO
                if phib<0
                    errordlg('Valor fuera de Rango, Valores de ΦBase entre 0.5Φ a 0.67Φ ','ERROR')
                    return
                end
                if isnan(phib)
                    errordlg('Valor de Phi de la base no numerico','ERROR')
                    return
                end


                 [FSD,FSVR,FSVA]= Mtrapezoide(Base,Co,Ha,D,B,alpha,phib,concreto,espesor,phi,PesoE,Cohesion,n,k,espe,pe,ph,R,TS,R1,R2,Carga);
                 set(handles.FSD,'String',round(FSD,2));
                 set(handles.FSVR,'String',round(FSVR,2));
                 set(handles.FSVA,'String',round(FSVA,2));
            
            
            end
        end

        % Button pushed function: Salir
        function Salir_Callback(app, event)
            % --- Executes on button press in Salir.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Salir (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.delete
        end

        % Button pushed function: ayuda
        function ayuda_Callback(app, event)
            % --- Executes on button press in ayuda.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ayuda (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            winopen('Fundamentos.pdf')
        end

        % Value changed function: checkbox1
        function checkbox1_Callback(app, event)
            % --- Executes on button press in checkbox1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox1
            global R3
            R3=get(hObject,'Value');
            if R3==1
                set(handles.alpha,'Visible','on');
                set(handles.text22,'Visible','on');
                set(handles.text23,'Visible','on');
            
            else
                set(handles.alpha,'Visible','off');
                set(handles.text22,'Visible','off');
                set(handles.text23,'Visible','off');
            
            end
        end

        % Button pushed function: estratificacion
        function estratificacion_Callback(app, event)
            % --- Executes on button press in estratificacion.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to estratificacion (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global R2 R1
            
            if R1==1
                R2=0;
                R11=1;
                R22=0;
            end
            if R2==1
                R1=0;
                R22=1;
                R11=0;
            end
            waitfor(helpdlg('Tenga en cuenta que para los muros de gravedad se utiliza la teoria de Coulomb para un solo estrato de suelo','Observación'));
            Estratigravedad_App(R11,R22)
            app.Calcular.Enable="on";
        end

        % Value changed function: popupmenu1
        function popupmenu1_Callback(app, event)
            % --- Executes on selection change in popupmenu1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu1 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu1
            global a
            a=get(hObject,'Value');
            if a==2% Muro Triangular
                axes(handles.axes1);
                bkgrnd=imread('Triangular.png');
                imshow(bkgrnd,'Parent',app.axes1);


                set(handles.text14,'Visible','on');
                set(handles.text15,'Visible','on');
                set(handles.Base1,'Visible','on');
                set(handles.Base2,'Visible','on');
                set(handles.text16,'Visible','on');
                set(handles.text11,'Visible','on');
                set(handles.text9,'Visible','on');
                set(handles.H,'Visible','on');
                set(handles.text12,'Visible','on');
                set(handles.text17,'Visible','on');
                set(handles.Beta,'Visible','on');
                set(handles.text18,'Visible','on');
                set(handles.phib,'Visible','on');
                set(handles.text24,'Visible','on');
                set(handles.text25,'Visible','on');
            
            
                set(handles.text19,'Visible','on');
                set(handles.deltas,'Visible','on');
                set(handles.text20,'Visible','on');
            
                set(handles.basec,'Visible','off');
                set(handles.text8,'Visible','off');
                set(handles.text10,'Visible','off');
                set(handles.Co,'Visible','off');
                set(handles.text13,'Visible','off');


            end
            
            if a==3% Muro Gravedad treapezoidal
                axes(handles.axes1);
                bkgrnd=imread('cou.png');
                imshow(bkgrnd);
            
            
                set(handles.basec,'Visible','on');
                set(handles.text8,'Visible','on');
                set(handles.text9,'Visible','on');
                set(handles.H,'Visible','on');
                set(handles.text12,'Visible','on');
                set(handles.text10,'Visible','on');
                set(handles.text11,'Visible','on');
                set(handles.Co,'Visible','on');
                set(handles.text13,'Visible','on');
                set(handles.text19,'Visible','on');
                set(handles.deltas,'Visible','on');
                set(handles.text20,'Visible','on');
                set(handles.text17,'Visible','on');
                set(handles.Beta,'Visible','on');
                set(handles.text18,'Visible','on');
                set(handles.phib,'Visible','on');
                set(handles.text24,'Visible','on');
                set(handles.text25,'Visible','on');
            
                set(handles.text14,'Visible','off');
                set(handles.text15,'Visible','off');
                set(handles.Base1,'Visible','off');
                set(handles.Base2,'Visible','off');
                set(handles.text16,'Visible','off');


            end
        end

        % Selection changed function: uibuttongroup1
        function uibuttongroup1SelectionChanged(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            selectedButton = app.uibuttongroup1.SelectedObject;
            global R1 R2 R3 a
            R1=app.radiobutton1.Value;
            R2=app.radiobutton2.Value;
            So=str2double(get(handles.Carga, 'string'));
            if R1==1
    
    
                set(handles.text11,'string','m');
                set(handles.text12,'string','m');
                set(handles.text13,'string','m');
                set(handles.text16,'string','m');
                app.text24_2.Text="kN/m";
                
                set(handles.Carga,'String',round((So/0.068521),2));
              
                set(handles.text32,'string','kN/m^{3}');
                concreto=str2double(get(handles.pconcreto,'string'));
                set(handles.pconcreto,'String',round(concreto/6.365880 ,2));
                if R3==0
                    
                   set(handles.Carga,'String',round(So/0.068521 ,2));
                end
                
                if a==2
                    Base1=str2double(get(handles.Base1, 'string')); % Base del muro de contencion
                    Base2=str2double(get(handles.Base2, 'string')); % Base del muro de contencion
                    Ha=str2double(get(handles.H, 'string')); % altura total
                    D=str2double(get(handles.deltas, 'string'));
                    B=str2double(get(handles.Beta, 'string')); 
                    phib=str2double(get(handles.phib, 'string'));
                 
                
                    set(handles.Base1,'String',round(Base1*0.3048 ,2));
                    set(handles.Base2,'String',round(Base2*0.3048 ,2));
                    set(handles.H,'String',round(Ha*0.3048 ,2));
                    
                end
            
                if a==3
                    Base=str2double(get(handles.basec, 'string')); % Base del muro de contencion
                    Ha=str2double(get(handles.H, 'string'));
                    Co=str2double(get(handles.Co, 'string'));
                    B=str2double(get(handles.Beta, 'string'));
                    D=str2double(get(handles.deltas, 'string'));
                    phib=str2double(get(handles.phib, 'string'));
                    
                    
                    set(handles.basec,'String',round(Base*0.3048 ,2));    
                    set(handles.H,'String',round(Ha*0.3048 ,2));
                    set(handles.Co,'String',round(Co*0.3048 ,2));
                end
            
            end
            %%%%%%%Para R2
            if R2==1
                     
                set(handles.text16,'string','ft');
                set(handles.text13,'string','ft');
                set(handles.text11,'string','ft');
                set(handles.text12,'string','ft');
                set(handles.text12,'string','ft');
                app.text24_2.Text="kips/ft";
                
                set(handles.Carga,'String',round(So*0.068521,2));
                
                set(handles.text32,'string','lb/ft^{3}');
                concreto=str2double(get(handles.pconcreto, 'string'));
                set(handles.pconcreto,'String',round(concreto*6.365880 ,2));
                if R3==0
                   
                   set(handles.Carga,'String',round(So*0.068521,2));
                end
                if a==2
                    Base1=str2double(get(handles.Base1, 'string')); % Base del muro de contencion
                    Base2=str2double(get(handles.Base2, 'string')); % Base del muro de contencion
                    Ha=str2double(get(handles.H, 'string')); % altura total
                    D=str2double(get(handles.deltas, 'string'));
                    B=str2double(get(handles.Beta, 'string')); 
                    phib=str2double(get(handles.phib, 'string'));


                    set(handles.Base1,'String',round(Base1/0.3048 ,2));
                    set(handles.Base2,'String',round(Base2/0.3048 ,2));
                    set(handles.H,'String',round(Ha/0.3048 ,2));
                
                end
                
                if a==3  
                
                    Base=str2double(get(handles.basec, 'string')); % Base del muro de contencion
                    Ha=str2double(get(handles.H, 'string'));
                    Co=str2double(get(handles.Co, 'string'));
                    B=str2double(get(handles.Beta, 'string'));
                    D=str2double(get(handles.deltas, 'string'));
                    phib=str2double(get(handles.phib, 'string'));    
                    
                    
                    set(handles.basec,'String',round(Base/0.3048 ,2));
                    set(handles.H,'String',round(Ha/0.3048 ,2));
                    set(handles.Co,'String',round(Co/0.3048 ,2));
            
                end
            end

        end

        % Button pushed function: pushbutton4
        function pushbutton4ButtonPushed(app, event)
             % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            set(handles.pconcreto,'string','');
            set(handles.Base1,'string','');
            set(handles.Base2,'string','');
            set(handles.H,'string','');
            set(handles.Co,'string','');
            set(handles.Beta,'string','');
            set(handles.deltas,'string','');
            set(handles.alpha,'string','');
            set(handles.basec,'string','');
            set(handles.phib,'string','');
        end

        % Menu selected function: MenPrincipalMenu
        function MenPrincipalMenuSelected(app, event)
            app.delete
            press11
        end

        % Menu selected function: AnlisisEstaticoMenu
        function AnlisisEstaticoMenuSelected(app, event)
            app.delete
            press2
        end

        % Menu selected function: AnlisispseudoestaticoMenu
        function AnlisispseudoestaticoMenuSelected(app, event)
             app.delete
             MGrape_App
        end

        % Callback function
        function MuroenvoladizoMenuSelected(app, event)
            app.delete
            MuroT_App
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Muro de Gravedad';
            app.figure1.Icon = fullfile(pathToMLAPP, 'ima', 'm.png');
            app.figure1.Tag = 'figure1';
            app.MenMenu = uimenu(app.figure1);
            app.MenMenu.Text = 'Menú';
            app.MenPrincipalMenu = uimenu(app.MenMenu);
            app.MenPrincipalMenu.MenuSelectedFcn = createCallbackFcn(app, @MenPrincipalMenuSelected, true);
            app.MenPrincipalMenu.Text = 'Menú Principal';
            app.AnlisisEstaticoMenu = uimenu(app.MenMenu);
            app.AnlisisEstaticoMenu.MenuSelectedFcn = createCallbackFcn(app, @AnlisisEstaticoMenuSelected, true);
            app.AnlisisEstaticoMenu.Text = 'Análisis Estatico';
            app.AnlisispseudoestaticoMenu = uimenu(app.MenMenu);
            app.AnlisispseudoestaticoMenu.MenuSelectedFcn = createCallbackFcn(app, @AnlisispseudoestaticoMenuSelected, true);
            app.AnlisispseudoestaticoMenu.Text = 'Análisis pseudo-estatico';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Caracteristicas del muro';
            app.uipanel1.Tag = 'uipanel1';
            app.text8 = uilabel(app.uipanel1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Base Muro(B)';
            app.text14 = uilabel(app.uipanel1);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Base 1(B1)';
            app.basec = uieditfield(app.uipanel1, 'text');
            app.basec.Tag = 'basec';
            app.Base1 = uieditfield(app.uipanel1, 'text');
            app.Base1.Tag = 'Base1';
            app.Base1.Tooltip = {'''Base del muro de gravedad'''};
            app.uibuttongroup1 = uibuttongroup(app.uipanel1);
            app.uibuttongroup1.SelectionChangedFcn = createCallbackFcn(app, @uibuttongroup1SelectionChanged, true);
            app.uibuttongroup1.Tooltip = {'''Sistema de Unidades'''};
            app.uibuttongroup1.Title = 'SISTEMA DE UNIDADES';
            app.uibuttongroup1.Tag = 'uibuttongroup1';
            app.radiobutton2 = uiradiobutton(app.uibuttongroup1);
            app.radiobutton2.Tag = 'radiobutton2';
            app.radiobutton2.Text = 'S.Inglés';
            app.radiobutton1 = uiradiobutton(app.uibuttongroup1);
            app.radiobutton1.Tag = 'radiobutton1';
            app.radiobutton1.Text = 'S.Internacional';
            app.radiobutton1.Value = true;
            app.uipanel3 = uipanel(app.uipanel1);
            app.uipanel3.Tooltip = {'''Tipo de muro'''; '1. Con una sola cara.'; '2. Con 2 caras.'};
            app.uipanel3.Title = 'Tipo de Muro';
            app.uipanel3.Tag = 'uipanel3';
            app.popupmenu1 = uidropdown(app.uipanel3);
            app.popupmenu1.Items = {'', 'Trapecio Rectangular', 'Trapecio Isósceles'};
            app.popupmenu1.ValueChangedFcn = createCallbackFcn(app, @popupmenu1_Callback, true);
            app.popupmenu1.Tag = 'popupmenu1';
            app.popupmenu1.Value = '';
            app.H = uieditfield(app.uipanel1, 'text');
            app.H.Tag = 'H';
            app.H.Tooltip = {'''Altura total del muro de gravedad'''};
            app.Co = uieditfield(app.uipanel1, 'text');
            app.Co.Tag = 'Co';
            app.Co.Tooltip = {'''Corona'''; 'Parte superior del muro.'};
            app.text9 = uilabel(app.uipanel1);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Altura muro(H)';
            app.text10 = uilabel(app.uipanel1);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Corona';
            app.text11 = uilabel(app.uipanel1);
            app.text11.Interpreter = 'tex';
            app.text11.Tag = 'text11';
            app.text11.Text = 'm';
            app.text12 = uilabel(app.uipanel1);
            app.text12.Interpreter = 'tex';
            app.text12.Tag = 'text12';
            app.text12.Text = 'm';
            app.text13 = uilabel(app.uipanel1);
            app.text13.Interpreter = 'tex';
            app.text13.Tag = 'text13';
            app.text13.Text = 'm';
            app.text15 = uilabel(app.uipanel1);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Base 2(B2)';
            app.Base2 = uieditfield(app.uipanel1, 'text');
            app.Base2.Tag = 'Base2';
            app.text16 = uilabel(app.uipanel1);
            app.text16.Interpreter = 'tex';
            app.text16.Tag = 'text16';
            app.text16.Text = 'm';
            app.Beta = uieditfield(app.uipanel1, 'text');
            app.Beta.Tag = 'Beta';
            app.Beta.Tooltip = {'''Angulo Beta'''; 'Inclinación del muro de gravedad.'; 'Para el muro ''Trapecio Rectangular'' Beta es 90°'};
            app.text17 = uilabel(app.uipanel1);
            app.text17.Tag = 'text17';
            app.text17.Text = {'Ángulo de'; 'Inclinación'; 'del muro (β) '};
            app.text18 = uilabel(app.uipanel1);
            app.text18.Interpreter = 'tex';
            app.text18.Tag = 'text18';
            app.text18.Text = 'grados°';
            app.text19 = uilabel(app.uipanel1);
            app.text19.Tag = 'text19';
            app.text19.Text = {'Ángulo de'; 'fricción'; 'suelo-muro (δ)'};
            app.deltas = uieditfield(app.uipanel1, 'text');
            app.deltas.Tag = 'deltas';
            app.deltas.Tooltip = {'''Ángulo delta'''; 'Ángulo de fricción  en la pantalla del muro.'};
            app.text20 = uilabel(app.uipanel1);
            app.text20.Interpreter = 'tex';
            app.text20.Tag = 'text20';
            app.text20.Text = 'grados°';
            app.text22 = uilabel(app.uipanel1);
            app.text22.Tag = 'text22';
            app.text22.Text = {'Ángulo de'; 'inclinación (α)'};
            app.alpha = uieditfield(app.uipanel1, 'text');
            app.alpha.Tag = 'alpha';
            app.alpha.Tooltip = {'''Ángulo alpha'''; 'Inclinación del terreno.'};
            app.text23 = uilabel(app.uipanel1);
            app.text23.Interpreter = 'tex';
            app.text23.Tag = 'text23';
            app.text23.Text = 'grados°';
            app.uipanel4 = uipanel(app.uipanel1);
            app.uipanel4.Tooltip = {'Activar si el suelo de relleno presenta inclinación.'};
            app.uipanel4.Title = 'Inclinación?';
            app.uipanel4.Tag = 'uipanel4';
            app.checkbox1 = uicheckbox(app.uipanel4);
            app.checkbox1.ValueChangedFcn = createCallbackFcn(app, @checkbox1_Callback, true);
            app.checkbox1.Tag = 'checkbox1';
            app.checkbox1.Text = 'SI';
            app.estratificacion = uibutton(app.uipanel1, 'push');
            app.estratificacion.ButtonPushedFcn = createCallbackFcn(app, @estratificacion_Callback, true);
            app.estratificacion.Tag = 'estratificacion';
            app.estratificacion.Tooltip = {'''SUELO'''; 'Aqui se ingresan los datos del terreno.'};
            app.estratificacion.Text = 'SUELO';
            app.text24 = uilabel(app.uipanel1);
            app.text24.Tag = 'text24';
            app.text24.Text = {'Ángulo de'; 'fricción'; 'base-muro (Φb)'};
            app.phib = uieditfield(app.uipanel1, 'text');
            app.phib.Tag = 'phib';
            app.phib.Tooltip = {'''Ángulo Phi'''; 'Ángulo de fricción en la base del muro.'};
            app.text25 = uilabel(app.uipanel1);
            app.text25.Interpreter = 'tex';
            app.text25.Tag = 'text25';
            app.text25.Text = 'grados°';
            app.text31 = uilabel(app.uipanel1);
            app.text31.Tag = 'text31';
            app.text31.Text = 'P.e.Concreto';
            app.pconcreto = uieditfield(app.uipanel1, 'text');
            app.pconcreto.Tag = 'pconcreto';
            app.pconcreto.Tooltip = {'''Peso específico del concreto'''};
            app.text32 = uilabel(app.uipanel1);
            app.text32.Interpreter = 'tex';
            app.text32.Tag = 'text32';
            app.text32.Text = 'kN/m^{3}';
            app.text23_2 = uilabel(app.uipanel1);
            app.text23_2.Tag = 'text23';
            app.text23_2.Text = 'Sobre-Carga';
            app.text24_2 = uilabel(app.uipanel1);
            app.text24_2.Interpreter = 'tex';
            app.text24_2.Tag = 'text24';
            app.text24_2.Text = 'kN/m';
            app.Carga = uieditfield(app.uipanel1, 'text');
            app.Carga.Tag = 'Carga';
            app.Carga.Tooltip = {''' Sobre carga en el terreno'''};
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Muro de Gravedad';
            app.Salir = uibutton(app.figure1, 'push');
            app.Salir.ButtonPushedFcn = createCallbackFcn(app, @Salir_Callback, true);
            app.Salir.Tag = 'Salir';
            app.Salir.Icon = fullfile(pathToMLAPP, 'ima', 'salir.png');
            app.Salir.Tooltip = {'"SALIR"'};
            app.Salir.Text = '';
            app.pushbutton4 = uibutton(app.figure1, 'push');
            app.pushbutton4.ButtonPushedFcn = createCallbackFcn(app, @pushbutton4ButtonPushed, true);
            app.pushbutton4.Tag = 'pushbutton4';
            app.pushbutton4.Icon = fullfile(pathToMLAPP, 'ima', 'reset.png');
            app.pushbutton4.Tooltip = {'''Restablecer'''; 'Borrar valores en las casillas.'};
            app.pushbutton4.Text = '';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Analisis de Estabilidad';
            app.uipanel2.Tag = 'uipanel2';
            app.Calcular = uibutton(app.uipanel2, 'push');
            app.Calcular.ButtonPushedFcn = createCallbackFcn(app, @Calcular_Callback, true);
            app.Calcular.Tag = 'Calcular';
            app.Calcular.Tooltip = {'''Boton Calcular'''; 'Calcular los Factores de Seguridad '};
            app.Calcular.Text = 'Calcular';
            app.text4 = uilabel(app.uipanel2);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Revisión por Volcamiento (+Resistente)';
            app.text5 = uilabel(app.uipanel2);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Revisión por Deslizamiento';
            app.text6 = uilabel(app.uipanel2);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Factores de Seguridad';
            app.FSD = uilabel(app.uipanel2);
            app.FSD.Tag = 'FSD';
            app.FSD.Text = '';
            app.FSVR = uilabel(app.uipanel2);
            app.FSVR.Tag = 'FSVR';
            app.FSVR.Text = '';
            app.FSVA = uilabel(app.uipanel2);
            app.FSVA.Tag = 'FSVA';
            app.FSVA.Text = '';
            app.text30 = uilabel(app.uipanel2);
            app.text30.Tag = 'text30';
            app.text30.Text = '       Revisión por Volcamiento                (-Actuante)';
            app.ayuda = uibutton(app.figure1, 'push');
            app.ayuda.ButtonPushedFcn = createCallbackFcn(app, @ayuda_Callback, true);
            app.ayuda.Tag = 'ayuda';
            app.ayuda.Icon = fullfile(pathToMLAPP, 'ima', 'ayuda.png');
            app.ayuda.Tooltip = {'''AYUDA'''; 'Algunos valores que se pueden usar.'};
            app.ayuda.Text = '';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MGravedad_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)MGravedad_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end