classdef MuroT_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                    matlab.ui.Figure
        MenMenu                    matlab.ui.container.Menu
        MenPrincipalMenu           matlab.ui.container.Menu
        AnlisisEstaticoMenu        matlab.ui.container.Menu
        AnlisispseudoestaticoMenu  matlab.ui.container.Menu
        ayuda                      matlab.ui.control.Button
        restablecer                matlab.ui.control.Button
        salir                      matlab.ui.control.Button
        uipanel2                   matlab.ui.container.Panel
        FSV                        matlab.ui.control.Label
        FSD                        matlab.ui.control.Label
        text15                     matlab.ui.control.Label
        text14                     matlab.ui.control.Label
        text13                     matlab.ui.control.Label
        Calcular                   matlab.ui.control.Button
        uipanel1                   matlab.ui.container.Panel
        text44                     matlab.ui.control.Label
        pconcreto                  matlab.ui.control.EditField
        text43                     matlab.ui.control.Label
        text40                     matlab.ui.control.Label
        text39                     matlab.ui.control.Label
        edit10                     matlab.ui.control.EditField
        checkbox4                  matlab.ui.control.CheckBox
        uibuttongroup3             matlab.ui.container.ButtonGroup
        popupmenu2                 matlab.ui.control.DropDown
        uibuttongroup2             matlab.ui.container.ButtonGroup
        checkbox1                  matlab.ui.control.CheckBox
        uibuttongroup1             matlab.ui.container.ButtonGroup
        radiobutton1               matlab.ui.control.RadioButton
        radiobutton2               matlab.ui.control.RadioButton
        Estratigrafia              matlab.ui.control.Button
        Carga                      matlab.ui.control.EditField
        text24                     matlab.ui.control.Label
        text23                     matlab.ui.control.Label
        text17                     matlab.ui.control.Label
        delta                      matlab.ui.control.EditField
        text16                     matlab.ui.control.Label
        text12                     matlab.ui.control.Label
        text11                     matlab.ui.control.Label
        text10                     matlab.ui.control.Label
        text9                      matlab.ui.control.Label
        text8                      matlab.ui.control.Label
        text7                      matlab.ui.control.Label
        text6                      matlab.ui.control.Label
        text5                      matlab.ui.control.Label
        text4                      matlab.ui.control.Label
        text3                      matlab.ui.control.Label
        ancpuntera                 matlab.ui.control.EditField
        alttalon                   matlab.ui.control.EditField
        corona                     matlab.ui.control.EditField
        altura                     matlab.ui.control.EditField
        base                       matlab.ui.control.EditField
        text2                      matlab.ui.control.Label
        axes3                      matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function Cargaf_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to Cargaf (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function ima1_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to ima1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: place code in OpeningFcn to populate ima1
            background = imread('Mt.jpg');
            axis off;
            imshow(background);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function MuroT_OpeningFcn(app, varargin)
            % --- Executes just before MuroT is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            app.Calcular.Enable="off";
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to MuroT (see VARARGIN)
            
            
            global espesor phi PesoE Cohesion NF n alpha R1 Cant
            axes(handles.axes3); %Definimos axes2 como el objeto al que le meteremos una imagen secundaria.
            bkgrnd=imread('principal.jpg'); %Leemos la imagen.
            imshow(bkgrnd); %Mostramos la imagen.
            Cant=0;
             R1=1;
            set(handles.edit10,'Visible','off');
            set(handles.text39,'Visible','off');
            set(handles.text40,'Visible','off');
            
            % axes(handles.axes4); %Definimos axes2 como el objeto al que le meteremos una imagen secundaria.
            % bkgrnd=imread('qantes.jpg'); %Leemos la imagen.
            % imshow(bkgrnd); %Mostramos la imagen.
            
            % axes(handles.axes5); %Definimos axes2 como el objeto al que le meteremos una imagen secundaria.
            % bkgrnd=imread('qdespues.jpg'); %Leemos la imagen.
            % imshow(bkgrnd); %Mostramos la imagen.
            
            % axes(handles.axes6); %Definimos axes2 como el objeto al que le meteremos una imagen secundaria.
            % bkgrnd=imread('voladizocargaa.jpg'); %Leemos la imagen.
            % imshow(bkgrnd); %Mostramos la imagen.


            % Choose default command line output for MuroT
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: Calcular
        function Calcular_Callback(app, event)
            % --- Executes on button press in Calcular.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
              if ismcc || isdeployed
                    % Make sure DOM is compilable
                    makeDOMCompilable();
              end
            import mlreportgen.dom.*
            import mlreportgen.report.*
            % hObject    handle to Calcular (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global espesor phi PesoE Cohesion NF n alpha FSD1 FSV1 FSD2 FSV2 R3 FSD3 FSV3 k espe pe ph R Cant R1 R2 aa
            Base=str2double(get(handles.base, 'string')); % Base del muro de contencion
            Ha=str2double(get(handles.altura, 'string')); % altura total
            AlTalo=str2double(get(handles.alttalon, 'string'));  %altura del talon
            AncT=str2double(get(handles.ancpuntera, 'string')); %ancho del talon
            Co=str2double(get(handles.corona, 'string')); %ancho de la coron
            concreto=str2double(get(handles.pconcreto, 'string'));
            
            
            %%%%CHEQUEO
                if R1==1
                    
                    if concreto<14
                        errordlg('Valor del peso específico del concreto fuera de Rango,Valor menor de 14 kN/m3, elegir un concreto de mayor resistencia','ERROR')
                        return
                    end
                    if isnan(concreto)
                        errordlg('Valor del peso específico del concreto no numerico','ERROR')
                        return
                    end


                end

                if R2==1
                    if concreto<90
                        errordlg('Valor del peso específico del concreto fuera de Rango,Valor menor de 90 lb/ft3, elegir un concreto de mayor resistencia','ERROR')
                        return
                    end
                    if isnan(concreto)
                        errordlg('Valor del peso específico del concreto no numerico','ERROR')
                        return
                    end


                end


            %Altura
            if Ha<0
                errordlg('Valor de altura fuera de Rango,Valor menor de 0 ','ERROR')
                return
            end
            if isnan(Ha)
                errordlg('Valor de la altura no numerico','ERROR')
                return
            end
            
            %AL TALON
            if AlTalo<0
                errordlg('Valor de altura talon fuera de Rango,Valor menor de 0 ','ERROR')
                return
            end
            if isnan(AlTalo)
                errordlg('Valor de altura de talon no numerico','ERROR')
                return
            end
            
            %ancho talon
            if AncT<0
                errordlg('Valor de ancho de puntera fuera de Rango,Valor menor de 0 ','ERROR')
                return
            end
            if isnan(AncT)
                errordlg('Valor de ancho de punteraa no numerico','ERROR')
                return
            end
            
            %Base
            if Base<0
                errordlg('Valor de Base fuera de Rango,Valor menor de 0 ','ERROR')
                return
            end
            if isnan(Base)
                errordlg('Valor de la base no numerico','ERROR')
                return
            end
            %Corona
            if Co<0
                errordlg('Valor de Corona fuera de Rango,Valor menor de 0','ERROR')
                return
            end
            if isnan(Co)
                errordlg('Valor de la corona no numerico','ERROR')
                return
            end
            
            
            %alpha
            if alpha<0
                errordlg('Valor de Alpha fuera de Rango,Valor menor de 0','ERROR')
                return
            end
            if isnan(alpha)
                errordlg('Valor de alpha no numerico','ERROR')
                return
            end


            %%%%%
            
            % result= resolver (Base, Ha, Alto)
            if R3==1
                So=0;
            else
                So=str2double(get(handles.Carga, 'string'));
            end
            
            %Muro Canteliver
            if Cant==1
                BaseP=str2double(get(handles.edit10, 'string'));
                assignin('base','Cant',Cant);
            else
                BaseP=Base;
            end
            
            %datos suelo
            d=str2double(get(handles.delta, 'string'));
            %PHI BASE MURO
            if d<0
                errordlg('Valor fuera de Rango, Valores de delta menor que 0 ','ERROR')
                return
            end
            if isnan(d)
                errordlg('Valor de delta no numerico','ERROR')
                return
            end
            
            
            %POSTERIOR
            assignin('base','R',R);
            if R==1
                %hallar kp
                num=1+sind(ph);
                den=1-sind(ph);
                kp=num/den;
                assignin('base','kp',kp);
                %Esfuerzo vertical
                ssv=espe*pe;
                assignin('base','espe',espe);
                assignin('base','pe',pe);
                %Esfuerzo horizontal
                ssh=ssv*kp;
                assignin('base','ssh',ssh);
                %Fuerza
                Fp=ssh*espe/2;
                %Brazo
                brap=espe/3;
                assignin('base','Fp',Fp);
                assignin('base','brap',brap);
                %Momento
                Mp=Fp*brap;
                assignin('base','Mp',Mp);
            
            end
            if R==0
                Fp=0;
                Mp=0;
            end
            
            % Si alpha>0 terreno inclinado de lo contrario sera plano
            nf=NF;
            h=espesor;
            g=PesoE;
            c=Cohesion;
            c1=c(1,1);
            phii=phi;
            a=alpha;
            assignin('base','alpha',a);
            assignin('base','h',h);
            hcopiaa=h;
            
            assignin('base','k',k);assignin('base','c',c);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%PLANO
            if a==0
                [FSD,FSV,FSD1,FSV1,FSD2,FSV2,FSD3,FSV3]= SINinclinacion(Base,Ha,AlTalo,AncT,Co,concreto,So,Cant,d,Fp,Mp,hcopiaa,k,phii,phi,c,nf,n,R,h,g,R1,R2,BaseP,aa)
                 set(handles.FSD,'String',round(FSD,2));
                % set(handles.FSDc,'String',FSDc);
                 set(handles.FSV,'String',round(FSV,2));
                 if aa==2
                     set(handles.FSD,'String',round(FSD1,2));
                    % set(handles.FSDc,'String',FSDc);
                     set(handles.FSV,'String',round(FSV1,2));
                 end
                 if aa==3
                      set(handles.FSD,'String',round(FSD2,2));
                     % set(handles.FSDc,'String',FSDc);
                      set(handles.FSV,'String',round(FSV2,2));
                 end
                 if aa==4
                      set(handles.FSD,'String',round(FSD3,2));
                     % set(handles.FSDc,'String',FSDc);
                      set(handles.FSV,'String',round(FSV3,2));
                 end
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%INCLINADO
            if a>0
                y=AncT*tand(a);
                hcopia=h; %se necesitará después
                Altura=sum(h);
                %condicional de cambio de estrato ampliar matriz de alturas
                if nf > 0
                    h(n+1)=h(n);
            
                end
            
            
                %hallar ka o Ko rankie inlcinado
                if k==2
                    ka=zeros(n,1);
                    for j=1:n
                        ka=(cosd(a)*(cosd(a)-sqrt((cosd(a)^2)-cosd(phi(j)).^2)))./(cosd(a)+sqrt((cosd(a)^2)-cosd(phi).^2));
            
                    end
                    assignin('base','Ka',ka);
                end
                if k==3
                    ka=zeros(n,1);
                    f=errordlg('Recuerde que en Rankine Inclinado no se tienen encuenta el SUELO en REPOSO, Se continuara con Ka','ERROR');
                end
                

                if k==4
                    ka=zeros(n,1);
                    for j=1:n
                        ka=(cosd(a)*(cosd(a)+sqrt((cosd(a)^2)-cosd(phi(j)).^2)))./(cosd(a)-sqrt((cosd(a)^2)-cosd(phi).^2));
            
                    end
                    assignin('base','Ka',ka);

                end
                ka=round(ka,3);
            
            
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Actuante
                %esfuerzo vertical
                %Condicional si esta humedo o no
                if nf>0
                    %antes del nivel freático
                    if nf~=1
                        for kk=1:nf-1
                            sv(kk)=h(kk)*g(kk);
                        end
                    end
                    %después del nivel freático o si empieza en uno
                    for kk2=nf:n
                        if R1==1
                        sv(kk2)=h(kk2)*(g(kk2)-9.81);
                        end
                         if R2==1
                        sv(kk2)=h(kk2)*(g(kk2)-62.4);
                        end
                    end
                end
                if nf==0
                    %ESTA SECO
                    for kk=1:n
                        sv(kk)=h(kk)*g(kk);
                    end
                end
                assignin('base','Sv',sv);
            
            
                %esfuerzo total
                St=0;
            
                for kk3=2:n+1
                    St(kk3)=St(kk3-1)+sv(kk3-1);
                end
                assignin('base','St',St);
            
                 %%%%%%%%%%%esfuerzo horizontal
                if phi(1)~=phi(2) || n==1
                    for ii=1:n
                        sa(ii)=St(ii)*ka(ii);
                        sb(ii)=St(ii+1)*ka(ii);
                        assignin('base','Sb',sb);
                        assignin('base','Sa',sa);
                    end
                    %reorganiza
                    w=1;
                    for i=1:n
                        SS(w)=sa(i);
                        w=w+1;
                        SS(w)=sb(i);
                        w=w+1;
                        assignin('base','SS',SS);
                    end
            
                    %Fuerzas actuantes
            
            
                    if n==1
            
                        F(1)=h(1)*SS(w+1)/2;
                    end
            
                    %%%oswa
                    if n>1
                        %Reorganizar tamaño de h
                        sizeh=size(hcopia);
                        assignin('base','hcopia',hcopia);
            
                        sizeh=sizeh(1);
                        assignin('base','sizeh',sizeh);
            
                        %CAMBIE PARA 2 ESTRATOS PARA QUE FUNCIONARA
                        if n==2
                            h0=zeros(1,sizeh+1);
                        end
                        if n>2
                             h0=zeros(1,sizeh+2);
                        end
            
            
                        sizeh0=size(h0);
                        sizeh0=sizeh0(2);
                        h0(1)=h(1);
                        pos=2;
                        for i=2:sizeh
                            h0(pos)= h(i);
                            pos= pos+1;
                            h0(pos) = h(i);
                            pos= pos+1;
            
                        end
                         assignin('base','H0',h0);
            
            
                        F=zeros(1,sizeh0);
                        F(1)=h(1)*SS(2)/2;
            
                        for i=2:sizeh0
                            residuo=rem(i,2);
                            if residuo==0
                                F(i)=h0(i)*SS(i+1);
                            else
                                F(i)=h0(i)*(SS(i+1)-SS(i))/2;
                            end
            
            
                        end
            
            
                        assignin('base','Factua',F);
                    end
                end
            
            
                %%%%%%%%%%%%%%%
                if ka(1)== ka(2)%si phi son iguales cambia el tamaño de las matrices de fuerzas
                    for ii=1:n
                        sa(ii)=St(ii)*ka(ii);
                    end
                    if n==2
                        sb(1)=St(2)*ka(1);
                        sb(2)=St(3)*ka(2);
                        %reorganiza
                        w=1;
                        for i=1:n
                            SS(1)=sa(1);
                            w=w+1;
                            SS(w)=sb(i);
                        end
                        F(1)=SS(3)*(h(1)+h(2))/2;
                        assignin('base','Factua',F);
                    end
            
                    if n==3
                        sb(1)=St(3)*ka(1);
                        sb(2)=St(3)*ka(3);
                        sb(3)=St(4)*ka(3);
                        %reorganiza
                        w=1;
                        for i=1:n
                            SS(1)=sa(1);
                            w=w+1;
                            SS(w)=sb(i);
            
                        end
            
                        F(1)=SS(2)*(h(1)+h(2))/2;
                        F(2)=SS(3)*(h(3));
                        F(3)=(SS(4)-SS(3))*(h(3))/2;
                        assignin('base','Factua',F);
                    end
                    if n==4
                        sb(1)=St(3)*ka(1);
                        sb(2)=St(3)*ka(3);
                        sb(3)=St(4)*ka(3);
                        sb(4)=St(5)*ka(4);
                        %reorganiza
                        w=1;
                        for i=1:n
                            SS(1)=sa(1);
                            w=w+1;
                            SS(w)=sb(i);
            
                        end
                        F(1)=SS(2)*(h(1)+h(2))/2;
                        F(2)=SS(3)*(h(3));
                        F(3)=(SS(4)-SS(3))*(h(3))/2;
                        F(4)=SS(5)*(h(4));
                        F(5)=(SS(5)-SS(4))*h(4)/2;
                        assignin('base','Factua',F);
                    end
            
            
                end
            
                 assignin('base','Sa',sa);
                 assignin('base','SB',sb);
                 assignin('base','SS',SS);
            
                % Peso agua si hay nivel freatico
                if nf>0
                    if nf==1 && n==2
                        if R1==1
                            pesoagua=9.81*(h(1)+h(2))^2/2;
                            assignin('base','PesoAgua',pesoagua);
                        end
                        if R2==1
                            pesoagua=62.4*(h(1)+h(2))^2/2;
                            assignin('base','PesoAgua',pesoagua);
                        end
                    end
                    if nf>1 && n==2
                        if R1==1
                            pesoagua=9.81*(h(nf))^2/2;
                            assignin('base','PesoAgua',pesoagua);
                        end
                        if R2==1
                            pesoagua=62.4*(h(nf))^2/2;
                            assignin('base','PesoAgua',pesoagua);
                        end
            
                    end
                    if n>2 && nf==1
                        if R1==1
                            pesoagua=9.81*(sum(h))^2/2;
                            assignin('base','PesoAgua',pesoagua);
                        end
                        if R2==1
                            pesoagua=62.4*(sum(h))^2/2;
                            assignin('base','PesoAgua',pesoagua);
                        end
                    end
            
            
                    if nf>=2 && n>2
                        hf=0;
                        for i=nf:n
                            hf=hf+h(i);
                        end
                        if R1==1
                            pesoagua=9.81*(hf)^2/2;
                        end
                        if R2==1
                            pesoagua=62.4*(hf)^2/2;
                        end
                        assignin('base','Hf',hf);
                        assignin('base','PesoAgua',pesoagua);
            
                    end
            
                else
                    pesoagua=0;
                    assignin('base','PesoAgua',pesoagua);
                end
            
            
                PesoA=sum(F);
                PesoVert=sind(a).*F;
                assignin('base','Pesoverti',PesoVert);
                PesoVerts=sum(PesoVert);
                assignin('base','Pesovertis',PesoVerts);
            
                PesoHor=cosd(a).*F;
                assignin('base','Pesohor',PesoHor);
            
                PesoAct=PesoHor+pesoagua;
                PesoActs=sum(PesoAct);
                assignin('base','PeACT',PesoAct);
                assignin('base','PeACT',PesoActs);


                %RESISTENTE
                %Volumen
                assignin('base','Cant',Cant);
                if Cant==1
                    Vol(1)=Co*(Ha-AlTalo)*1;
                    Vol(2)=(BaseP-Co)*(Ha-AlTalo);%Parte triangular
                    Vol(3)=Base*AlTalo*1;
                    hactuante=h;
                    hactuante(n)=hactuante(n)-AlTalo;
                    for i=1:n
                    Vol(i+3)=hactuante(i)*AncT;
                    end
                    assignin('base','VOLUM',Vol);
                    %Peso
                    Pes(1)=Vol(1)*concreto;%Kn
                    Pes(2)=Vol(2)*concreto;%Kn
                    Pes(3)=Vol(3)*concreto;%Kn
                    for i=1:n
                    Pes(i+3)=Vol(i+3)*g(i);
                    end
                end
            
                if Cant==0
                    Vol(1)=Co*(Ha-AlTalo)*1;
                    Vol(2)=Base*AlTalo*1;
                    hactuante=h;
                    hactuante(n)=hactuante(n)-AlTalo;
                    for i=1:n
                    Vol(i+2)=hactuante(i)*AncT;
                    end
                    assignin('base','VOLUM',Vol);
                    %Peso
                    Pes(1)=Vol(1)*concreto;%Kn
                    Pes(2)=Vol(2)*concreto;%Kn
                    for i=1:n
                    Pes(i+2)=Vol(i+2)*g(i);
                    end
                end
            
                assignin('base','PESO',Pes);
                %PESO SIN CARGA
                PesoRes=sum(Pes);
                assignin('base','PesoResis',PesoRes);
            
                %Brazos Resistente
            
               anctalon=Base-AncT-Co;
            
               if Cant==1
                   B(1)=anctalon+(Co/2);
                   B(2)=anctalon+(Co*2/3);
                   B(3)=Base/2;
                   B(4)=anctalon+Co+(AncT*2/3);%Cuaña inclicada de suelo
                   for i=1:n-1
                   B(i+4)=(AncT/2)+Co+anctalon;
                   end
                   assignin('base','Brazos',B);
            
                   %Momento resistente
                   M(1)=B(1)*Pes(1);
                   M(2)=B(2)*Pes(2);
                   M(3)=B(3)*Pes(3);
                   M(4)=B(4)*Pes(4);
                   for i=1:n-1
                   M(i+4)=B(i+4)*Pes(i+4);%desde 4 es suelo
                   end
               end
               if Cant==0
                   B(1)=anctalon+(Co/2);
                   B(2)=Base/2;
                   B(3)=anctalon+Co+(AncT*2/3);%Cuaña inclicada de suelo
                   for i=1:n-1
                   B(i+3)=(AncT/2)+Co+anctalon;
                   end
                   assignin('base','Brazosresis',B);
                   %Momento resistente
                   M(1)=B(1)*Pes(1);
                   M(2)=B(2)*Pes(2);
                   M(3)=B(3)*Pes(3);
                   for i=1:n-1
                       M(i+3)=B(i+3)*Pes(i+3);%desde 4 es suelo
                   end
            
               end
            
               Mresistente=sum(M);
               assignin('base','Mresistente',Mresistente);
            
            
               %%%%%%%
               %Brazo Actuante para suelo
            
               br(1)=h(1)*2/3;
               j=2;
               Lr=h(1);
            
               if n>2 && phi(1)==phi(2)
                   Lr=h(1)+h(2);
               end
            
               if n==2 && phi(1)==phi(2)
                   br(1)=(h(1)+h(2))*2/3;%Solo abra un brazo debido a que no hay cambio en el phi
               end
            
               if n>1 && phi(1)~=phi(2)
                   %brazos de arriba hacia abajo % mas de 2 estratos con phi diferente
                   for i=2:n
                       br(j)=Lr+h(i)/2;
                       br(j+1)=Lr+h(i)*2/3;
                       j=j+2;
                       Lr=Lr+h(i);
                   end
               end
            
               if n>2 && phi(1)==phi(2)%Mas de 2 estratos con phi1 = phi2
                   for i=3:n
                       hka=h(1)+h(2);
                       br(1)=hka*2/3;
                       br(j)=Lr+h(i)/2;
                       br(j+1)=Lr+h(i)*2/3;
                       j=j+2;
                       Lr=Lr+h(i);
                   end
            
               end
               assignin('base','br',br);
               %brazos en el punto de abajo
               brvolteados=sum(hcopiaa)-br;
               assignin('base','Brazosr',brvolteados);
            
               % Brazo para agua
               hf=0;
               for i=nf:n
                   hf=hf+h(i);
               end
               assignin('base','hf',hf);
               Ba=hf*1/3;
               Magua=Ba*pesoagua;
               assignin('base','Magua',Magua);
            
               %Momento actuante suelo
            
               Maa=brvolteados.*PesoHor;
               assignin('base','Maa',Maa);
            
               Mactuante=sum(Maa)+Magua;
               assignin('base','Mactuante',Mactuante);
            
            
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %Caso sin carga
                if R==1 %R=1 es que hay suelo posterior y se le esta al peso actuante y al momento actuante
            
                    FSD=((PesoRes+PesoVerts)*tand(d)+(c*Base))/(PesoActs-Fp);
            %         FSDc=((PesoRes+PesoVert)*tand(d)+(c1*Base))/PesoAct;
                    FSV=Mresistente/((Mactuante-Mp)-(PesoVerts*Base));
                    assignin('base','FSDD',FSD);
                    set(handles.FSD,'String',round(FSD,2));
                %    set(handles.FSDc,'String',FSDc);
                    set(handles.FSV,'String',round(FSV,2));
                end
            
                if R==0
            
                    FSD=((PesoRes+PesoVerts)*tand(d)+(c*Base))/(PesoActs);
            %         FSDc=((PesoRes+PesoVert)*tand(d)+(c1*Base))/PesoAct;
                    FSV=Mresistente/(Mactuante-(PesoVerts*Base));
                    assignin('base','FSDD',FSD);
                    set(handles.FSD,'String',round(FSD,2));
                %   set(handles.FSDc,'String',FSDc);
                    set(handles.FSV,'String',round(FSV,2));
                end


                if n==2

                    reporte=questdlg('Desea generar reporte?', ...
                        'Generar reporte', ...
                        'SI',' NO  ','  CANCELAR  ','');
                        % Handle response
                        switch reporte

                            case 'SI'  
                                          

                                % Add content to container (required)
                                % Types of content added here: title 
                                % page and table of contents reporters
                                doctype = "pdf";                  
                                rpt = Report("REPORTE MUROS DE RETENCIÓN",doctype);
                                open(rpt);
                                
                    
                                layoutObj = rpt.Layout;
                                layoutObj.PageSize = PageSize("10in","11in","portrait");
                    
                                %Create Report container
                                rpt.Layout.Landscape = 1;
                                rpt.Layout.PageNumberFormat = "i";
                                titlepg = TitlePage;
                    
                                 %%%%%%%%%%%%
                                titlepg.Title = 'ANÁLISIS GEOTÉCNICO DE MUROS DE RETENCIÓN';
                                titlepg.Author = 'Juan Solarte';
                                tplayoutObj = titlepg.Layout;
                                tplayoutObj.PageSize = PageSize("10in","12in","portrait");
                                imDir = which("m.png");
                                if exist(imDir,"file") == 2
                                    ImObj = Image(imDir);
                                    ImObj.Height = "5in";
                                    ImObj.Width = "6in";
                                    titlepg.Image = ImObj;
                                end
                                add(rpt,titlepg);
                                add(rpt,TableOfContents);
                        
                    %%%%%%%%%%%%%%%5
                                % Add content to report sections (optional)
                                % Text and formal image added to chapter
                    
                    
                                chap = Chapter('Valores de entrada');
                                %TABLA MURO Triangular
                                if Cant==0


                                    s1=Section("CARACTERISTICAS MURO(Triangular)");
                                    seccion1=["ALTURA MURO","BASE","CORONA ","ANCHO TALÓN","ALTURA TALÓN","PESO ESPECIFICO (concreto)"]';
                                    volumen1=[Ha,Base,Co,AncT,AlTalo,concreto]';
                                   
                                    
                                    Tabla1= FormalTable(["PROPIEDAD","VALOR"],[seccion1,volumen1]);
                                    Tabla1.Header.Style{end+1} = BackgroundColor("silver");
                                    Tabla1.Width = "500pt";
                                    Tabla1.Border="solid";
                                    Tabla1.ColSep="solid";
                                    Tabla1.RowSep="solid";
                                    Tabla1.HAlign="left";
                                    Tabla1.TableEntriesHAlign="center";

                                end


                                 %Tabla muro canteliver

                                if Cant==1
                                    %Base,Ha,AlTalo,AncT,Co,concreto,So,Cant,d,Fp,Mp,hcopiaa,k,phii,phi,c,nf,n,R,h,g,R1,R2,BaseP)

                                    s1=Section("CARACTERISTICAS MURO(Canteliver)");
                                    seccion1=["ALTURA MURO","BASE","CORONA ","ANCHO TALÓN","ALTURA TALÓN","BASE PANTALLA","PESO ESPECIFICO (concreto)"]';
                                    volumen1=[Ha,Base,Co,AncT,AlTalo,BaseP,concreto]';
                                   
                                    
                                    Tabla1= FormalTable(["PROPIEDAD","VALOR"],[seccion1,volumen1]);
                                    Tabla1.Header.Style{end+1} = BackgroundColor("silver");
                                    Tabla1.Width = "500pt";
                                    Tabla1.Border="solid";
                                    Tabla1.ColSep="solid";
                                    Tabla1.RowSep="solid";
                                    Tabla1.HAlign="left";
                                    Tabla1.TableEntriesHAlign="center";


                                end


                                %TABLA SUELO RELLENO
                                 ss1=Section("CARACTERISTICAS SUELO DE RELLENO")
                                  %Base,Ha,AlTalo,AncT,Co,concreto,So,Cant,d,Fp,Mp,hcopiaa,k,phii,phi,c,nf,n,R,h,g,R1,R2,BaseP)
                                
                                seccions1=["No. ESTRATOS","ESTRATO DEL NIVEL FREATICO" ]';                           
                                                                                                                                   
                                volumens1=[n,nf]';                              
                                  
                                
                                Tablas1= FormalTable(["PROPIEDAD","VALOR"],[seccions1,volumens1]);
                                Tablas1.Header.Style{end+1} = BackgroundColor("silver");
                                Tablas1.Width = "300pt";
                                Tablas1.Border="solid";
                                Tablas1.ColSep="solid";
                                Tablas1.RowSep="solid";
                                Tablas1.HAlign="left";
                                Tablas1.TableEntriesHAlign="center";


                                %%%
                                seccions11=[1,2]';                         
                                                                                                                                   
                                volumens11=[hcopia(1),hcopia(2)]'; 
                                p=[g(1),g(2)]'; 
                                pi=[phi(1),phi(2)]';
                                alp=[alpha(1),""]';
                                Tablas11= FormalTable(["ESTRATO","INCLINACIÓN DEL ESTRATO","ALTURA ESTRATO","PESO ESPECIFICO ESTRATO","ANGULO PHI ESTRATO"],[seccions11,alp,volumens11,p,pi]);
                                Tablas11.Header.Style{end+1} = BackgroundColor("silver");
                                Tablas11.Width = "500pt";
                                Tablas11.Border="solid";
                                Tablas11.ColSep="solid";
                                Tablas11.RowSep="solid";
                                Tablas11.HAlign="left";
                                Tablas11.TableEntriesHAlign="center";
                                Tablas11.Style= [Tablas11.Style {NumberFormat("%.2f")}];

                                %%%
                    
                                %TABLA SUELO DE CIMENTACION
                                ssr1=Section("CARACTERISTICAS SUELO DE CIMENTACIÓN")
                                seccionsr1=["ANGULO DE FRICCION Base (ϕ2)","PESO ESPECIFICO (cimentación)","COHESIÓN"]';
                                volumensr1=[phii(end),g(end),c]';
                               %phib,concreto,espesor,phi,PesoE,Cohesion,n,k,espe,pe,ph,R,TS,R1,R2,kh,kv,So
                                
                                
                                Tablasr1= FormalTable(["PROPIEDAD","VALOR"],[seccionsr1,volumensr1]);
                                Tablasr1.Header.Style{end+1} = BackgroundColor("silver");
                                Tablasr1.Width = "600pt";
                                Tablasr1.Border="solid";
                                Tablasr1.ColSep="solid";
                                Tablasr1.RowSep="solid";
                                Tablasr1.HAlign="left";
                                Tablasr1.TableEntriesHAlign="center";
                                Tablasr1.Style= [Tablasr1.Style {NumberFormat("%.2f")}];
                                
                                add(s1,Tabla1)
                               
                                add(ss1,Tablas1)
                                add(ss1,Tablas11)
                                add(ssr1,Tablasr1)
                                add(chap,s1)
                                add(chap,ss1)
                                add(chap,ssr1)
                                add(rpt,chap)
                    
                                 %%%%%%%Capitulo2
                                chap2 = Chapter('Factores Resistentes');
                                s=Section("Partes resistentes");

                                if Cant==0
                                    image2 = mlreportgen.report.FormalImage();
                                    image2.Image = which('inclinado.png');
                                    image2.Caption = 'Partes Resistente(Muro Triangular)';
                                    image2.Height = '3in';
                                    image2.Width = '4in';   

                                    seccion=["A","B","C","D"," "]';
                                    volumen=[Vol(1),Vol(2),Vol(3),Vol(4),Vol(1)+Vol(2)+Vol(3)+Vol(4)]';
                                    peso=[Pes(1),Pes(2),Pes(3),Pes(4),PesoRes]';
                                    brazo=[B(1),B(2),B(3),B(4)," "]';
                                    momento=[M(1),M(2),M(3),M(4),Mresistente]';
                                    Tabla= FormalTable(["SECCIÓN","VOLUMEN","PESO","BRAZO","MOMENTO"],[seccion,volumen,peso,brazo,momento]);
                                    Tabla.Style = {OuterMargin("0pt", "0pt","50pt","5pt")};
                                    Tabla.Header.Style{end+1} = BackgroundColor("silver");
                                    Tabla.Width = "500pt";
                                    Tabla.Border="solid";
                                    Tabla.ColSep="solid";
                                    Tabla.RowSep="solid";
                                    Tabla.HAlign="center";
                                    Tabla.TableEntriesHAlign="center";
                                    
                                end
                                if Cant==1
                                    image2 = mlreportgen.report.FormalImage();
                                    image2.Image = which('inclinadotriza.png');
                                    image2.Caption = 'Partes Resistente(Muro Canteliver)';
                                    image2.Height = '3in';
                                    image2.Width = '4in'; 

                                    seccion=["A","B","C","D"," "]';
                                    volumen=[Vol(1),Vol(2),Vol(3),Vol(4),Vol(1)+Vol(2)+Vol(3)+Vol(4)]';
                                    peso=[Pes(1),Pes(2),Pes(3),Pes(4),PesoRes]';
                                    brazo=[B(1),B(2),B(3),B(4)," "]';
                                    momento=[M(1),M(2),M(3),M(4),Mresistente]';
                                    Tabla= FormalTable(["SECCIÓN","VOLUMEN","PESO","BRAZO","MOMENTO"],[seccion,volumen,peso,brazo,momento]);
                                    Tabla.Style = {OuterMargin("0pt", "0pt","50pt","5pt")};
                                    Tabla.Header.Style{end+1} = BackgroundColor("silver");
                                    Tabla.Width = "500pt";
                                    Tabla.Border="solid";
                                    Tabla.ColSep="solid";
                                    Tabla.RowSep="solid";
                                    Tabla.HAlign="center";
                                    Tabla.TableEntriesHAlign="center";
                                
                                end
                                                      
                                                             
                                add(s,image2);
                               
%                                 plot1 = Image("reportetriza.png");
%                                 plot1.Width = "4in";
%                                 plot1.Height = "3in";
%                                 plot1.Style = {OuterMargin("150pt", "0pt","0pt","5pt")};
%                                 append(s,plot1);


                                add(s,Tabla)
                                add(chap2,s)
                                add(rpt,chap2)
                                %%%%%%%Capitulo3
                                chap3 = Chapter('Factores Actuantes');
                                if k==2
                                    s3=Section("Condición Activa de Rankine");
                                     condicion=["Ka"]';
                                end
                                if k==3
                                    s3=Section("Condición Reposo de Rankine");
                                     condicion=["Ko"]';
                                end


%                                 plot3 = Image("kacou.png");
%                                 plot3.Style = {OuterMargin("100pt", "0pt","0pt","5pt")};
%                                 append(s3,plot3);
                    
                                
                                valor=[ka]';
                               
                                
                                Tabla3= FormalTable(["CONDICIÓN", " "],[condicion,valor]);
                                Tabla3.Header.Style{end+1} = BackgroundColor("silver");
                                Tabla3.Style = {OuterMargin("0pt", "0pt","30pt","30pt")};
                                Tabla3.Width = "200pt";
                                Tabla3.Border="solid";
                                Tabla3.ColSep="solid";
                                Tabla3.RowSep="solid";
                                Tabla3.HAlign="center";
                                Tabla3.TableEntriesHAlign="center";
                                Tabla3.Style= [Tabla3.Style {NumberFormat("%.2f")}];
                                add(s3,Tabla3);
                    
                                %%%%TABLA FUERZAS
                                s32=Section("Fuerzas");
                                secc=[1,"agua"]';
                                fue=[F(1),pesoagua]';
                                mom=[Maa,Magua]';
                                fue=round(fue,3);
                                mom=round(mom,3);
                                
                                Tabla31= FormalTable([" "," Fuerzas ","Momento "],[secc,fue,mom]);
                                Tabla31.Header.Style{end+1} = BackgroundColor("silver");
                                Tabla31.Style = {OuterMargin("0pt", "0pt","40pt","40pt")};
                                Tabla31.Width = "400pt";
                                Tabla31.Border="solid";
                                Tabla31.ColSep="solid";
                                Tabla31.RowSep="solid";
                                Tabla31.HAlign="center";
                                Tabla31.TableEntriesHAlign="center";
                                Tabla31.Style= [Tabla31.Style {NumberFormat("%.2f")}];
                                add(s32,Tabla31);
%                                 %%%%FUERZAS DESCOMPUESTAS
%                                 secc1=["Pav (Pa*sen(δ))","Pah (Pa*cos(δ))"]';
%                                 fue=[pvertical,phor]';
%                                 bra=[Bvertical,Ba]';
%                                 mom=[Mvertical,Ma(1)]';
%                                 
%                                 Tabla32= FormalTable(["SECCIÓN"," Fuerzas ","Brazos","Momento "],[secc1,fue,bra,mom]);
%                                 Tabla32.Header.Style{end+1} = BackgroundColor("silver");
%                                 Tabla32.Style = {OuterMargin("0pt", "0pt","40pt","40pt")};
%                                 Tabla32.Width = "500pt";
%                                 Tabla32.Border="solid";
%                                 Tabla32.ColSep="solid";
%                                 Tabla32.RowSep="solid";
%                                 Tabla32.HAlign="center";
%                                 Tabla32.TableEntriesHAlign="center";
%                                 Tabla32.Style= [Tabla32.Style {NumberFormat("%.2f")}];
%                                 add(s32,Tabla32);
                               
                    
                                add(chap3,s3);
                                add(chap3,s32)
                                add(rpt,chap3);
                     %%%%%%%Capitulo5
                    
                    
                                chap5 = Chapter('Factores de seguridad estaticos');
                                s5=Section("Factor de seguridad al desplazamiento");
                                image5 = mlreportgen.report.FormalImage();
                                image5.Image = which('fsdes.png');
                                image5.Caption = 'Ecucaion para el Factor de seguridad al deslizamiento ';
                                add(s5,image5);
%                                 plot5 = Image("fsdes.png");
%                                 plot5.Style = {OuterMargin("100pt", "0pt","0pt","5pt")};
%                                 append(s5,plot5);
                                s52=Section("Factor de seguridad al volcamiento");
                                image52 = mlreportgen.report.FormalImage();
                                image52.Image = which('fsvol.png');
                                image5.Caption = 'Ecucaion para el Factor de seguridad al volcamiento ';
                                add(s52,image52);

%                                 plot52 = Image("fsvol.png");
%                                 plot52.Style = {OuterMargin("100pt", "0pt","0pt","5pt")};
%                                 append(s52,plot52);
%                                 
                                s53=Section("Resultados");
                                %TABLARESULTADOS
                                fsde=[FSD]';
                                fsv1=[FSV]';


                                Tabla5= FormalTable(["F.S DES","F.S.1 VOL"],[fsde,fsv1]);
                                Tabla5.Style = {OuterMargin("0pt", "0pt","50pt","5pt")};
                                Tabla5.Header.Style{end+1} = BackgroundColor("silver");
                                Tabla5.Width = "400pt";
                                Tabla5.Border="solid";
                                Tabla5.ColSep="solid";
                                Tabla5.RowSep="solid";
                                Tabla5.HAlign="center";
                                Tabla5.TableEntriesHAlign="center";
                                Tabla5.Style= [Tabla5.Style {NumberFormat("%.2f")}];
                                add(s53,Tabla5);
                    
                    
                                add(chap5,s5);
                                add(chap5,s52);
                                add(chap5,s53);
                                add(rpt,chap5);

                                % Close the report (required)
                                close(rpt)
                                % Display the report (optional)
                                rptview(rpt);
            
            
                             case 'NO'

                        end
            

                 end


            end
        end

        % Button pushed function: Estratigrafia
        function Estratigrafia_Callback(app, event)
            % --- Executes on button press in Estratigrafia.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Estratigrafia (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global R2 R1
            
            if R1==1
                R2=0;
                R11=1;
                R22=0;
            end
            if R2==1
                R1=0;
                R22=1;
                R11=0;
            end
            
            waitfor(warndlg('Tenga en cuenta que si el terreno esta inclinado, la cuña del ángulo cuenta como un estrato, para lo cual debe ingrasarla, se puede realizar el calculo con AlturaCuña=anchoTalón*Tan(α)','Observación'));
            Estratificacionprimer_App(R11,R22)
            app.Calcular.Enable="on";
            
        end

        % Button pushed function: ayuda
        function ayuda_Callback(app, event)
            % --- Executes on button press in ayuda.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ayuda (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            winopen('Fundamentos.pdf')
        end

        % Value changed function: checkbox1
        function checkbox1_Callback(app, event)
            % --- Executes on button press in checkbox1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox1
            global R3
            R3=get(hObject,'Value');
            if R3==1
                set(handles.Carga,'Visible','off');
                set(handles.text23,'Visible','off');
                set(handles.text24,'Visible','off');
                set(handles.uibuttongroup3,'Visible','off');
                set(handles.popupmenu2,'Visible','off');
            
                axes(handles.axes3); %Definimos axes2 como el objeto al que le meteremos una imagen secundaria.
                bkgrnd=imread('Mt.jpg'); %Leemos la imagen.
                imshow(bkgrnd); %Mostramos la imagen.
            else
                set(handles.Carga,'Visible','on');
                set(handles.text23,'Visible','on');
                set(handles.text24,'Visible','on');
                set(handles.uibuttongroup3,'Visible','on');
                set(handles.popupmenu2,'Visible','on');
            
                axes(handles.axes3); %Definimos axes2 como el objeto al que le meteremos una imagen secundaria.
                bkgrnd=imread('principal.jpg'); %Leemos la imagen.
                imshow(bkgrnd); %Mostramos la imagen.
            
            end
        end

        % Value changed function: checkbox4
        function checkbox4_Callback(app, event)
            % --- Executes on button press in checkbox4.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox4
            global Cant
            Cant=get(hObject,'Value');
            if Cant==0
                set(handles.edit10,'Visible','off');
                set(handles.text39,'Visible','off');
                set(handles.text40,'Visible','off');
            
                axes(handles.axes3); %Definimos axes2 como el objeto al que le meteremos una imagen secundaria.
                bkgrnd=imread('principal.jpg'); %Leemos la imagen.
                imshow(bkgrnd); %Mostramos la imagen.
            
            else
                set(handles.edit10,'Visible','on');
                set(handles.text39,'Visible','on');
                set(handles.text40,'Visible','on');
            
                axes(handles.axes3); %Definimos axes2 como el objeto al que le meteremos una imagen secundaria.
                bkgrnd=imread('canti.png'); %Leemos la imagen.
                imshow(bkgrnd); %Mostramos la imagen.
            end
        end

        % Value changed function: popupmenu2
        function popupmenu2_Callback(app, event)
            % --- Executes on selection change in popupmenu2.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu2 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu2
            global FSD1 FSV1 FSD2 FSV2 FSD3 FSV3 aa
            aa=get(hObject,'Value');
            if aa==1
                axes(handles.axes3); %Definimos axes2 como el objeto al que le meteremos una imagen secundaria.
                bkgrnd=imread('principal.jpg'); %Leemos la imagen.
                imshow(bkgrnd); %Mostramos la imagen.
                
            end
            if aa==2
%                 set(handles.FSD,'String',round(FSD1,2));
%                 set(handles.FSV,'String',round(FSV1,2));
            
            
                axes(handles.axes3); %Definimos axes2 como el objeto al que le meteremos una imagen secundaria.
                bkgrnd=imread('qantes.jpg'); %Leemos la imagen.
                imshow(bkgrnd); %Mostramos la imagen.
            end
            if aa==3
%                 set(handles.FSD,'String',round(FSD2,2));
%                 set(handles.FSV,'String',round(FSV2,2));
            
            
                axes(handles.axes3); %Definimos axes2 como el objeto al que le meteremos una imagen secundaria.
                bkgrnd=imread('qdespues.jpg'); %Leemos la imagen.
                imshow(bkgrnd); %Mostramos la imagen.
            
            end
            if aa==4
%                 set(handles.FSD,'String',round(FSD3,2));
%                 set(handles.FSV,'String',round(FSV3,2));
            
                axes(handles.axes3); %Definimos axes2 como el objeto al que le meteremos una imagen secundaria.
                bkgrnd=imread('voladizocargaa.jpg'); %Leemos la imagen.
                imshow(bkgrnd); %Mostramos la imagen.
            end
        end

        % Button pushed function: restablecer
        function restablecer_Callback(app, event)
            % --- Executes on button press in restablecer.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to restablecer (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %limpiar textboxs
            set(handles.pconcreto,'string','');
            set(handles.base,'string','');
            set(handles.altura,'string','');
            set(handles.corona,'string','');
            set(handles.alttalon,'string','');
            set(handles.ancpuntera,'string','');
            set(handles.Carga,'string','');
            set(handles.delta,'string','');
            set(handles.FSD,'string','');
            set(handles.FSV,'string','');
            
        end

        % Button pushed function: salir
        function salir_Callback(app, event)
            % --- Executes on button press in salir.
            app.delete
            
        end

        % Selection changed function: uibuttongroup1
        function uibuttongroup1SelectionChanged(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            selectedButton = app.uibuttongroup1.SelectedObject;
            global R1 R2 R3 
            R1=app.radiobutton1.Value;
            R2=app.radiobutton2.Value;
            Base=str2double(get(handles.base, 'string')); % Base del muro de contencion
            Ha=str2double(get(handles.altura, 'string')); % altura total
            AlTalo=str2double(get(handles.alttalon, 'string'));  %altura del talon
            AncT=str2double(get(handles.ancpuntera, 'string')); %ancho del talon
            Co=str2double(get(handles.corona, 'string')); %ancho de la corona
            So=str2double(get(handles.Carga, 'string'));
            concreto=str2double(get(handles.pconcreto, 'string'));
            if R1==1
   
                set(handles.text8,'string','m');
                set(handles.text9,'string','m');
                set(handles.text10,'string','m');
                set(handles.text11,'string','m');
                set(handles.text12,'string','m');
                set(handles.text39,'string','m');
                
                set(handles.text44,'string','kN/m^{3}');
                if R3==0
                   set(handles.text24,'string','kN/m');   
                   set(handles.Carga,'String',round(So*0.068521 ,2));
                end
                
                set(handles.base,'String',round(Base*0.3048 ,2));
                set(handles.altura,'String',round(Ha*0.3048 ,2));
                set(handles.corona,'String',round(Co*0.3048 ,2));
                set(handles.base,'String',round(Base*0.3048 ,2) );
                set(handles.alttalon,'String',round(AlTalo*0.3048 ,2));
                set(handles.ancpuntera,'String',round(AncT*0.3048 ,2));
                set(handles.pconcreto,'String',round(concreto/6.365880 ,2));
                
            end

            if R2==1
    
                set(handles.text8,'string','ft');
                set(handles.text9,'string','ft');
                set(handles.text10,'string','ft');
                set(handles.text11,'string','ft');
                set(handles.text12,'string','ft');
                set(handles.text39,'string','ft');
                set(handles.text44,'string','lb/ft^{3}');
                set(handles.text24,'string','kips/ft');
                set(handles.Carga,'String',round(So/ 0.068521 ,2));
                 if R3==0
                   set(handles.text24,'string','kips/ft');
                   set(handles.Carga,'String',round(So/ 0.068521 ,2));
                end
                set(handles.base,'String',round(Base/0.3048 ,2));
                set(handles.altura,'String',round(Ha/0.3048 ,2));
                set(handles.corona,'String',round(Co/0.3048 ,2));
                set(handles.base,'String',round(Base/0.3048 ,2));
                set(handles.alttalon,'String',round(AlTalo/0.3048 ,2));
                set(handles.ancpuntera,'String',round(AncT/0.3048 ,2));
                set(handles.pconcreto,'String',round(concreto*6.365880 ,2));
            end

        end

        % Menu selected function: MenPrincipalMenu
        function MenPrincipalMenuSelected(app, event)
            app.delete
            press11
        end

        % Menu selected function: AnlisisEstaticoMenu
        function AnlisisEstaticoMenuSelected(app, event)
            app.delete
            press2
        end

        % Callback function
        function MurodegravedadMenuSelected(app, event)
            app.delete
            MGravedad_App
        end

        % Menu selected function: AnlisispseudoestaticoMenu
        function AnlisispseudoestaticoMenuSelected(app, event)
            app.delete
            MGrape_App
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Muro en Voladizo';
            app.figure1.Icon = fullfile(pathToMLAPP, 'ima', 'm.png');
            app.figure1.Tag = 'figure1';
            app.MenMenu = uimenu(app.figure1);
            app.MenMenu.Text = 'Menú';
            app.MenPrincipalMenu = uimenu(app.MenMenu);
            app.MenPrincipalMenu.MenuSelectedFcn = createCallbackFcn(app, @MenPrincipalMenuSelected, true);
            app.MenPrincipalMenu.Text = 'Menú Principal';
            app.AnlisisEstaticoMenu = uimenu(app.MenMenu);
            app.AnlisisEstaticoMenu.MenuSelectedFcn = createCallbackFcn(app, @AnlisisEstaticoMenuSelected, true);
            app.AnlisisEstaticoMenu.Text = 'Análisis Estatico';
            app.AnlisispseudoestaticoMenu = uimenu(app.MenMenu);
            app.AnlisispseudoestaticoMenu.MenuSelectedFcn = createCallbackFcn(app, @AnlisispseudoestaticoMenuSelected, true);
            app.AnlisispseudoestaticoMenu.Text = 'Análisis pseudo-estatico';
            app.axes3 = uiaxes(app.figure1);
            app.axes3.XTick = [];
            app.axes3.YTick = [];
            app.axes3.Tag = 'axes3';
            colormap(app.axes3, 'turbo')
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Muro en Voladizo';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Caracteristicas del muro';
            app.uipanel1.Tag = 'uipanel1';
            app.base = uieditfield(app.uipanel1, 'text');
            app.base.Tag = 'base';
            app.base.Tooltip = {'''Ancho de la base del muro de retención'''};
            app.altura = uieditfield(app.uipanel1, 'text');
            app.altura.Tag = 'altura';
            app.altura.Tooltip = {'''Altura total del muro de retención'''};
            app.corona = uieditfield(app.uipanel1, 'text');
            app.corona.Tag = 'corona';
            app.corona.Tooltip = {'''Corona'''; 'Parte Superior del muro de retención'};
            app.alttalon = uieditfield(app.uipanel1, 'text');
            app.alttalon.Tag = 'alttalon';
            app.alttalon.Tooltip = {'''Altura del talon'''};
            app.ancpuntera = uieditfield(app.uipanel1, 'text');
            app.ancpuntera.Tag = 'ancpuntera';
            app.ancpuntera.Tooltip = {'''Ancho de la del talón'''};
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Base Muro';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Altura muro(H)';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Corona';
            app.text6 = uilabel(app.uipanel1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Altura Talón(H.T)';
            app.text7 = uilabel(app.uipanel1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Ancho Talón';
            app.text8 = uilabel(app.uipanel1);
            app.text8.Interpreter = 'tex';
            app.text8.Tag = 'text8';
            app.text8.Text = 'm';
            app.text9 = uilabel(app.uipanel1);
            app.text9.Interpreter = 'tex';
            app.text9.Tag = 'text9';
            app.text9.Text = 'm';
            app.text10 = uilabel(app.uipanel1);
            app.text10.Interpreter = 'tex';
            app.text10.Tag = 'text10';
            app.text10.Text = 'm';
            app.text11 = uilabel(app.uipanel1);
            app.text11.Interpreter = 'tex';
            app.text11.Tag = 'text11';
            app.text11.Text = 'm';
            app.text12 = uilabel(app.uipanel1);
            app.text12.Interpreter = 'tex';
            app.text12.Tag = 'text12';
            app.text12.Text = 'm';
            app.text16 = uilabel(app.uipanel1);
            app.text16.Tag = 'text16';
            app.text16.Text = {'Ángulo de'; 'fricción'; 'suelo-base (δ)'};
            app.delta = uieditfield(app.uipanel1, 'text');
            app.delta.Tag = 'delta';
            app.delta.Tooltip = {'''Ángulo delta'' '; 'ángulo de friccion entre la base del muro y el suelo'};
            app.text17 = uilabel(app.uipanel1);
            app.text17.Interpreter = 'tex';
            app.text17.Tag = 'text17';
            app.text17.Text = 'grados°';
            app.text23 = uilabel(app.uipanel1);
            app.text23.Tag = 'text23';
            app.text23.Text = 'Carga';
            app.text24 = uilabel(app.uipanel1);
            app.text24.Interpreter = 'tex';
            app.text24.Tag = 'text24';
            app.text24.Text = 'kN/m';
            app.Carga = uieditfield(app.uipanel1, 'text');
            app.Carga.Tag = 'Carga';
            app.Carga.Tooltip = {''' Sobrecarga en el terreno'''};
            app.Estratigrafia = uibutton(app.uipanel1, 'push');
            app.Estratigrafia.ButtonPushedFcn = createCallbackFcn(app, @Estratigrafia_Callback, true);
            app.Estratigrafia.Tag = 'Estratigrafia';
            app.Estratigrafia.Tooltip = {'''SUELO'''; 'Aqui se ingresan los datos del terreno.'};
            app.Estratigrafia.Text = 'SUELO';
            app.uibuttongroup1 = uibuttongroup(app.uipanel1);
            app.uibuttongroup1.SelectionChangedFcn = createCallbackFcn(app, @uibuttongroup1SelectionChanged, true);
            app.uibuttongroup1.Tooltip = {'''Sistema de Unidades'''};
            app.uibuttongroup1.Title = 'SISTEMA DE UNIDADES';
            app.uibuttongroup1.Tag = 'uibuttongroup1';
            app.radiobutton2 = uiradiobutton(app.uibuttongroup1);
            app.radiobutton2.Tag = 'radiobutton2';
            app.radiobutton2.Text = 'S.Inglés';
            app.radiobutton1 = uiradiobutton(app.uibuttongroup1);
            app.radiobutton1.Tag = 'radiobutton1';
            app.radiobutton1.Text = 'S.Internacional';
            app.radiobutton1.Value = true;
            app.uibuttongroup2 = uibuttongroup(app.uipanel1);
            app.uibuttongroup2.Tooltip = {'''Inclinación del terreno'''};
            app.uibuttongroup2.Title = 'Inclinación';
            app.uibuttongroup2.Tag = 'uibuttongroup2';
            app.checkbox1 = uicheckbox(app.uibuttongroup2);
            app.checkbox1.ValueChangedFcn = createCallbackFcn(app, @checkbox1_Callback, true);
            app.checkbox1.Tag = 'checkbox1';
            app.checkbox1.Text = 'SI';
            app.uibuttongroup3 = uibuttongroup(app.uipanel1);
            app.uibuttongroup3.Tooltip = {''' Tipo de carga'''; '1.Qp: Carga sobre el talón.'; '2.Qf: Carga por fuera del talón.'; '3.Q: Carga sobre y fuera del talón .'};
            app.uibuttongroup3.Title = 'Tipo de Carga';
            app.uibuttongroup3.Tag = 'uibuttongroup3';
            app.popupmenu2 = uidropdown(app.uibuttongroup3);
            app.popupmenu2.Items = {'', 'Qp', 'Qf', 'Q'};
            app.popupmenu2.ValueChangedFcn = createCallbackFcn(app, @popupmenu2_Callback, true);
            app.popupmenu2.Tag = 'popupmenu2';
            app.popupmenu2.Tooltip = {'''La opción en blanco mostrara la imagen principal pero no cambiara los factores de degurirad'''};
            app.popupmenu2.Value = '';
            app.checkbox4 = uicheckbox(app.uipanel1);
            app.checkbox4.ValueChangedFcn = createCallbackFcn(app, @checkbox4_Callback, true);
            app.checkbox4.Tag = 'checkbox4';
            app.checkbox4.Tooltip = {'''Si es muro tipo Cantilever, es decir que la corona es menor que la base de la pantalla o tallo se tiene que seleccionar esta opción.'''};
            app.checkbox4.Text = 'Muro Cantilever?';
            app.edit10 = uieditfield(app.uipanel1, 'text');
            app.edit10.Tag = 'edit10';
            app.edit10.Tooltip = {'''Base de la pantalla del muro Cantilever'''};
            app.text39 = uilabel(app.uipanel1);
            app.text39.Interpreter = 'tex';
            app.text39.Tag = 'text39';
            app.text39.Text = 'm';
            app.text40 = uilabel(app.uipanel1);
            app.text40.Tag = 'text40';
            app.text40.Text = 'Base de la pantalla';
            app.text43 = uilabel(app.uipanel1);
            app.text43.Tag = 'text43';
            app.text43.Text = {'    P.e.'; 'Concreto'};
            app.pconcreto = uieditfield(app.uipanel1, 'text');
            app.pconcreto.Tag = 'pconcreto';
            app.pconcreto.Tooltip = {'''Peso específico del concreto'''};
            app.text44 = uilabel(app.uipanel1);
            app.text44.Interpreter = 'tex';
            app.text44.Tag = 'text44';
            app.text44.Text = 'kN/m^{3}';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Analisis de estabilidad';
            app.uipanel2.Tag = 'uipanel2';
            app.Calcular = uibutton(app.uipanel2, 'push');
            app.Calcular.ButtonPushedFcn = createCallbackFcn(app, @Calcular_Callback, true);
            app.Calcular.Tag = 'Calcular';
            app.Calcular.Tooltip = {'''Boton Calcular'''; 'Calcular los Factores de Seguridad '};
            app.Calcular.Text = 'Calcular';
            app.text13 = uilabel(app.uipanel2);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Revisión por Volcamiento';
            app.text14 = uilabel(app.uipanel2);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Revisión por Deslizamiento';
            app.text15 = uilabel(app.uipanel2);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Factores de Seguridad';
            app.FSD = uilabel(app.uipanel2);
            app.FSD.Interpreter = 'tex';
            app.FSD.Tag = 'FSD';
            app.FSD.Text = '';
            app.FSV = uilabel(app.uipanel2);
            app.FSV.Interpreter = 'tex';
            app.FSV.Tag = 'FSV';
            app.FSV.Text = '';
            app.salir = uibutton(app.figure1, 'push');
            app.salir.ButtonPushedFcn = createCallbackFcn(app, @salir_Callback, true);
            app.salir.Tag = 'salir';
            app.salir.Icon = fullfile(pathToMLAPP, 'ima', 'salir.png');
            app.salir.Text = '';
            app.restablecer = uibutton(app.figure1, 'push');
            app.restablecer.ButtonPushedFcn = createCallbackFcn(app, @restablecer_Callback, true);
            app.restablecer.Tag = 'restablecer';
            app.restablecer.Icon = fullfile(pathToMLAPP, 'ima', 'reset.png');
            app.restablecer.Tooltip = {'''Restablecer'''; 'Borrar valores en las casillas.'};
            app.restablecer.Text = '';
            app.ayuda = uibutton(app.figure1, 'push');
            app.ayuda.ButtonPushedFcn = createCallbackFcn(app, @ayuda_Callback, true);
            app.ayuda.Tag = 'ayuda';
            app.ayuda.Icon = fullfile(pathToMLAPP, 'ima', 'ayuda.png');
            app.ayuda.Tooltip = {'''AYUDA'''; 'Algunos valores que se pueden usar.'};
            app.ayuda.Text = '';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MuroT_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)MuroT_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end