classdef TablaEstaca_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1          matlab.ui.Figure
        pushbutton4      matlab.ui.control.Button
        pushbutton3      matlab.ui.control.Button
        uipanel2         matlab.ui.container.Panel
        text31           matlab.ui.control.Label
        S                matlab.ui.control.Label
        text29           matlab.ui.control.Label
        text27           matlab.ui.control.Label
        text26           matlab.ui.control.Label
        eper             matlab.ui.control.EditField
        text25           matlab.ui.control.Label
        Dmax             matlab.ui.control.Label
        text23           matlab.ui.control.Label
        MM               matlab.ui.control.Label
        text21           matlab.ui.control.Label
        text20           matlab.ui.control.Label
        PS               matlab.ui.control.Label
        text18           matlab.ui.control.Label
        text17           matlab.ui.control.Label
        FS               matlab.ui.control.EditField
        text16           matlab.ui.control.Label
        text15           matlab.ui.control.Label
        text14           matlab.ui.control.Label
        text13           matlab.ui.control.Label
        Ff               matlab.ui.control.Label
        text9            matlab.ui.control.Label
        text6            matlab.ui.control.Label
        F                matlab.ui.control.Label
        PH               matlab.ui.control.Label
        text3            matlab.ui.control.Label
        Calcular         matlab.ui.control.Button
        text2            matlab.ui.control.Label
        uipanel1         matlab.ui.container.Panel
        text12           matlab.ui.control.Label
        text11           matlab.ui.control.Label
        l2               matlab.ui.control.EditField
        l1               matlab.ui.control.EditField
        pa               matlab.ui.control.CheckBox
        uibuttongroup1   matlab.ui.container.ButtonGroup
        radiobutton2     matlab.ui.control.RadioButton
        radiobutton1     matlab.ui.control.RadioButton
        checkbox2        matlab.ui.control.CheckBox
        AA               matlab.ui.control.EditField
        text8            matlab.ui.control.Label
        checkbox1        matlab.ui.control.CheckBox
        EstratigraficaT  matlab.ui.control.Button
        axes1            matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function TablaEstaca_OpeningFcn(app, varargin)
            % --- Executes just before TablaEstaca is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to TablaEstaca (see VARARGIN)
            
            % Choose default command line output for TablaEstaca
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
           
            
            % UIWAIT makes TablaEstaca wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
            global rr1 AA R1 R3
            axes(handles.axes1); %Definimos axes2 como el objeto al que le meteremos una imagen secundaria.
            bkgrnd=imread('table.png'); %Leemos la imagen.
            imshow(bkgrnd); %Mostramos la imagen.
            set(handles.F,'Visible','off');
            set(handles.text6,'Visible','off');
            set(handles.AA,'Visible','off');
            set(handles.text8,'Visible','off');
            set(handles.Ff,'Visible','off');
            set(handles.text9,'Visible','off')
            
            set(handles.pa,'Visible','off');
            set(handles.l1,'Visible','off');
            set(handles.text11,'Visible','off');
            set(handles.l2,'Visible','off');
            set(handles.text12,'Visible','off')
            set(handles.text13,'Visible','off')
            set(handles.text14,'Visible','off')
            set(handles.text15,'Visible','off')
            set(handles.text18,'Visible','off')
            set(handles.text25,'Visible','off')
            set(handles.text21,'Visible','off')
            set(handles.text31,'Visible','off')
            
            
            set(handles.checkbox2,'Visible','off')
            R1=0;
            R3=0;
            assignin('base','R1',R1);
            assignin('base','R3',R3);
            rr1=1;
            %assignin('base','rr1',rr1);
            R11=1;
            assignin('base','R11',R11);
            AA=0;
            assignin('base','AA',AA);
        end

        % Button pushed function: Calcular
        function Calcular_Callback(app, event)
            % --- Executes on button press in Calcular.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Calcular (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global R1 R2 espesor phi PesoE n NF rr1 rr2 R3 AA
            FS=str2double(get(handles.FS, 'string'));
            eper=str2double(get(handles.eper, 'string'));
            if rr1==1
                set(handles.text15,'Visible','on')
                set(handles.text15,'string','m')
                set(handles.text18,'Visible','on')
                set(handles.text18,'string','m')
                set(handles.text21,'Visible','on')
                set(handles.text21,'string','kN*m')
                set(handles.text25,'Visible','on')
                set(handles.text25,'string','m')
                set(handles.text31,'Visible','on')
                set(handles.text31,'string','m3')
            
                if R1==1
                    set(handles.text13,'Visible','on')
                    set(handles.text13,'string','kN/m')
                    set(handles.text14,'Visible','on')
                    set(handles.text14,'string','kN/m')
                end
                if R1==0
                    set(handles.text13,'Visible','off')
                    set(handles.text13,'string','kN/m')
                    set(handles.text14,'Visible','off')
                    set(handles.text14,'string','kN/m')
                end
            
            end
            if rr2==1
                set(handles.text15,'Visible','on')
                set(handles.text15,'string','ft')
                set(handles.text18,'Visible','on')
                set(handles.text18,'string','ft')
                set(handles.text21,'Visible','on')
                set(handles.text21,'string','lb*ft')
                set(handles.text25,'Visible','on')
                set(handles.text25,'string','ft')
                set(handles.text31,'Visible','on')
                set(handles.text31,'string','ft3')
            
            
                if R1==1
                    set(handles.text13,'Visible','on')
                    set(handles.text13,'string','lb/ft')
                    set(handles.text14,'Visible','on')
                    set(handles.text14,'string','lb/ft')
                end
                if R1==0
                    set(handles.text13,'Visible','off')
                    set(handles.text13,'string','lb/ft')
                    set(handles.text14,'Visible','off')
                    set(handles.text14,'string','lb/ft')
                end
            
            end
            
            if R1==1
                if R3==1
                     l1=str2double(get(handles.l1,'string'));
                     l2=str2double(get(handles.l2,'string'));
                end
                if R3==0
                    warndlg('Interreuptor de profundidad de anclaje necesita estar activado','Observación')
                    return
                end
                if R2==1
                    AA=str2double(get(handles.AA,'string'));
                else
                    AA=0;
                end
                [D,F,Ff,PS,z,Mmax,Ss]=Anclaje(espesor,phi,PesoE,n,NF,AA,rr1,rr2,R3,l1,l2,FS,eper);
                set(handles.PH,'String',round(D,2));
                set(handles.PS,'String',round(PS,2));
                set(handles.F,'String',round(F,2));
                set(handles.Ff,'String',round(Ff,2));
                set(handles.Dmax,'String',round(z,2));
                set(handles.MM,'String',round(Mmax,2));
                set(handles.S,'String',round(Ss,4));
            
            
            end
            if R1==0
                [D,PS,Mmax,pmm,Ss]=SinAnclaje(espesor,phi,PesoE,n,NF,rr1,rr2,FS,eper);
                set(handles.PH,'String',round(D,2));
                set(handles.PS,'String',round(PS,2));
                set(handles.MM,'String',round(Mmax,2));
                set(handles.Dmax,'String',round(pmm,2));
                set(handles.S,'String',round(Ss,5));
            
            end
        end

        % Button pushed function: EstratigraficaT
        function EstratigraficaT_Callback(app, event)
            % --- Executes on button press in EstratigraficaT.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to EstratigraficaT (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global rr2 rr1 Rad1 Rad2
           
            if rr1==1
                rr2=0;
                R11=1;
                R22=0;
            end
            if rr2==1
                rr1=0;
                R22=1;
                R11=0;
            end
            estratiestaca_App(R11,R22)
        end

        % Value changed function: checkbox1
        function checkbox1_Callback(app, event)
            % --- Executes on button press in checkbox1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox1
            global R1
            R1=get(hObject,'Value');
             assignin('base','R1',R1);
            if R1==1
                set(handles.pa,'Visible','on');
                set(handles.checkbox2,'Visible','on')
                set(handles.F,'Visible','on');
                set(handles.text6,'Visible','on');
            
                set(handles.Ff,'Visible','on');
                set(handles.text9,'Visible','on');
                axes(handles.axes1); %Definimos axes2 como el objeto al que le meteremos una imagen secundaria.
                bkgrnd=imread('tancla.png'); %Leemos la imagen.
                imshow(bkgrnd); %Mostramos la imagen.
            
            else
                set(handles.pa,'Visible','off');
                set(handles.pa,'value',0)
                set(handles.l1,'Visible','off');
                set(handles.text11,'Visible','off');
                set(handles.l2,'Visible','off');
                set(handles.text12,'Visible','off')
                %%%%
                set(handles.checkbox2,'Visible','off')
                set(handles.F,'Visible','off');
                set(handles.text6,'Visible','off');
                set(handles.AA,'Visible','off');
                set(handles.text8,'Visible','off');
                set(handles.Ff,'Visible','off');
                set(handles.text9,'Visible','off');
                axes(handles.axes1); %Definimos axes2 como el objeto al que le meteremos una imagen secundaria.
                bkgrnd=imread('table.png'); %Leemos la imagen.
                imshow(bkgrnd); %Mostramos la imagen.
            
            end
        end

        % Value changed function: checkbox2
        function checkbox2_Callback(app, event)
            % --- Executes on button press in checkbox2.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox2
            global R2
            R2=get(hObject,'Value');
            if R2==1
            
                set(handles.AA,'Visible','on');
                set(handles.text8,'Visible','on');
                axes(handles.axes1); %Definimos axes2 como el objeto al que le meteremos una imagen secundaria.
                bkgrnd=imread('taincli.png'); %Leemos la imagen.
                imshow(bkgrnd); %Mostramos la imagen.
            else
                set(handles.AA,'Visible','off');
                set(handles.text8,'Visible','off');
                axes(handles.axes1); %Definimos axes2 como el objeto al que le meteremos una imagen secundaria.
                bkgrnd=imread('tancla.png'); %Leemos la imagen.
                imshow(bkgrnd); %Mostramos la imagen.
            end
        end

        % Value changed function: pa
        function pa_Callback(app, event)
            % --- Executes on button press in pa.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pa (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of pa
            global R3
            R3=get(hObject,'Value');
            assignin('base','R3',R3);
            if R3==1
                set(handles.l1,'Visible','on');
                set(handles.text11,'Visible','on');
                set(handles.l2,'Visible','on');
                set(handles.text12,'Visible','on')
                axes(handles.axes1); %Definimos axes2 como el objeto al que le meteremos una imagen secundaria.
                bkgrnd=imread('siancla.png'); %Leemos la imagen.
                imshow(bkgrnd); %Mostramos la imagen.
            end
            if R3==0
            
                set(handles.l1,'Visible','off');
                set(handles.text11,'Visible','off');
                set(handles.l2,'Visible','off');
                set(handles.text12,'Visible','off')
                axes(handles.axes1); %Definimos axes2 como el objeto al que le meteremos una imagen secundaria.
                bkgrnd=imread('tancla.png'); %Leemos la imagen.
                imshow(bkgrnd); %Mostramos la imagen.
            end
        end

        % Selection changed function: uibuttongroup1
        function uibuttongroup1SelectionChanged(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            selectedButton = app.uibuttongroup1.SelectedObject;
            global rr1 rr2
            rr1=app.radiobutton1.Value;
            assignin('base','rr1',rr1);
            rr2=app.radiobutton2.Value;
            assignin('base','rr2',rr2);
            if rr1==1
                set(handles.text27,'Visible','on')
                set(handles.text27,'string','kN/m2')
                
            end
            
            if rr2==1
                set(handles.text27,'Visible','on')
                set(handles.text27,'string','lb/ft2')
                
            end


        end

        % Button pushed function: pushbutton4
        function pushbutton4ButtonPushed(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            winopen('Fundamentos.pdf')
        end

        % Button pushed function: pushbutton3
        function pushbutton3ButtonPushed(app, event)
            app.delete
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'TablaEstaca';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Panel';
            app.uipanel1.Tag = 'uipanel1';
            app.EstratigraficaT = uibutton(app.uipanel1, 'push');
            app.EstratigraficaT.ButtonPushedFcn = createCallbackFcn(app, @EstratigraficaT_Callback, true);
            app.EstratigraficaT.Tag = 'EstratigraficaT';
            app.EstratigraficaT.Tooltip = {'Aqui puede ingresar los valores del suelo de relleno.'};
            app.EstratigraficaT.Text = 'Estratigrafia';
            app.checkbox1 = uicheckbox(app.uipanel1);
            app.checkbox1.ValueChangedFcn = createCallbackFcn(app, @checkbox1_Callback, true);
            app.checkbox1.Tag = 'checkbox1';
            app.checkbox1.Text = 'Anclaje Sencillo';
            app.text8 = uilabel(app.uipanel1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Angulo del anclaje';
            app.AA = uieditfield(app.uipanel1, 'text');
            app.AA.Tag = 'AA';
            app.checkbox2 = uicheckbox(app.uipanel1);
            app.checkbox2.ValueChangedFcn = createCallbackFcn(app, @checkbox2_Callback, true);
            app.checkbox2.Tag = 'checkbox2';
            app.checkbox2.Tooltip = {'Se aume como 90° a un anclaje perpendicular a la tablestaca.'; 'Si se tiene un angulo inferior activarlo para poder ingresar el valor, de lo contrario se tomara 90°'};
            app.checkbox2.Text = 'Angulo <90°';
            app.uibuttongroup1 = uibuttongroup(app.uipanel1);
            app.uibuttongroup1.SelectionChangedFcn = createCallbackFcn(app, @uibuttongroup1SelectionChanged, true);
            app.uibuttongroup1.Tooltip = {'Sistema de unidades'};
            app.uibuttongroup1.Title = 'SISTEMA DE UNIDADES';
            app.uibuttongroup1.Tag = 'uibuttongroup1';
            app.radiobutton1 = uiradiobutton(app.uibuttongroup1);
            app.radiobutton1.Tag = 'radiobutton1';
            app.radiobutton1.Text = 'S.Internacional';
            app.radiobutton1.Value = true;
            app.radiobutton2 = uiradiobutton(app.uibuttongroup1);
            app.radiobutton2.Tag = 'radiobutton2';
            app.radiobutton2.Text = 'S.Ingles';
            app.pa = uicheckbox(app.uipanel1);
            app.pa.ValueChangedFcn = createCallbackFcn(app, @pa_Callback, true);
            app.pa.Tag = 'pa';
            app.pa.Tooltip = {'¿Conoce la profundidad de anclado?'; 'Si no esta activado se asume a la altura del primer estrato como lo muestra la imagen.'};
            app.pa.Text = 'Profundidad de anclado';
            app.l1 = uieditfield(app.uipanel1, 'text');
            app.l1.Tag = 'l1';
            app.l1.Tooltip = {'Longitud desde la superficie hasta el anclaje.'};
            app.l2 = uieditfield(app.uipanel1, 'text');
            app.l2.Tag = 'l2';
            app.l2.Tooltip = {'Longitud desde el anclaje hasta el primer estrato.'};
            app.text11 = uilabel(app.uipanel1);
            app.text11.Tag = 'text11';
            app.text11.Text = 'l1';
            app.text12 = uilabel(app.uipanel1);
            app.text12.Tag = 'text12';
            app.text12.Text = 'l2';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Tablestacas';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'CÁLCULO';
            app.uipanel2.Tag = 'uipanel2';
            app.Calcular = uibutton(app.uipanel2, 'push');
            app.Calcular.ButtonPushedFcn = createCallbackFcn(app, @Calcular_Callback, true);
            app.Calcular.Tag = 'Calcular';
            app.Calcular.Text = 'Calcular';
            app.text3 = uilabel(app.uipanel2);
            app.text3.Tag = 'text3';
            app.text3.Text = {'Profundidad de Hincado'; ''};
            app.PH = uilabel(app.uipanel2);
            app.PH.Tag = 'PH';
            app.PH.Text = '';
            app.F = uilabel(app.uipanel2);
            app.F.Tag = 'F';
            app.F.Text = '';
            app.text6 = uilabel(app.uipanel2);
            app.text6.Tag = 'text6';
            app.text6.Text = {' Fx Anclaje'; ''};
            app.text9 = uilabel(app.uipanel2);
            app.text9.Tag = 'text9';
            app.text9.Text = {'F Anclaje'; ''};
            app.Ff = uilabel(app.uipanel2);
            app.Ff.Tag = 'Ff';
            app.Ff.Text = '';
            app.text13 = uilabel(app.uipanel2);
            app.text13.Tag = 'text13';
            app.text13.Text = 'kN/m';
            app.text14 = uilabel(app.uipanel2);
            app.text14.Tag = 'text14';
            app.text14.Text = 'kN/m';
            app.text15 = uilabel(app.uipanel2);
            app.text15.Tag = 'text15';
            app.text15.Text = 'm';
            app.text16 = uilabel(app.uipanel2);
            app.text16.Tag = 'text16';
            app.text16.Text = 'F.S de 1.2 a 1.5';
            app.FS = uieditfield(app.uipanel2, 'text');
            app.FS.Tag = 'FS';
            app.text17 = uilabel(app.uipanel2);
            app.text17.Tag = 'text17';
            app.text17.Text = {'Profundidad de Hincado con F.S'; ''};
            app.text18 = uilabel(app.uipanel2);
            app.text18.Tag = 'text18';
            app.text18.Text = 'm';
            app.PS = uilabel(app.uipanel2);
            app.PS.Tag = 'PS';
            app.PS.Text = '';
            app.text20 = uilabel(app.uipanel2);
            app.text20.Tag = 'text20';
            app.text20.Text = {'Momento Maximo'; ''};
            app.text21 = uilabel(app.uipanel2);
            app.text21.Tag = 'text21';
            app.text21.Text = 'kN*m';
            app.MM = uilabel(app.uipanel2);
            app.MM.Tag = 'MM';
            app.MM.Text = '';
            app.text23 = uilabel(app.uipanel2);
            app.text23.Tag = 'text23';
            app.text23.Text = {'Dist. donde ocurre'; ''};
            app.Dmax = uilabel(app.uipanel2);
            app.Dmax.Tag = 'Dmax';
            app.Dmax.Text = '';
            app.text25 = uilabel(app.uipanel2);
            app.text25.Tag = 'text25';
            app.text25.Text = 'm';
            app.eper = uieditfield(app.uipanel2, 'text');
            app.eper.Tag = 'eper';
            app.eper.Tooltip = {'Esfuerzo permisible del material'};
            app.text26 = uilabel(app.uipanel2);
            app.text26.Tag = 'text26';
            app.text26.Text = 'Esfuerzo permisible del material';
            app.text27 = uilabel(app.uipanel2);
            app.text27.Tag = 'text27';
            app.text27.Text = 'kN/m2';
            app.text29 = uilabel(app.uipanel2);
            app.text29.Tag = 'text29';
            app.text29.Text = {'Modulo de seccion minimo (S)'; ''};
            app.S = uilabel(app.uipanel2);
            app.S.Tag = 'S';
            app.S.Text = '';
            app.text31 = uilabel(app.uipanel2);
            app.text31.Tag = 'text31';
            app.text31.Text = 'm3';
            app.pushbutton3 = uibutton(app.figure1, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3ButtonPushed, true);
            app.pushbutton3.Tag = 'pushbutton3';
            app.pushbutton3.Text = 'Salir';
            app.pushbutton4 = uibutton(app.figure1, 'push');
            app.pushbutton4.ButtonPushedFcn = createCallbackFcn(app, @pushbutton4ButtonPushed, true);
            app.pushbutton4.Tag = 'pushbutton4';
            app.pushbutton4.Icon = fullfile(pathToMLAPP, 'ima', 'ayuda.png');
            app.pushbutton4.Text = '';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TablaEstaca_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)TablaEstaca_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end