classdef estratiestaca_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        uipanel1     matlab.ui.container.Panel
        text17       matlab.ui.control.Label
        text20       matlab.ui.control.Label
        text19       matlab.ui.control.Label
        pushbutton3  matlab.ui.control.Button
        tabla1       matlab.ui.control.Table
        nf           matlab.ui.control.EditField
        text12       matlab.ui.control.Label
        Aceptar      matlab.ui.control.Button
        pushbutton1  matlab.ui.control.Button
        espesor      matlab.ui.control.EditField
        Pe           matlab.ui.control.EditField
        Phi          matlab.ui.control.EditField
        UIAxes       matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function pi_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to pi (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.tabla1.BackgroundColor = [1 1 1];
            app.tabla1.ColumnFormat = {[] [] []};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function estratiestaca_OpeningFcn(app, varargin)
            % --- Executes just before estratiestaca is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to estratiestaca (see VARARGIN)
            global tt1 tt2
            datos=get(handles.tabla1,'Data');
            R11=varargin{1};
            R22=varargin{2};
            tt1=varargin{3};
            tt2=varargin{4};
            
            
            if R11==1
            
            
                set(handles.text19,'string','(m)');
                set(handles.text20,'string','(kN/m3)');
            end
            if R22==1
            
                set(handles.text19,'string','(ft)');
                set(handles.text20,'string','(lb/ft3)');
            end
            datos(:,:)=[];
            
            set(handles.tabla1,'Data',datos);
            % Choose default command line output for estratiestaca
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: Aceptar
        function Aceptar_Callback(app, event)
            % --- Executes on button press in Aceptar.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Aceptar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            ObtenerDatos=get(handles.tabla1,'data');
            assignin('base','dataa',ObtenerDatos);
            
            nn=size(ObtenerDatos,1);%el numero de filas me dira la cantidad de estratos
%             archivo=fopen('datos.dat','W');
%             %llenamos la matriz usando un For
%             for i=1:nn
%                 fprintf(archivo,'%s %s %s \n',ObtenerDatos{i,:});
%             end
%             %cerramos el archivo creado
%             fclose(archivo);
%             %cargamos la matriz igualandola a una varible
            B=ObtenerDatos;%ya esta formada la matriz
            assignin('base','B',B);
            %separamos el vector igualandolo a una variable aqui ya obtengo los valores
            %del suelo para usarlos se vuelven valores globales
            global espesor phi PesoE n NF tt1 tt2
            
            n=nn;
            assignin('base','n',n);
            %%%%%SUELO
            if tt1==1

                if n>1 || n<0
                    errordlg('Cantidad de estratos fuera de rango, para el metodo de Blum solo se tiene encuenta un estrato','ERROR')
                    return
                end
                
            end

            if tt2==1
                if n>2 || n<0
                   errordlg('Cantidad de estratos fuera de rango, para el metodo de Braja Das solo se tiene encuenta valores de 1 a 2 estartos','ERROR')
                   return
                end

            end


            NF=str2double(get(handles.nf,'String'));
            assignin('base','nf',NF);
            
            espesor=B(:,1);
            assignin('base','espesor',espesor);
            
            PesoE=B(:,2);
            phi=B(:,3);
            
            %NF
            if NF<0
                errordlg('Valor menor de N.F menor de 0, si no hay nivel freatico es 0','ERROR')
                return
            end
            if NF>n
                errordlg('Valor de N.F por encima del numero de estratos','ERROR')
                return
            end

            Y1='k';
           Y2='k';
           
           if n==1
              
            
              fill(app.UIAxes,[0 0 10 10],[0 espesor espesor 0],[0.63 .45 0.3]);hold on;
               
              % Linea cota altura
               plot(app.UIAxes,[2 2],[0,espesor],Y1,'LineWidth',1.2),  
               % Flechas cota altura
               plot(app.UIAxes,[1.7 2],[0.85*espesor espesor],Y1,'LineWidth',1.2);	
               plot(app.UIAxes,[2 2.3],[espesor 0.85*espesor],Y1,'LineWidth',1.2);
               plot(app.UIAxes,[1.7 2],[0.15*espesor 0],Y1,'LineWidth',1.2);
               plot(app.UIAxes,[2 2.3],[0 0.15*espesor],Y1,'LineWidth',1.2);
               % Etiquetas
               text(2.3,0.5*espesor,['H = ',num2str(espesor)],'FontSize',20,'color',Y2);
               text(7,0.7*espesor,['γ = ',num2str(PesoE)],'FontSize',20,'color',Y2);
               text(7,0.5*espesor,['Φ = ',num2str(phi)],'FontSize',20,'color',Y2);
               text(0.1,1.05*espesor,'Superficie','FontSize',10,'color','K');

               if NF==1
                   plot(-0:0.1:10,(ones(101,1).*espesor),'--b','linewidth',2);
                   text(10,espesor,'N.F.','FontSize',10,'color','b');
               end
           end
           if n==2
               esp=espesor(1)+espesor(2);
               fill(app.UIAxes,[0 0 10 10],[esp esp-espesor(1) esp-espesor(1) esp],[0.1 .45 0.3]);hold on;
               fill(app.UIAxes,[0 0 10 10],[0 espesor(2) espesor(2) 0],[0.63 .45 0.3]);hold on;
               
              % Linea cota altura
               plot(app.UIAxes,[2 2],[0,espesor(1)+espesor(2)],Y1,'LineWidth',1.2),  
               % Flechas cota altura
               plot(app.UIAxes,[1.7 2],[0.85*esp esp],Y1,'LineWidth',1.2);	
               plot(app.UIAxes,[2 2.3],[esp 0.85*esp],Y1,'LineWidth',1.2);
               plot(app.UIAxes,[1.7 2],[0.15*esp 0],Y1,'LineWidth',1.2);
               plot(app.UIAxes,[2 2.3],[0 0.15*esp],Y1,'LineWidth',1.2);
               % Etiquetas
               text(2.3,0.5*esp,['H = ',num2str(espesor(1)+espesor(2))],'FontSize',20,'color',Y2);
               text(5,(0.59*espesor(1))+espesor(2),['γ1 = ',num2str(PesoE(1))],'FontSize',15,'color',Y2);
               text(7,(0.4*espesor(1))+espesor(2),['Φ1 = ',num2str(phi(1))],'FontSize',15,'color',Y2);
               text(5,(0.59*espesor(2)),['γ2 = ',num2str(PesoE(2))],'FontSize',15,'color',Y2);
               text(7,(0.4*espesor(2)),['Φ2 = ',num2str(phi(2))],'FontSize',15,'color',Y2);
               text(0.1,1.05*esp,'Superficie','FontSize',10,'color','K');
               if NF==1
                   plot(-0:0.1:10,(ones(101,1).*esp),'--b','linewidth',2);
                   text(10,esp,'N.F.','FontSize',10,'color','b');
               end
               if NF==2
                   plot(-0:0.1:10,(ones(101,1).*espesor(2)),'--b','linewidth',2);
                   text(10,espesor(2),'N.F.','FontSize',10,'color','b');
               end
           end
           waitfor(msgbox('Datos Asignados','Ok'));
           Tablestaca
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % --- Executes on button press in pushbutton1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            espesor=str2double(get(handles.espesor,'String'));
            PesoEspecifico=str2double(get(handles.Pe,'String'));
            Phia=str2double(get(handles.Phi,'String'));
            %ESPESOR
            if isnan(espesor)
                    errordlg('Valor de Espesor no numerico','ERROR')
                    return
            else
                espesor=espesor;
            end
            %PESO ESPECIFICO
            if isnan(PesoEspecifico)
                    errordlg('Valor de Peso especifico no numerico','ERROR')
                    return
            else
                PesoEspecifico=PesoEspecifico;
            end
            %PHI
            if isnan(Phia)
                    errordlg('Valor de PHI no numerico','ERROR')
                    return
            else
                Phia=Phia;
            end
            
            datos=get(handles.tabla1,'Data');
            
            if isempty(datos)
                datos(1,1)=espesor;
                datos(1,2)=PesoEspecifico;
                datos(1,3)=Phia;
            
            else
                datos(end+1,1)=espesor;
                datos(end,2)=PesoEspecifico;
                datos(end,3)=Phia;
            
            end
            set(handles.tabla1,'Data',datos);
        end

        % Button pushed function: pushbutton3
        function pushbutton3ButtonPushed(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            cla(app.UIAxes);
            datos=get(handles.tabla1,'Data');
            datos(:,:)=[];
            set(handles.tabla1,'Data',datos);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Estratificación';
            app.figure1.Icon = fullfile(pathToMLAPP, 'ima', 'm.png');
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Datos del suelo';
            app.uipanel1.Tag = 'uipanel1';
            app.UIAxes = uiaxes(app.uipanel1);
            title(app.UIAxes, 'Estratos')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [0 1 2 3 4];
            app.UIAxes.YTickLabel = {'0'; '1'; '0'; '1'; '4'};
            app.Phi = uieditfield(app.uipanel1, 'text');
            app.Phi.Tag = 'Phi';
            app.Phi.Tooltip = {'''Valores de angulo de friccion interna'''; 'Ingreasar de 1 en 1.'};
            app.Pe = uieditfield(app.uipanel1, 'text');
            app.Pe.Tag = 'Pe';
            app.Pe.Tooltip = {'''Valores de Peso especifico del suelo de relleno'''; 'Ingreasar de 1 en 1.'};
            app.espesor = uieditfield(app.uipanel1, 'text');
            app.espesor.Tag = 'espesor';
            app.espesor.Tooltip = {'''Valores de espesor'''; 'Ingreasar de 1 en 1.'};
            app.pushbutton1 = uibutton(app.uipanel1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Tooltip = {'''Añadir'''; 'Ingresa los valores a la tabla '};
            app.pushbutton1.Text = 'Añadir';
            app.Aceptar = uibutton(app.uipanel1, 'push');
            app.Aceptar.ButtonPushedFcn = createCallbackFcn(app, @Aceptar_Callback, true);
            app.Aceptar.Tag = 'Aceptar';
            app.Aceptar.Text = 'Aceptar';
            app.text12 = uilabel(app.uipanel1);
            app.text12.Tag = 'text12';
            app.text12.Tooltip = {'Para metodo de blum solo se considera NF=1 o NF=0.'; ''; 'Para metod Braja das se considera NF=2 o'; 'NF=0.'};
            app.text12.Text = 'N.F ▼   ';
            app.nf = uieditfield(app.uipanel1, 'text');
            app.nf.Tag = 'nf';
            app.nf.Tooltip = {'''N.F'''; 'Estrato donde se encuentra el nivel Freatico. '; 'donde 1 corresponde al primer estrato ingresado.'};
            app.tabla1 = uitable(app.uipanel1);
            app.tabla1.ColumnName = {'Espesor'; 'P.e.'; 'Phi  '};
            app.tabla1.RowName = {};
            app.tabla1.ColumnEditable = [false false false];
            app.tabla1.Tag = 'tabla1';
            app.pushbutton3 = uibutton(app.uipanel1, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3ButtonPushed, true);
            app.pushbutton3.Tag = 'pushbutton3';
            app.pushbutton3.Tooltip = {'''Eliminar'''; 'Limpiar valores de la tabla'};
            app.pushbutton3.Text = 'Eliminar';
            app.text19 = uilabel(app.uipanel1);
            app.text19.Tag = 'text19';
            app.text19.Text = '(m)';
            app.text20 = uilabel(app.uipanel1);
            app.text20.Tag = 'text20';
            app.text20.Text = '(kN/m3)';
            app.text17 = uilabel(app.uipanel1);
            app.text17.Tag = 'text17';
            app.text17.Text = '(grados°)';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = estratiestaca_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)estratiestaca_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end